classdef etc_render_fsbrain_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        uipanel1                        matlab.ui.container.Panel
        text_status                     matlab.ui.control.Label
        slider_timeVec                  matlab.ui.control.Slider
        text_timeVec_min                matlab.ui.control.Label
        text_timeVec_max                matlab.ui.control.Label
        checkbox_show_overlay           matlab.ui.control.CheckBox
        text7                           matlab.ui.control.Label
        edit_threshold_min              matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        edit_threshold_max              matlab.ui.control.EditField
        text9                           matlab.ui.control.Label
        edit_timeVec                    matlab.ui.control.EditField
        text10                          matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        checkbox_show_colorbar          matlab.ui.control.CheckBox
        edit_smooth                     matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        text13                          matlab.ui.control.Label
        slider_alpha                    matlab.ui.control.Slider
        text14                          matlab.ui.control.Label
        pushbutton_auto_threshold       matlab.ui.control.Button
        uipanel2                        matlab.ui.container.Panel
        edit_aux_point_size             matlab.ui.control.EditField
        edit_aux2_point_size            matlab.ui.control.EditField
        text18                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        pushbutton_aux_point_color      matlab.ui.control.Button
        pushbutton_aux2_point_color     matlab.ui.control.Button
        checkbox_aux_point_label        matlab.ui.control.CheckBox
        text22                          matlab.ui.control.Label
        text29                          matlab.ui.control.Label
        uipanel3                        matlab.ui.container.Panel
        pushbutton_click_vertex_point_color  matlab.ui.control.Button
        pushbutton_click_point_color    matlab.ui.control.Button
        text21                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        edit_click_vertex_point_size    matlab.ui.control.EditField
        edit_click_point_size           matlab.ui.control.EditField
        checkbox_nearest_brain_surface  matlab.ui.control.CheckBox
        uipanel4                        matlab.ui.container.Panel
        text23                          matlab.ui.control.Label
        checkbox_overlay_truncate_neg   matlab.ui.control.CheckBox
        text25                          matlab.ui.control.Label
        checkbox_overlay_truncate_pos   matlab.ui.control.CheckBox
        uipanel5                        matlab.ui.container.Panel
        pushbutton_selected_contact_color  matlab.ui.control.Button
        text26                          matlab.ui.control.Label
        edit_selected_contact_size      matlab.ui.control.EditField
        checkbox_selected_contact       matlab.ui.control.CheckBox
        text28                          matlab.ui.control.Label
        checkbox_selected_electrode     matlab.ui.control.CheckBox
        pushbutton_selected_electrode_color  matlab.ui.control.Button
        edit_selected_electrode_size    matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function etc_render_fsbrain_gui_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to etc_render_fsbrain_gui (see VARARGIN)
%             
%             % Choose default command line output for etc_render_fsbrain_gui
%             app.output = hObject;
%             
%             global etc_render_fsbrain;
%             
%             %surface opacity slider
%             set(app.slider_alpha,'value',get(etc_render_fsbrain.h,'facealpha'));
%             
%             %timeVec slider
%             try
%                 set(app.slider_timeVec,'Enable','off');
%                 set(app.edit_timeVec,'Enable','off');
%             
%                 if(isfield(etc_render_fsbrain,'overlay_stc_timeVec_idx'))
%                     if(~isempty(etc_render_fsbrain.overlay_stc_timeVec))
%                         if(length(etc_render_fsbrain.overlay_stc_timeVec)>1)
%                             set(app.slider_timeVec,'Enable','on');
%                             set(app.slider_timeVec,'value',etc_render_fsbrain.overlay_stc_timeVec(etc_render_fsbrain.overlay_stc_timeVec_idx));
%             
%                             set(app.edit_timeVec,'Enable','on');
%                             set(app.edit_timeVec,'value',etc_render_fsbrain.overlay_stc_timeVec(etc_render_fsbrain.overlay_stc_timeVec_idx));
%                             set(app.edit_timeVec,'string',sprintf('%1.1f',etc_render_fsbrain.overlay_stc_timeVec(etc_render_fsbrain.overlay_stc_timeVec_idx)));
%                         end;
%                     end;
%                 end;
%             catch
%             end;
%             
%             %overlay smooth
%             set(app.edit_smooth,'enable','off');
%             if(isfield(etc_render_fsbrain,'overlay_value'))
%                 if(~isempty(etc_render_fsbrain.overlay_value))
%                     set(app.edit_smooth,'enable','on');
%                 end;
%             end;
%             if(isfield(etc_render_fsbrain,'overlay_stc'))
%                 if(~isempty(etc_render_fsbrain.overlay_stc))
%                     set(app.edit_smooth,'enable','on');
%                 end;
%             end;
%             
%             %colorbar check box
%             set(app.checkbox_show_colorbar,'value',0);
%             if(isfield(etc_render_fsbrain,'h_colorbar_pos'))
%                 if(isempty(etc_render_fsbrain.h_colorbar_pos))
%                     set(app.checkbox_show_colorbar,'value',1);
%                 end;
%             end;
%             if(get(app.checkbox_show_colorbar,'value'))
%                 set(app.checkbox_show_colorbar,'enable','on');
%             else
%                 set(app.checkbox_show_colorbar,'enable','off');
%             end;
%             
%             set(app.checkbox_show_colorbar,'enable','off');
%             
%             set(app.pushbutton_aux_point_color,'BackgroundColor',etc_render_fsbrain.aux_point_color);
%             set(app.edit_aux_point_size,'string',sprintf('%3.3f',etc_render_fsbrain.aux_point_size));
%             if(~isfield(etc_render_fsbrain,'aux_point_label_flag'))
%                 etc_render_fsbrain.aux_point_label_flag=1;
%             end;
%             set(app.checkbox_aux_point_label,'value',etc_render_fsbrain.aux_point_label_flag);
%             
%             set(app.pushbutton_aux2_point_color,'BackgroundColor',etc_render_fsbrain.aux2_point_color);
%             set(app.edit_aux2_point_size,'string',sprintf('%d',etc_render_fsbrain.aux2_point_size));
%             
%             set(app.pushbutton_click_point_color,'BackgroundColor',etc_render_fsbrain.click_point_color);
%             set(app.edit_click_point_size,'string',sprintf('%d',etc_render_fsbrain.click_point_size));
%             
%             set(app.checkbox_nearest_brain_surface,'value',etc_render_fsbrain.show_nearest_brain_surface_location_flag);
%             set(app.pushbutton_click_vertex_point_color,'BackgroundColor',etc_render_fsbrain.click_vertex_point_color);
%             set(app.edit_click_vertex_point_size,'string',sprintf('%d',etc_render_fsbrain.click_vertex_point_size));
%             
%             set(app.checkbox_overlay_truncate_neg,'value',etc_render_fsbrain.flag_overlay_truncate_neg);
%             set(app.checkbox_overlay_truncate_pos,'value',etc_render_fsbrain.flag_overlay_truncate_pos);
%             
%             
%             set(app.checkbox_selected_contact,'value',etc_render_fsbrain.selected_contact_flag);
%             set(app.pushbutton_selected_contact_color,'BackgroundColor',etc_render_fsbrain.selected_contact_color);
%             set(app.edit_selected_contact_size,'string',sprintf('%d',etc_render_fsbrain.selected_contact_size));
%             
%             set(app.checkbox_selected_electrode,'value',etc_render_fsbrain.selected_electrode_flag);
%             set(app.pushbutton_selected_electrode_color,'BackgroundColor',etc_render_fsbrain.selected_electrode_color);
%             set(app.edit_selected_electrode_size,'string',sprintf('%d',etc_render_fsbrain.selected_electrode_size));
%             
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: checkbox_aux_point_label
        function checkbox_aux_point_label_Callback(app, event)
            % hObject    handle to checkbox_aux_point_label (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_aux_point_label
            global etc_render_fsbrain
            
            etc_render_fsbrain.aux_point_label_flag=get(hObject,'Value');
            
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: checkbox_nearest_brain_surface
        function checkbox_nearest_brain_surface_Callback(app, event)
            % hObject    handle to checkbox_nearest_brain_surface (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_nearest_brain_surface
            global etc_render_fsbrain
            
            etc_render_fsbrain.show_nearest_brain_surface_location_flag=get(hObject,'Value');
            
            if(isfield(etc_render_fsbrain,'click_coord'))
                if(~isempty(etc_render_fsbrain.click_coord))
                    etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
                end;
            end
        end

        % Value changed function: checkbox_overlay_truncate_neg
        function checkbox_overlay_truncate_neg_Callback(app, event)
            % hObject    handle to checkbox_overlay_truncate_neg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_overlay_truncate_neg
            global etc_render_fsbrain
            
            etc_render_fsbrain.flag_overlay_truncate_neg=get(hObject,'Value');
            
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
            
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: checkbox_overlay_truncate_pos
        function checkbox_overlay_truncate_pos_Callback(app, event)
            % hObject    handle to checkbox_overlay_truncate_pos (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_overlay_truncate_pos
            global etc_render_fsbrain
            
            etc_render_fsbrain.flag_overlay_truncate_pos=get(hObject,'Value');
            
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
            
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: checkbox_selected_contact
        function checkbox_selected_contact_Callback(app, event)
            % hObject    handle to checkbox_selected_contact (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_selected_contact
            global etc_render_fsbrain
            
            etc_render_fsbrain.selected_contact_flag=get(hObject,'Value');
            
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: checkbox_selected_electrode
        function checkbox_selected_electrode_Callback(app, event)
            % hObject    handle to checkbox_selected_electrode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_selected_electrode
            global etc_render_fsbrain
            
            etc_render_fsbrain.selected_electrode_flag=get(hObject,'Value');
            
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: checkbox_show_colorbar
        function checkbox_show_colorbar_Callback(app, event)
            % hObject    handle to checkbox_show_colorbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_show_colorbar
            global etc_render_fsbrain
            set(etc_render_fsbrain.fig_brain,'currentchar','c');
            etc_render_fsbrain_handle('kb','cc','c');
        end

        % Value changed function: checkbox_show_overlay
        function checkbox_show_overlay_Callback(app, event)
            % hObject    handle to checkbox_show_overlay (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_show_overlay
            global etc_render_fsbrain
            etc_render_fsbrain.overlay_flag_render=get(hObject,'value');
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: edit_aux2_point_size
        function edit_aux2_point_size_Callback(app, event)
            % hObject    handle to edit_aux2_point_size (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_aux2_point_size as text
            %        str2double(get(hObject,'String')) returns contents of edit_aux2_point_size as a double
            
            global etc_render_fsbrain;
            
            etc_render_fsbrain.aux2_point_size=str2double(get(hObject,'String'));
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: edit_aux_point_size
        function edit_aux_point_size_Callback(app, event)
            % hObject    handle to edit_aux_point_size (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_aux_point_size as text
            %        str2double(get(hObject,'String')) returns contents of edit_aux_point_size as a double
            global etc_render_fsbrain;
            
            etc_render_fsbrain.aux_point_size=str2double(get(hObject,'String'));
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: edit_click_point_size
        function edit_click_point_size_Callback(app, event)
            % hObject    handle to edit_click_point_size (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_click_point_size as text
            %        str2double(get(hObject,'String')) returns contents of edit_click_point_size as a double
            global etc_render_fsbrain;
            
            etc_render_fsbrain.click_point_size=str2double(get(hObject,'String'));
            %etc_render_fsbrain_handle('draw_pointer');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
        end

        % Value changed function: edit_click_vertex_point_size
        function edit_click_vertex_point_size_Callback(app, event)
            % hObject    handle to edit_click_vertex_point_size (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_click_vertex_point_size as text
            %        str2double(get(hObject,'String')) returns contents of edit_click_vertex_point_size as a double
            global etc_render_fsbrain;
            
            etc_render_fsbrain.click_vertex_point_size=str2double(get(hObject,'String'));
            %etc_render_fsbrain_handle('draw_pointer');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
        end

        % Value changed function: edit_selected_contact_size
        function edit_selected_contact_size_Callback(app, event)
            % hObject    handle to edit_selected_contact_size (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_selected_contact_size as text
            %        str2double(get(hObject,'String')) returns contents of edit_selected_contact_size as a double
            global etc_render_fsbrain;
            
            etc_render_fsbrain.selected_contact_size=str2double(get(hObject,'String'));
            %etc_render_fsbrain_handle('draw_pointer');
            try
                %if(isfield(etc_render_fsbrain,'click_coord')&&(isfield(etc_render_fsbrain,'click_vertex_vox')))
                    %etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
                %else
                    %etc_render_fsbrain_handle('draw_pointer');
                    etc_render_fsbrain_handle('redraw');
                %end;
            catch ME
            end
        end

        % Value changed function: edit_selected_electrode_size
        function edit_selected_electrode_size_Callback(app, event)
            % hObject    handle to edit_selected_electrode_size (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_selected_electrode_size as text
            %        str2double(get(hObject,'String')) returns contents of edit_selected_electrode_size as a double
            global etc_render_fsbrain;
            
            etc_render_fsbrain.selected_electrode_size=str2double(get(hObject,'String'));
            %etc_render_fsbrain_handle('draw_pointer');
            try
                %if(isfield(etc_render_fsbrain,'click_coord')&&(isfield(etc_render_fsbrain,'click_vertex_vox')))
                    %etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
                %else
                    %etc_render_fsbrain_handle('draw_pointer');
                    etc_render_fsbrain_handle('redraw');
                %end;
            catch ME
            end
        end

        % Value changed function: edit_smooth
        function edit_smooth_Callback(app, event)
            % hObject    handle to edit_smooth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_smooth as text
            %        str2double(get(hObject,'String')) returns contents of edit_smooth as a double
            global etc_render_fsbrain
            etc_render_fsbrain.overlay_smooth=str2double(get(hObject,'string'));
            etc_render_fsbrain_handle('redraw');
        end

        % Value changed function: edit_threshold_max
        function edit_threshold_max_Callback(app, event)
            % hObject    handle to edit_threshold_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_threshold_max as text
            %        str2double(get(hObject,'String')) returns contents of edit_threshold_max as a double
            global etc_render_fsbrain
            mx=str2double(get(hObject,'string'));
            mm=min(etc_render_fsbrain.overlay_threshold);
            etc_render_fsbrain.overlay_threshold=[mm,mx];
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
            etc_render_fsbrain_handle('redraw');
            etc_render_fsbrain_handle('kb','c0','c0');
        end

        % Value changed function: edit_threshold_min
        function edit_threshold_min_Callback(app, event)
            % hObject    handle to edit_threshold_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_threshold_min as text
            %        str2double(get(hObject,'String')) returns contents of edit_threshold_min as a double
            global etc_render_fsbrain
            mm=str2double(get(hObject,'string'));
            mx=max(etc_render_fsbrain.overlay_threshold);
            etc_render_fsbrain.overlay_threshold=[mm,mx];
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
            etc_render_fsbrain_handle('redraw');
            etc_render_fsbrain_handle('kb','c0','c0');
        end

        % Value changed function: edit_timeVec
        function edit_timeVec_Callback(app, event)
            % hObject    handle to edit_timeVec (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_timeVec as text
            %        str2double(get(hObject,'String')) returns contents of edit_timeVec as a double
            global etc_render_fsbrain
            time=str2double(get(hObject,'string'));
            [dummy, etc_render_fsbrain.overlay_stc_timeVec_idx]=min(abs(etc_render_fsbrain.overlay_stc_timeVec-time));
            
            if(~iscell(etc_render_fsbrain.overlay_value))
                etc_render_fsbrain.overlay_value=etc_render_fsbrain.overlay_stc(:,etc_render_fsbrain.overlay_stc_timeVec_idx);
            else
                for h_idx=1:length(etc_render_fsbrain.overlay_value)
                    etc_render_fsbrain.overlay_value{h_idx}=etc_render_fsbrain.overlay_stc_hemi{h_idx}(:,etc_render_fsbrain.overlay_stc_timeVec_idx);
                end;
            end;
            
            
            %update time index edit
            h=findobj('tag','slider_timeVec');
            set(h,'value',time);
            
            if(isempty(etc_render_fsbrain.overlay_stc_timeVec))
                fprintf('showing STC at time index [%d] (sample)\n',etc_render_fsbrain.overlay_stc_timeVec_idx);
            else
                fprintf('showing STC at time [%2.2f] ms\n',etc_render_fsbrain.overlay_stc_timeVec(etc_render_fsbrain.overlay_stc_timeVec_idx));
            end;
            
            
            etc_render_fsbrain_handle('update_overlay_vol');
            
            if(~isempty(etc_render_fsbrain.overlay_stc))
                etc_render_fsbrain_handle('draw_stc');
            end;
            
            %etc_render_fsbrain_handle('draw_pointer');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
            
            etc_render_fsbrain_handle('redraw');
        end

        % Button pushed function: pushbutton_auto_threshold
        function pushbutton_auto_threshold_Callback(app, event)
            % hObject    handle to pushbutton_auto_threshold (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global etc_render_fsbrain
            
            etc_render_fsbrain.overlay_threshold=[];
            
            etc_render_fsbrain_handle('redraw');
            
            set(app.edit_threshold_min,'string',sprintf('%2.1e',min(etc_render_fsbrain.overlay_threshold)));
            set(app.edit_threshold_max,'string',sprintf('%2.1e',max(etc_render_fsbrain.overlay_threshold)));
        end

        % Button pushed function: pushbutton_aux2_point_color
        function pushbutton_aux2_point_color_Callback(app, event)
            % hObject    handle to pushbutton_aux2_point_color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global etc_render_fsbrain;
            
            c = uisetcolor(etc_render_fsbrain.aux2_point_color,'Select a color');
            etc_render_fsbrain.aux2_point_color=c;
            set(app.pushbutton_aux2_point_color,'BackgroundColor',etc_render_fsbrain.aux2_point_color);
            etc_render_fsbrain_handle('redraw');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
        end

        % Button pushed function: pushbutton_aux_point_color
        function pushbutton_aux_point_color_Callback(app, event)
            % hObject    handle to pushbutton_aux_point_color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global etc_render_fsbrain;
            
            c = uisetcolor(etc_render_fsbrain.aux_point_color,'Select a color');
            etc_render_fsbrain.aux_point_color=c;
            set(app.pushbutton_aux_point_color,'BackgroundColor',etc_render_fsbrain.aux_point_color);
            etc_render_fsbrain_handle('redraw');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
        end

        % Button pushed function: pushbutton_click_point_color
        function pushbutton_click_point_color_Callback(app, event)
            % hObject    handle to pushbutton_click_point_color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global etc_render_fsbrain;
            
            c = uisetcolor(etc_render_fsbrain.click_point_color,'Select a color');
            etc_render_fsbrain.click_point_color=c;
            set(app.pushbutton_click_point_color,'BackgroundColor',etc_render_fsbrain.click_point_color);
            %etc_render_fsbrain_handle('draw_pointer');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
        end

        % Button pushed function: 
        % pushbutton_click_vertex_point_color
        function pushbutton_click_vertex_point_color_Callback(app, event)
            % hObject    handle to pushbutton_click_vertex_point_color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global etc_render_fsbrain;
            
            c = uisetcolor(etc_render_fsbrain.click_vertex_point_color,'Select a color');
            etc_render_fsbrain.click_vertex_point_color=c;
            set(app.pushbutton_click_vertex_point_color,'BackgroundColor',etc_render_fsbrain.click_vertex_point_color);
            %etc_render_fsbrain_handle('draw_pointer');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
        end

        % Button pushed function: pushbutton_selected_contact_color
        function pushbutton_selected_contact_color_Callback(app, event)
            % hObject    handle to pushbutton_selected_contact_color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global etc_render_fsbrain;
            
            c = uisetcolor(etc_render_fsbrain.selected_contact_color,'Select a color');
            etc_render_fsbrain.selected_contact_color=c;
            set(app.pushbutton_selected_contact_color,'BackgroundColor',etc_render_fsbrain.selected_contact_color);
            %etc_render_fsbrain_handle('draw_pointer');
            try
                %if(isfield(etc_render_fsbrain,'click_coord')&&(isfield(etc_render_fsbrain,'click_vertex_vox')))
                    %etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
                %else
                    %etc_render_fsbrain_handle('draw_pointer');
                    etc_render_fsbrain_handle('redraw');
                %end;
            catch ME
            end
        end

        % Button pushed function: 
        % pushbutton_selected_electrode_color
        function pushbutton_selected_electrode_color_Callback(app, event)
            % hObject    handle to pushbutton_selected_electrode_color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global etc_render_fsbrain;
            
            c = uisetcolor(etc_render_fsbrain.selected_electrode_color,'Select a color');
            etc_render_fsbrain.selected_electrode_color=c;
            set(app.pushbutton_selected_electrode_color,'BackgroundColor',etc_render_fsbrain.selected_electrode_color);
            %etc_render_fsbrain_handle('draw_pointer');
            try
                %if(isfield(etc_render_fsbrain,'click_coord')&&(isfield(etc_render_fsbrain,'click_vertex_vox')))
                    %etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
                %else
                    %etc_render_fsbrain_handle('draw_pointer');
                    etc_render_fsbrain_handle('redraw');
                %end;
            catch ME
            end
        end

        % Value changed function: slider_alpha
        function slider_alpha_Callback(app, event)
            % hObject    handle to slider_alpha (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global etc_render_fsbrain;
            
            set(etc_render_fsbrain.h,'facealpha',get(hObject,'Value'));
            etc_render_fsbrain.alpha=get(hObject,'Value');
            guidata(hObject, handles);
        end

        % Value changed function: slider_timeVec
        function slider_timeVec_Callback(app, event)
            % hObject    handle to slider_timeVec (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global etc_render_fsbrain
            time=get(hObject,'Value');
            [dummy, etc_render_fsbrain.overlay_stc_timeVec_idx]=min(abs(etc_render_fsbrain.overlay_stc_timeVec-time));
            
            if(~iscell(etc_render_fsbrain.overlay_value))
                etc_render_fsbrain.overlay_value=etc_render_fsbrain.overlay_stc(:,etc_render_fsbrain.overlay_stc_timeVec_idx);
            else
                for h_idx=1:length(etc_render_fsbrain.overlay_value)
                    etc_render_fsbrain.overlay_value{h_idx}=etc_render_fsbrain.overlay_stc_hemi{h_idx}(:,etc_render_fsbrain.overlay_stc_timeVec_idx);
                end;
            end;
            
            
            %update time index edit
            h=findobj('tag','edit_timeVec');
            set(h,'value',time);
            set(h,'string',sprintf('%1.1f',time));
            
            if(isempty(etc_render_fsbrain.overlay_stc_timeVec))
                fprintf('showing STC at time index [%d] (sample)\n',etc_render_fsbrain.overlay_stc_timeVec_idx);
            else
                fprintf('showing STC at time [%2.2f] ms\n',etc_render_fsbrain.overlay_stc_timeVec(etc_render_fsbrain.overlay_stc_timeVec_idx));
            end;
            
            etc_render_fsbrain_handle('update_overlay_vol');
            
            if(~isempty(etc_render_fsbrain.overlay_stc))
                etc_render_fsbrain_handle('draw_stc');
            end;
            
            %etc_render_fsbrain_handle('draw_pointer');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
            
            etc_render_fsbrain_handle('redraw');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'display GUI';
            app.uipanel1 = uipanel(app.figure1);
            app.text_status = uilabel(app.uipanel1);
            app.text_status.Text = ' ';
            app.slider_timeVec = uislider(app.uipanel1);
            app.slider_timeVec.Limits = [0 5];
            app.slider_timeVec.MajorTicks = [];
            app.slider_timeVec.ValueChangedFcn = createCallbackFcn(app, @slider_timeVec_Callback, true);
            app.slider_timeVec.MinorTicks = [];
            app.slider_timeVec.Enable = 'off';
            app.slider_timeVec.Value = 5;
            app.text_timeVec_min = uilabel(app.uipanel1);
            app.text_timeVec_min.Enable = 'off';
            app.text_timeVec_min.Text = '0';
            app.text_timeVec_max = uilabel(app.uipanel1);
            app.text_timeVec_max.Enable = 'off';
            app.text_timeVec_max.Text = '5';
            app.checkbox_show_overlay = uicheckbox(app.uipanel1);
            app.checkbox_show_overlay.ValueChangedFcn = createCallbackFcn(app, @checkbox_show_overlay_Callback, true);
            app.checkbox_show_overlay.Enable = 'off';
            app.checkbox_show_overlay.Text = 'Show overlay';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = 'Threshold min.';
            app.edit_threshold_min = uieditfield(app.uipanel1, 'text');
            app.edit_threshold_min.ValueChangedFcn = createCallbackFcn(app, @edit_threshold_min_Callback, true);
            app.edit_threshold_min.Value = ' ';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = 'Threshold max.';
            app.edit_threshold_max = uieditfield(app.uipanel1, 'text');
            app.edit_threshold_max.ValueChangedFcn = createCallbackFcn(app, @edit_threshold_max_Callback, true);
            app.edit_threshold_max.Value = ' ';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Text = 'Time vector';
            app.edit_timeVec = uieditfield(app.uipanel1, 'text');
            app.edit_timeVec.ValueChangedFcn = createCallbackFcn(app, @edit_timeVec_Callback, true);
            app.text10 = uilabel(app.uipanel1);
            app.text10.Text = 'Time:';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Text = '';
            app.checkbox_show_colorbar = uicheckbox(app.uipanel1);
            app.checkbox_show_colorbar.ValueChangedFcn = createCallbackFcn(app, @checkbox_show_colorbar_Callback, true);
            app.checkbox_show_colorbar.Enable = 'off';
            app.checkbox_show_colorbar.Text = 'Show colorbar';
            app.checkbox_show_colorbar.Value = true;
            app.edit_smooth = uieditfield(app.uipanel1, 'text');
            app.edit_smooth.ValueChangedFcn = createCallbackFcn(app, @edit_smooth_Callback, true);
            app.edit_smooth.Enable = 'off';
            app.edit_smooth.Value = '5';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Text = 'Smooth:';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Text = 'steps';
            app.slider_alpha = uislider(app.uipanel1);
            app.slider_alpha.Limits = [0 1];
            app.slider_alpha.MajorTicks = [];
            app.slider_alpha.ValueChangedFcn = createCallbackFcn(app, @slider_alpha_Callback, true);
            app.slider_alpha.MinorTicks = [];
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = 'surface opacity';
            app.pushbutton_auto_threshold = uibutton(app.uipanel1, 'push');
            app.pushbutton_auto_threshold.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_auto_threshold_Callback, true);
            app.pushbutton_auto_threshold.Text = 'a';
            app.uipanel2 = uipanel(app.figure1);
            app.edit_aux_point_size = uieditfield(app.uipanel2, 'text');
            app.edit_aux_point_size.ValueChangedFcn = createCallbackFcn(app, @edit_aux_point_size_Callback, true);
            app.edit_aux_point_size.Value = 'Edit Text';
            app.edit_aux2_point_size = uieditfield(app.uipanel2, 'text');
            app.edit_aux2_point_size.ValueChangedFcn = createCallbackFcn(app, @edit_aux2_point_size_Callback, true);
            app.edit_aux2_point_size.Value = 'Edit Text';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Text = 'aux point';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Text = 'aux2 point';
            app.pushbutton_aux_point_color = uibutton(app.uipanel2, 'push');
            app.pushbutton_aux_point_color.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_aux_point_color_Callback, true);
            app.pushbutton_aux_point_color.Text = ' ';
            app.pushbutton_aux2_point_color = uibutton(app.uipanel2, 'push');
            app.pushbutton_aux2_point_color.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_aux2_point_color_Callback, true);
            app.pushbutton_aux2_point_color.Text = ' ';
            app.checkbox_aux_point_label = uicheckbox(app.uipanel2);
            app.checkbox_aux_point_label.ValueChangedFcn = createCallbackFcn(app, @checkbox_aux_point_label_Callback, true);
            app.checkbox_aux_point_label.Text = '';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Text = 'aux point label';
            app.text29 = uilabel(app.uipanel2);
            app.text29.Text = '(electrodes and contacts)';
            app.uipanel3 = uipanel(app.figure1);
            app.pushbutton_click_vertex_point_color = uibutton(app.uipanel3, 'push');
            app.pushbutton_click_vertex_point_color.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_click_vertex_point_color_Callback, true);
            app.pushbutton_click_vertex_point_color.Text = ' ';
            app.pushbutton_click_point_color = uibutton(app.uipanel3, 'push');
            app.pushbutton_click_point_color.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_click_point_color_Callback, true);
            app.pushbutton_click_point_color.Text = ' ';
            app.text21 = uilabel(app.uipanel3);
            app.text21.Text = 'clicked vertex point';
            app.text20 = uilabel(app.uipanel3);
            app.text20.Text = 'clicked point';
            app.edit_click_vertex_point_size = uieditfield(app.uipanel3, 'text');
            app.edit_click_vertex_point_size.ValueChangedFcn = createCallbackFcn(app, @edit_click_vertex_point_size_Callback, true);
            app.edit_click_vertex_point_size.Value = 'Edit Text';
            app.edit_click_point_size = uieditfield(app.uipanel3, 'text');
            app.edit_click_point_size.ValueChangedFcn = createCallbackFcn(app, @edit_click_point_size_Callback, true);
            app.edit_click_point_size.Value = 'Edit Text';
            app.checkbox_nearest_brain_surface = uicheckbox(app.uipanel3);
            app.checkbox_nearest_brain_surface.ValueChangedFcn = createCallbackFcn(app, @checkbox_nearest_brain_surface_Callback, true);
            app.checkbox_nearest_brain_surface.Text = '';
            app.uipanel4 = uipanel(app.figure1);
            app.text23 = uilabel(app.uipanel4);
            app.text23.Text = 'truncate neg. overlay';
            app.checkbox_overlay_truncate_neg = uicheckbox(app.uipanel4);
            app.checkbox_overlay_truncate_neg.ValueChangedFcn = createCallbackFcn(app, @checkbox_overlay_truncate_neg_Callback, true);
            app.checkbox_overlay_truncate_neg.Text = '';
            app.text25 = uilabel(app.uipanel4);
            app.text25.Text = 'truncate pos. overlay';
            app.checkbox_overlay_truncate_pos = uicheckbox(app.uipanel4);
            app.checkbox_overlay_truncate_pos.ValueChangedFcn = createCallbackFcn(app, @checkbox_overlay_truncate_pos_Callback, true);
            app.checkbox_overlay_truncate_pos.Text = '';
            app.uipanel5 = uipanel(app.figure1);
            app.pushbutton_selected_contact_color = uibutton(app.uipanel5, 'push');
            app.pushbutton_selected_contact_color.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selected_contact_color_Callback, true);
            app.pushbutton_selected_contact_color.Text = ' ';
            app.text26 = uilabel(app.uipanel5);
            app.text26.Text = 'selected contact';
            app.edit_selected_contact_size = uieditfield(app.uipanel5, 'text');
            app.edit_selected_contact_size.ValueChangedFcn = createCallbackFcn(app, @edit_selected_contact_size_Callback, true);
            app.edit_selected_contact_size.Value = 'Edit Text';
            app.checkbox_selected_contact = uicheckbox(app.uipanel5);
            app.checkbox_selected_contact.ValueChangedFcn = createCallbackFcn(app, @checkbox_selected_contact_Callback, true);
            app.checkbox_selected_contact.Text = '';
            app.text28 = uilabel(app.uipanel5);
            app.text28.Text = 'selected electrode';
            app.checkbox_selected_electrode = uicheckbox(app.uipanel5);
            app.checkbox_selected_electrode.ValueChangedFcn = createCallbackFcn(app, @checkbox_selected_electrode_Callback, true);
            app.checkbox_selected_electrode.Text = '';
            app.pushbutton_selected_electrode_color = uibutton(app.uipanel5, 'push');
            app.pushbutton_selected_electrode_color.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_selected_electrode_color_Callback, true);
            app.pushbutton_selected_electrode_color.Text = ' ';
            app.edit_selected_electrode_size = uieditfield(app.uipanel5, 'text');
            app.edit_selected_electrode_size.ValueChangedFcn = createCallbackFcn(app, @edit_selected_electrode_size_Callback, true);
            app.edit_selected_electrode_size.Value = 'Edit Text';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_gui_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @etc_render_fsbrain_gui_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end