classdef etc_render_fsbrain_montage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MontageUIFigure           matlab.ui.Figure
        SliceindexEditField       matlab.ui.control.EditField
        SliceindexEditFieldLabel  matlab.ui.control.Label
        InfoLabel                 matlab.ui.control.Label
        ShowButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        slice_index % Description
    end
    
    methods (Access = public)
        
        function results = make_montage(app)
            global etc_render_fsbrain;
            slice_idx=app.slice_index;
            for s_idx=1:length(slice_idx)
                img_mont(:,:,s_idx)=rot90(squeeze(etc_render_fsbrain.vol.vol(slice_idx(s_idx),:,:)));
            end;

            if(~isempty(etc_render_fsbrain.overlay_vol))
                if(ndims(etc_render_fsbrain.overlay_vol.vol)==4)
                    for s_idx=1:length(slice_idx)
                        img_mont_overlay(:,:,s_idx)=rot90(squeeze(etc_render_fsbrain.overlay_vol.vol(slice_idx(s_idx),:,:,etc_render_fsbrain.overlay_stc_timeVec_idx)));
                    end;
                elseif(ndims(etc_render_fsbrain.overlay_vol.vol)==3)
                    for s_idx=1:length(slice_idx)
                        img_mont_overlay(:,:,s_idx)=rot90(squeeze(etc_render_fsbrain.overlay_vol.vol(slice_idx(s_idx),:,:)));
                    end;
                end;

                %truncate positive value overlay
                if(etc_render_fsbrain.flag_overlay_truncate_pos)
                    idx=find(img_mont_overlay(:)>0);
                    img_mont_overlay(idx)=0;
                end;

                %truncate negative value overlay
                if(etc_render_fsbrain.flag_overlay_truncate_neg)
                    idx=find(img_mont_overlay(:)<0);
                    img_mont_overlay(idx)=0;
                end;
            else
                img_mont_overlay=[];
            end;

            if(~etc_render_fsbrain.overlay_vol_flag_render)
                img_mont_overlay=[];
            end;


            ff=figure; set(ff,'visible','off'); h=montage(img_mont); etc_render_fsbrain.img_mont2d=h.CData; close(ff);
            if(~isempty(img_mont_overlay))
                ff=figure; set(ff,'visible','off'); h=montage(img_mont_overlay); etc_render_fsbrain.img_mont_overlay2d=h.CData; close(ff);
            end;

            if(~isempty(etc_render_fsbrain.overlay_vol))
                etc_render_fsbrain.overlay_mont2d_c=zeros(size(etc_render_fsbrain.img_mont2d,1)*size(etc_render_fsbrain.img_mont2d,2),3);

                c_idx=[1:prod(size(etc_render_fsbrain.img_mont2d))];
                mmax=max(etc_render_fsbrain.img_mont2d(:));
                mmin=min(etc_render_fsbrain.img_mont2d(:));
                etc_render_fsbrain.overlay_mont2d_c(c_idx,:)=inverse_get_color(gray(128),etc_render_fsbrain.img_mont2d(c_idx),mmax,mmin);

                c_idx=find(etc_render_fsbrain.img_mont_overlay2d(:)>=min(etc_render_fsbrain.overlay_threshold));

                etc_render_fsbrain.overlay_mont2d_c(c_idx,:)=inverse_get_color(etc_render_fsbrain.overlay_cmap,etc_render_fsbrain.img_mont_overlay2d(c_idx),max(etc_render_fsbrain.overlay_threshold),min(etc_render_fsbrain.overlay_threshold));

                c_idx=find(etc_render_fsbrain.img_mont_overlay2d(:)<=-min(etc_render_fsbrain.overlay_threshold));

                etc_render_fsbrain.overlay_mont2d_c(c_idx,:)=inverse_get_color(etc_render_fsbrain.overlay_cmap_neg,-etc_render_fsbrain.img_mont_overlay2d(c_idx),max(etc_render_fsbrain.overlay_threshold),min(etc_render_fsbrain.overlay_threshold));
            else
                etc_render_fsbrain.overlay_mont2d_c=zeros(size(etc_render_fsbrain.img_mont2d,1)*size(etc_render_fsbrain.img_mont2d,2),3);

                c_idx=[1:prod(size(etc_render_fsbrain.img_mont2d))];
                mmax=max(etc_render_fsbrain.img_mont2d(:));
                mmin=min(etc_render_fsbrain.img_mont2d(:));
                etc_render_fsbrain.overlay_mont2d_c(c_idx,:)=inverse_get_color(gray(128),etc_render_fsbrain.img_mont2d(c_idx),mmax,mmin);
            end;

            etc_render_fsbrain.overlay_mont2d_c=reshape(etc_render_fsbrain.overlay_mont2d_c,[ size(etc_render_fsbrain.img_mont2d,1), size(etc_render_fsbrain.img_mont2d,2),3]);

            if(isfield(etc_render_fsbrain,'fig_montage'))
                if(isvalid(etc_render_fsbrain.fig_montage))
                    figure(etc_render_fsbrain.fig_montage);
                else
                    figure(app.MontageUIFigure);
                    etc_render_fsbrain.fig_montage=app.MontageUIFigure;
                end;
            else
                figure(app.MontageUIFigure);
            end;
            %axes(app.UIAxes);
            %image(etc_render_fsbrain.overlay_mont2d_c); hold on;
            imshow(etc_render_fsbrain.overlay_mont2d_c, 'Parent', app.UIAxes);
            axis off image;
            etc_plotstyle;
            set(app.MontageUIFigure,'color','k')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_render_fsbrain;

            app.SliceindexEditField.Value=sprintf('1:1:%d',size(etc_render_fsbrain.vol.vol,3));
            app.slice_index=eval(app.SliceindexEditField.Value);
            make_montage(app);

        end

        % Value changed function: SliceindexEditField
        function SliceindexEditFieldValueChanged(app, event)
            value = app.SliceindexEditField.Value;
            try
                app.slice_index=eval(value);

                global etc_render_fsbrain;
                if(~isempty(etc_render_fsbrain.vol))
                    if(min(app.slice_index)>=1&max(app.slice_index)<=max(size(etc_render_fsbrain.vol.vol)))
                        make_montage(app);

                    else
                        return;
                    end;
                end;
            catch
                
            end;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            make_montage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MontageUIFigure = uifigure('Visible', 'off');
            app.MontageUIFigure.Name = 'Montage';
            app.UIAxes = uiaxes(app.MontageUIFigure);
            app.ShowButton = uibutton(app.MontageUIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.InfoLabel = uilabel(app.MontageUIFigure);
            app.InfoLabel.Text = 'Info';
            app.SliceindexEditFieldLabel = uilabel(app.MontageUIFigure);
            app.SliceindexEditFieldLabel.Text = 'Slice index';
            app.SliceindexEditField = uieditfield(app.MontageUIFigure, 'text');
            app.SliceindexEditField.ValueChangedFcn = createCallbackFcn(app, @SliceindexEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.MontageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_montage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MontageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MontageUIFigure)
        end
    end
end