classdef etc_render_fsbrain_register_object < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Move3DobjectUIFigure  matlab.ui.Figure
        centerlineCheckBox    matlab.ui.control.CheckBox
        ExportSaveButton      matlab.ui.control.Button
        ExportButton          matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        TranslationPanel      matlab.ui.container.Panel
        mmLabel               matlab.ui.control.Label
        EditMove              matlab.ui.control.NumericEditField
        ButtonMoveDown        matlab.ui.control.Button
        ButtonMoveRight       matlab.ui.control.Button
        ButtonMoveLeft        matlab.ui.control.Button
        ButtonMoveUp          matlab.ui.control.Button
        RotationPanel         matlab.ui.container.Panel
        axisCheckBox          matlab.ui.control.CheckBox
        degLabel              matlab.ui.control.Label
        EditRotate            matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        ButtonRotateC         matlab.ui.control.Button
        ButtonRotateCC        matlab.ui.control.Button
    end


    properties (Access = public)
        Property % Description
    end
    
    properties (Access = private)
        Axis_orig;
        Up_orig;
        Origin_orig;
        Object_xfm_orig;% Description
        Vertices_orig;
    end
    
    methods (Access = private)
        
        function results = update_centerline(app)
            global etc_render_fsbrain;

            if(isfield(etc_render_fsbrain,'object_line'))
                if(isvalid(etc_render_fsbrain.object_line))
                    delete(etc_render_fsbrain.object_line);

                    if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                        if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                            X1=etc_render_fsbrain.object.UserData.Origin(1);
                            Y1=etc_render_fsbrain.object.UserData.Origin(2);
                            Z1=etc_render_fsbrain.object.UserData.Origin(3);

                            X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                            Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                            Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                            etc_render_fsbrain.object_line=line([X1 X2],[Y1 Y2],[Z1 Z2]);
                            set(etc_render_fsbrain.object_line,'linewidth',2);
                        end;
                    end;
                end;
            end;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_render_fsbrain;


            app.Vertices_orig=etc_render_fsbrain.object.Vertices;

            app.Origin_orig=etc_render_fsbrain.object_xfm(1:3,4).*1e3;
            app.Axis_orig=-etc_render_fsbrain.object_xfm(1:3,3);
            app.Up_orig=etc_render_fsbrain.object_xfm(1:3,2);
            
            app.Object_xfm_orig=etc_render_fsbrain.object_xfm;

            %initialize the transformation matrix if necessary
            if(isfield(etc_render_fsbrain,'object'))
                if(~isempty(etc_render_fsbrain.object))
                    if(~isfield(etc_render_fsbrain,'object_xfm'))
                        etc_render_fsbrain.object_xfm=eye(4);
                        etc_render_fsbrain.object.Vertices_orig=etc_render_fsbrain.object.Vertices;
                    end;
                end;
            end;

            etc_render_fsbrain.register_rotate_angle=3; %default: 3 degrees
            app.EditRotate.Value=etc_render_fsbrain.register_rotate_angle;

            etc_render_fsbrain.register_translate_dist=1e-3; %default: 1 mm
            app.EditMove.Value=etc_render_fsbrain.register_translate_dist.*1e3;


        end

        % Button pushed function: ButtonRotateCC
        function ButtonRotateCCPushed(app, event)
            global etc_render_fsbrain
            figure(etc_render_fsbrain.fig_brain);


            if(app.axisCheckBox.Value) %reset origin by translation
                R=eye(4);
                R(1:3,4)=-etc_render_fsbrain.object.UserData.Origin(:);
                Origin_orig=etc_render_fsbrain.object.UserData.Origin(:);

                if(~isempty(etc_render_fsbrain.object))
                    etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object.Vertices+repmat(-Origin_orig',[size(etc_render_fsbrain.object.Vertices,1),1]);
                    try
                        %etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis-Origin_orig;
                    catch
                    end;
                    try
                        %etc_render_fsbrain.object.UserData.Up=etc_render_fsbrain.object.UserData.Up-Origin_orig;
                    catch
                    end;
                    try
                        etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin(:)-Origin_orig(:);
                    catch
                    end;
                    app.update_centerline;
                end;

                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=R;
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;
                end;
            end;

            if(app.axisCheckBox.Value==0)
                [az,el]=view;
                [x,y,z] = sph2cart((az-90)*pi/180,el*pi/180,1);
            else
                %tmp=etc_render_fsbrain.object.UserData.Axis-etc_render_fsbrain.object.UserData.Origin;
                tmp=etc_render_fsbrain.object.UserData.Axis;
                tmp=tmp./norm(tmp);
                x=tmp(1);
                y=tmp(2);
                z=tmp(3);
            end;            

            theta=(etc_render_fsbrain.register_rotate_angle).*pi/180;
            R=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix

            if(~isempty(etc_render_fsbrain.object))
                etc_render_fsbrain.object.Vertices=(R*etc_render_fsbrain.object.Vertices').';
                try
                    etc_render_fsbrain.object.UserData.Axis=R*etc_render_fsbrain.object.UserData.Axis;
                catch
                end;
                try
                    etc_render_fsbrain.object.UserData.Up=R*etc_render_fsbrain.object.UserData.Up;
                catch
                end;
                try
                    etc_render_fsbrain.object.UserData.Origin=R*etc_render_fsbrain.object.UserData.Origin(:);
                catch
                end;
                app.update_centerline;
            end;

            if(~isempty(etc_render_fsbrain.object))
                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=R;
                    Rtmp(4,:)=0;
                    Rtmp(:,4)=0;
                    Rtmp(4,4)=1;
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;

                end;
            end;

            if(app.axisCheckBox.Value) %reset origin by translation
                R=eye(4);
                R(1:3,4)=Origin_orig;

                if(~isempty(etc_render_fsbrain.object))
                    etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object.Vertices+repmat(Origin_orig',[size(etc_render_fsbrain.object.Vertices,1),1]);
                    try
                        %etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis+Origin_orig;
                    catch
                    end;
                    try
                        %etc_render_fsbrain.object.UserData.Up=etc_render_fsbrain.object.UserData.Up+Origin_orig;
                    catch
                    end;
                    try
                        etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin+Origin_orig;
                    catch
                    end;
                    app.update_centerline;
                end;

                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=R;
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;

                end;
            end;

            %update coil up/norm vectors; if any
            if(isfield(etc_render_fsbrain,'app_tms_nav'))
                if(~isempty(etc_render_fsbrain.app_tms_nav))
                    etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,[]);
                end;
            end;

        end

        % Button pushed function: ButtonRotateC
        function ButtonRotateCPushed(app, event)
            global etc_render_fsbrain
            figure(etc_render_fsbrain.fig_brain);

            if(app.axisCheckBox.Value) %reset origin by translation
                R=eye(4);
                R(1:3,4)=-etc_render_fsbrain.object.UserData.Origin(:);
                Origin_orig=etc_render_fsbrain.object.UserData.Origin(:);

                if(~isempty(etc_render_fsbrain.object))
                    etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object.Vertices+repmat(-Origin_orig',[size(etc_render_fsbrain.object.Vertices,1),1]);
                    try
                        %etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis-Origin_orig;
                    catch
                    end;
                    try
                        %etc_render_fsbrain.object.UserData.Up=etc_render_fsbrain.object.UserData.Up-Origin_orig;
                    catch
                    end;
                    try
                        etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin(:)-Origin_orig(:);
                    catch
                    end;
                    app.update_centerline;
                end;

                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=R;
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;
                end;
            end;

            if(app.axisCheckBox.Value==0)
                [az,el]=view;
                [x,y,z] = sph2cart((az-90)*pi/180,el*pi/180,1);
            else
                %tmp=etc_render_fsbrain.object.UserData.Axis-etc_render_fsbrain.object.UserData.Origin;
                tmp=etc_render_fsbrain.object.UserData.Axis;
                tmp=tmp./norm(tmp);
                x=tmp(1);
                y=tmp(2);
                z=tmp(3);
            end;

            theta=-(etc_render_fsbrain.register_rotate_angle).*pi/180;
            R=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix

            if(~isempty(etc_render_fsbrain.object))
                etc_render_fsbrain.object.Vertices=(R*etc_render_fsbrain.object.Vertices').';
                try
                    etc_render_fsbrain.object.UserData.Axis=R*etc_render_fsbrain.object.UserData.Axis(:);
                catch
                end;
                try
                    etc_render_fsbrain.object.UserData.Up=R*etc_render_fsbrain.object.UserData.Up(:);
                catch
                end;
                try
                    etc_render_fsbrain.object.UserData.Origin=R*etc_render_fsbrain.object.UserData.Origin(:);
                catch
                end;
                app.update_centerline;
            end;

            if(~isempty(etc_render_fsbrain.object))
                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=R;
                    Rtmp(4,:)=0;
                    Rtmp(:,4)=0;
                    Rtmp(4,4)=1;
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;

                end;
            end;

            if(app.axisCheckBox.Value) %reset origin by translation
                R=eye(4);
                R(1:3,4)=Origin_orig;

                if(~isempty(etc_render_fsbrain.object))
                    etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object.Vertices+repmat(Origin_orig',[size(etc_render_fsbrain.object.Vertices,1),1]);
                    try
                        %etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis+Origin_orig;
                    catch
                    end;
                    try
                        %etc_render_fsbrain.object.UserData.Up=etc_render_fsbrain.object.UserData.Up+Origin_orig;
                    catch
                    end;
                    try
                        etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin+Origin_orig;
                    catch
                    end;
                    app.update_centerline;
                end;

                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=R;
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;

                end;
            end;

            %update coil up/norm vectors; if any
            if(isfield(etc_render_fsbrain,'app_tms_nav'))
                if(~isempty(etc_render_fsbrain.app_tms_nav))
                    etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,[]);
                end;
            end;

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            global etc_render_fsbrain

            if(~isempty(etc_render_fsbrain.object))
                object_xfm=(etc_render_fsbrain.object_xfm);

                assignin('base','object_xfm',object_xfm);
                fprintf('variables "object_xfm" exported\n');


                if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                    if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        X1=etc_render_fsbrain.object.UserData.Origin(1);
                        Y1=etc_render_fsbrain.object.UserData.Origin(2);
                        Z1=etc_render_fsbrain.object.UserData.Origin(3);

                        X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                        Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                        Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                        object_line=[X1 X2; Y1 Y2; Z1 Z2];

                        assignin('base','object_line',object_line);
                        fprintf('variables "object_line" exported\n');
                        

                    end;
                end;
            end;
        end

        % Value changed function: EditMove
        function EditMoveValueChanged(app, event)
            global etc_render_fsbrain;

            value = app.EditMove.Value;
            etc_render_fsbrain.register_translate_dist=value./1e3;
        end

        % Value changed function: EditRotate
        function EditRotateValueChanged(app, event)
            global etc_render_fsbrain

            value = app.EditRotate.Value;
            etc_render_fsbrain.register_rotate_angle=value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global etc_render_fsbrain;

            %etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object_Vertices_orig;

            etc_render_fsbrain.object.Vertices=app.Vertices_orig;
            etc_render_fsbrain.object_xfm=app.Object_xfm_orig;
            etc_render_fsbrain.object.UserData.Axis=-etc_render_fsbrain.object_xfm(1:3,3);
            etc_render_fsbrain.object.UserData.Up=etc_render_fsbrain.object_xfm(1:3,2);
            etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object_xfm(1:3,4).*1e3;


            %update coil up/norm vectors; if any
            if(isfield(etc_render_fsbrain,'app_tms_nav'))
                if(~isempty(etc_render_fsbrain.app_tms_nav))
                    etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,[]);
                end;
            end;


%             if(isfield(etc_render_fsbrain.object.UserData,'Axis_orig'))
%                 etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis_orig;
%             end;
%             if(isfield(etc_render_fsbrain.object.UserData,'Up_orig'))
%                 etc_render_fsbrain.object.UserData.Up=etc_render_fsbrain.object.UserData.Up_orig;
%             end;
%             if(isfield(etc_render_fsbrain.object.UserData,'Origin_orig'))
%                 etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin_orig;
%             end;
%            app.update_centerline;
            
            %etc_render_fsbrain.object_xfm=eye(4);
            

        end

        % Button pushed function: ExportSaveButton
        function ExportSaveButtonPushed(app, event)
            global etc_render_fsbrain

            if(~isempty(etc_render_fsbrain.object))
                object_xfm=(etc_render_fsbrain.object_xfm);

                assignin('base','object_xfm',object_xfm);
                fprintf('variables "object_xfm" exported\n');

                if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                    if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        X1=etc_render_fsbrain.object.UserData.Origin(1);
                        Y1=etc_render_fsbrain.object.UserData.Origin(2);
                        Z1=etc_render_fsbrain.object.UserData.Origin(3);

                        X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                        Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                        Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                        object_line=[X1 X2; Y1 Y2; Z1 Z2];

                        assignin('base','object_line',object_line);
                        fprintf('variables "object_line" exported\n');
                        

                    end;
                end;


                filename = uigetfile;
                if(filename)
                    object_xfm=(etc_render_fsbrain.object_xfm);
                    save(filename,'-append','object_xfm');
                    fprintf('variable "object_xfm" exported and saved in [%s]\n',filename);


                    if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                        if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                            X1=etc_render_fsbrain.object.UserData.Origin(1);
                            Y1=etc_render_fsbrain.object.UserData.Origin(2);
                            Z1=etc_render_fsbrain.object.UserData.Origin(3);

                            X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                            Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                            Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                            object_line=[X1 X2; Y1 Y2; Z1 Z2];

                            save(filename,'-append','object_line');
                            fprintf('variables "object_line" exported and saved in [%s]\n',filename);
                        end;
                    end;
                end;
            end;
        end

        % Button pushed function: ButtonMoveUp
        function ButtonMoveUpPushed(app, event)
            global etc_render_fsbrain
            figure(etc_render_fsbrain.fig_brain);
            [az,el]=view;
            [x,y,z] = sph2cart((az-90)*pi/180,el*pi/180,1);
            [xr,yr,zr] = sph2cart((az)*pi/180,0,1);
            oo=[x,y,z]'; %outward screen unit vector
            rr=[xr,yr,zr]'; %right screen unit vector
            uu = cross(oo,rr); %up screen unit vector

            dist=etc_render_fsbrain.register_translate_dist.*1e3;
            if(~isempty(etc_render_fsbrain.object))
                etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object.Vertices+repmat(uu.'.*dist,[size(etc_render_fsbrain.object.Vertices,1),1]);
                try
                    %etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis;
                catch
                end;
                try
                    etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin(:)+uu(:).*dist;
                catch
                end;
                app.update_centerline;
            end;

            if(~isempty(etc_render_fsbrain.object))
                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=eye(4);
                    Rtmp(1:3,4)=(uu.'.*dist.*1e-3)';
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;
                end;
            end;

            %update coil up/norm vectors; if any
            if(isfield(etc_render_fsbrain,'app_tms_nav'))
                if(~isempty(etc_render_fsbrain.app_tms_nav))
                    etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,[]);
                end;
            end;

        end

        % Button pushed function: ButtonMoveDown
        function ButtonMoveDownPushed(app, event)
            global etc_render_fsbrain
            figure(etc_render_fsbrain.fig_brain);
            [az,el]=view;
            [x,y,z] = sph2cart((az-90)*pi/180,el*pi/180,1);
            [xr,yr,zr] = sph2cart((az)*pi/180,0,1);
            oo=[x,y,z]'; %outward screen unit vector
            rr=[xr,yr,zr]'; %right screen unit vector
            uu = cross(oo,rr); %up screen unit vector

            dist=-(etc_render_fsbrain.register_translate_dist.*1e3);
            if(~isempty(etc_render_fsbrain.object))
                %etc_render_fsbrain.object.Vertices=(R*etc_render_fsbrain.object.Vertices').';
                etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object.Vertices+repmat(uu.'.*dist,[size(etc_render_fsbrain.object.Vertices,1),1]);
                try
                    %etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis;
                catch
                end;
                try
                    etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin(:)+uu(:).*dist;
                catch
                end;
                app.update_centerline;
            end;

            if(~isempty(etc_render_fsbrain.object))
                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=eye(4);
                    Rtmp(1:3,4)=(uu.'.*dist.*1e-3)';
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;
                end;
            end;

            %update coil up/norm vectors; if any
            if(isfield(etc_render_fsbrain,'app_tms_nav'))
                if(~isempty(etc_render_fsbrain.app_tms_nav))
                    etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,[]);
                end;
            end;

        end

        % Button pushed function: ButtonMoveLeft
        function ButtonMoveLeftPushed(app, event)
            global etc_render_fsbrain
            figure(etc_render_fsbrain.fig_brain);
            [az,el]=view;
            [x,y,z] = sph2cart((az-90)*pi/180,el*pi/180,1);
            [xr,yr,zr] = sph2cart((az)*pi/180,0,1);
            oo=[x,y,z]'; %outward screen unit vector
            rr=[xr,yr,zr]'; %right screen unit vector
            uu = cross(oo,rr); %up screen unit vector

            dist=-(etc_render_fsbrain.register_translate_dist.*1e3);
            if(~isempty(etc_render_fsbrain.object))
                %etc_render_fsbrain.object.Vertices=(R*etc_render_fsbrain.object.Vertices').';
                etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object.Vertices+repmat(rr.'.*dist,[size(etc_render_fsbrain.object.Vertices,1),1]);
                try
                    %etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis;
                catch
                end;
                try
                    etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin(:)+rr(:).*dist;
                catch
                end;
                app.update_centerline;
            end;

            if(~isempty(etc_render_fsbrain.object))
                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=eye(4);
                    Rtmp(1:3,4)=(rr.'.*dist.*1e-3)';
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;
                end;
            end;

            %update coil up/norm vectors; if any
            if(isfield(etc_render_fsbrain,'app_tms_nav'))
                if(~isempty(etc_render_fsbrain.app_tms_nav))
                    etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,[]);
                end;
            end;
            
        end

        % Button pushed function: ButtonMoveRight
        function ButtonMoveRightPushed(app, event)
            global etc_render_fsbrain
            figure(etc_render_fsbrain.fig_brain);
            [az,el]=view;
            [x,y,z] = sph2cart((az-90)*pi/180,el*pi/180,1);
            [xr,yr,zr] = sph2cart((az)*pi/180,0,1);
            oo=[x,y,z]'; %outward screen unit vector
            rr=[xr,yr,zr]'; %right screen unit vector
            uu = cross(oo,rr); %up screen unit vector

            dist=etc_render_fsbrain.register_translate_dist.*1e3;
            if(~isempty(etc_render_fsbrain.object))
                %etc_render_fsbrain.object.Vertices=(R*etc_render_fsbrain.object.Vertices').';
                etc_render_fsbrain.object.Vertices=etc_render_fsbrain.object.Vertices+repmat(rr.'.*dist,[size(etc_render_fsbrain.object.Vertices,1),1]);
                try
                    %etc_render_fsbrain.object.UserData.Axis=etc_render_fsbrain.object.UserData.Axis;
                catch
                end;
                try
                    etc_render_fsbrain.object.UserData.Origin=etc_render_fsbrain.object.UserData.Origin(:)+rr(:).*dist;
                catch
                end;
                app.update_centerline;
            end;

            if(~isempty(etc_render_fsbrain.object))
                if(isfield(etc_render_fsbrain,'object_xfm'))
                    if(isempty(etc_render_fsbrain.object_xfm))
                        etc_render_fsbrain.object_xfm=eye(4);
                    end;
                    Rtmp=eye(4);
                    Rtmp(1:3,4)=(rr.'.*dist.*1e-3)';
                    etc_render_fsbrain.object_xfm=(Rtmp)*etc_render_fsbrain.object_xfm;
                end;
            end;

            %update coil up/norm vectors; if any
            if(isfield(etc_render_fsbrain,'app_tms_nav'))
                if(~isempty(etc_render_fsbrain.app_tms_nav))
                    etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,[]);
                end;
            end;
         
        end

        % Value changed function: centerlineCheckBox
        function centerlineCheckBoxValueChanged(app, event)
            value = app.centerlineCheckBox.Value;
            global etc_render_fsbrain

            %etc_render_fsbrain.object_line=[];
            %delete(etc_render_fsbrain.object_line);

            if(value==true)
               if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                   if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        X1=etc_render_fsbrain.object.UserData.Origin(1);
                        Y1=etc_render_fsbrain.object.UserData.Origin(2);
                        Z1=etc_render_fsbrain.object.UserData.Origin(3);
                        
                        X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                        Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                        Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                        etc_render_fsbrain.object_line=line([X1 X2],[Y1 Y2],[Z1 Z2]);
                        set(etc_render_fsbrain.object_line,'linewidth',2);

                   end;
               end;
            else
                delete(etc_render_fsbrain.object_line);
            end;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Move3DobjectUIFigure = uifigure('Visible', 'off');
            app.Move3DobjectUIFigure.Name = 'Move 3D object';
            app.RotationPanel = uipanel(app.Move3DobjectUIFigure);
            app.RotationPanel.Title = 'Rotation';
            app.ButtonRotateCC = uibutton(app.RotationPanel, 'push');
            app.ButtonRotateCC.ButtonPushedFcn = createCallbackFcn(app, @ButtonRotateCCPushed, true);
            app.ButtonRotateCC.Text = 'CC';
            app.ButtonRotateC = uibutton(app.RotationPanel, 'push');
            app.ButtonRotateC.ButtonPushedFcn = createCallbackFcn(app, @ButtonRotateCPushed, true);
            app.ButtonRotateC.Text = 'C';
            app.Label = uilabel(app.RotationPanel);
            app.Label.Text = '';
            app.EditRotate = uieditfield(app.RotationPanel, 'numeric');
            app.EditRotate.ValueChangedFcn = createCallbackFcn(app, @EditRotateValueChanged, true);
            app.EditRotate.Value = 3;
            app.degLabel = uilabel(app.RotationPanel);
            app.degLabel.Text = '(deg)';
            app.axisCheckBox = uicheckbox(app.RotationPanel);
            app.axisCheckBox.Text = 'axis';
            app.TranslationPanel = uipanel(app.Move3DobjectUIFigure);
            app.TranslationPanel.Title = 'Translation';
            app.ButtonMoveUp = uibutton(app.TranslationPanel, 'push');
            app.ButtonMoveUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonMoveUpPushed, true);
            app.ButtonMoveUp.Text = '^';
            app.ButtonMoveLeft = uibutton(app.TranslationPanel, 'push');
            app.ButtonMoveLeft.ButtonPushedFcn = createCallbackFcn(app, @ButtonMoveLeftPushed, true);
            app.ButtonMoveLeft.Text = '<';
            app.ButtonMoveRight = uibutton(app.TranslationPanel, 'push');
            app.ButtonMoveRight.ButtonPushedFcn = createCallbackFcn(app, @ButtonMoveRightPushed, true);
            app.ButtonMoveRight.Text = '>';
            app.ButtonMoveDown = uibutton(app.TranslationPanel, 'push');
            app.ButtonMoveDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonMoveDownPushed, true);
            app.ButtonMoveDown.Text = 'V';
            app.EditMove = uieditfield(app.TranslationPanel, 'numeric');
            app.EditMove.ValueChangedFcn = createCallbackFcn(app, @EditMoveValueChanged, true);
            app.EditMove.Value = 5;
            app.mmLabel = uilabel(app.TranslationPanel);
            app.mmLabel.Text = '(mm)';
            app.ResetButton = uibutton(app.Move3DobjectUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportButton = uibutton(app.Move3DobjectUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExportSaveButton = uibutton(app.Move3DobjectUIFigure, 'push');
            app.ExportSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSaveButtonPushed, true);
            app.ExportSaveButton.Text = 'Export + Save';
            app.centerlineCheckBox = uicheckbox(app.Move3DobjectUIFigure);
            app.centerlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @centerlineCheckBoxValueChanged, true);
            app.centerlineCheckBox.Text = 'center line';
            % Show the figure after all components are created
            app.Move3DobjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_register_object

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Move3DobjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Move3DobjectUIFigure)
        end
    end
end