classdef etc_render_fsbrain_surf_contour < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SurfacecontoursPanel  matlab.ui.container.Panel
        AddButton             matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = public)
        surf_category=categorical({'skull','skin','GM','WM','CSF','unspecified'});
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%             file_surf{1,1}='Users/fhlin/workspace/eegmri_wm/subjects/s001/bem/inner_skill.surf';
%             flag_show_surf{1,1}=boolean(1);
%             color_surf{1,1}=[1 0 0];
%             file_surf{2,1}='Users/fhlin/workspace/eegmri_wm/subjects/s001/bem/outer_skill.surf';
%             flag_show_surf{2,1}=boolean(1);
%             color_surf{2,1}=[1 0 0];
%             file_surf{3,1}='Users/fhlin/workspace/eegmri_wm/subjects/s001/bem/outer_skin.surf';
%             flag_show_surf{3,1}=boolean(1);
%             color_surf{3,1}=[1 0 0];
            
            global etc_render_fsbrain;
            if(isfield(etc_render_fsbrain,'surf_obj'))
                file_surf={};
                flag_show_surf={};
                color_surf={};
                for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                    file_surf{surf_idx,1}=etc_render_fsbrain.surf_obj(surf_idx).filename;
                    flag_show_surf{surf_idx,1}=boolean(etc_render_fsbrain.surf_obj(surf_idx).flag_show);
                    color_surf{surf_idx,1}=etc_render_fsbrain.surf_obj(surf_idx).color;
                    cate{surf_idx,1}=etc_render_fsbrain.surf_obj(surf_idx).category;
                end;
                t = table(file_surf,cate,flag_show_surf,color_surf);
            
                %t.flag_show_surf = categorical(t.flag_show_surf,{'On','Off'},'Ordinal',true);
                app.UITable.Data=t;
            end;

            app.UITable.ColumnName={'file','category','on/off','color'};

            app.UITable.ColumnEditable = [false true true true];
            if(isfield(etc_render_fsbrain,'surf_obj'))
                for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                        s = uistyle('BackgroundColor',etc_render_fsbrain.surf_obj(surf_idx).color);
                        addStyle(app.UITable,s,'cell',[surf_idx,4]);
                end;
            end;
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;

            if(~isempty(selection))
                if(selection(2)==4) %color
                    c = uisetcolor;
                    if(length(c)>1)
                        s = uistyle('BackgroundColor',c);
                        addStyle(app.UITable,s,'cell',selection);
                        global etc_render_fsbrain;
                        etc_render_fsbrain.surf_obj(selection(1)).color=c;
                        etc_render_fsbrain_handle('draw_pointer');
                    end;
                end;
            end;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if(indices(2)==2) %category
                global etc_render_fsbrain;
                etc_render_fsbrain.surf_obj(indices(1)).category=newData;
                %etc_render_fsbrain_handle('draw_pointer');
            end;

            if(indices(2)==3) %flag_show
                global etc_render_fsbrain;
                etc_render_fsbrain.surf_obj(indices(1)).flag_show=newData;
                etc_render_fsbrain_handle('draw_pointer');
            end;
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;
            switch key
                case {'delete','backspace'}
                    global etc_render_fsbrain;
                    
                    if(~isempty(event.Source.Selection))
                        delete(etc_render_fsbrain.surf_obj(event.Source.Selection(1)).patch_handle);
                        etc_render_fsbrain.surf_obj(event.Source.Selection(1))=[];
                        app.UITable.Data(event.Source.Selection(1),:)=[];
                        etc_render_fsbrain_handle('draw_pointer');

                        etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.SurfaceDropDown));
                    end;
            end;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.*','surface'});
            if(filename==0) return; end;

            %make sure no duplicates
            if(isfield(etc_render_fsbrain,'surf_obj'))
                surf_files={};
                for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                     surf_files{surf_idx}=etc_render_fsbrain.surf_obj(surf_idx).filename;
                end;
                if(ismember(sprintf('%s%s',pathname,filename),surf_files)) return; end;
            end;

            [FILEPATH,NAME,EXT] =fileparts(filename);
            if(strcmp(EXT,'.tri'))
                [vv,ff]=inverse_read_tri(sprintf('%s%s',pathname, filename));    
                ff=ff-1;
            else
                [vv,ff]=read_surf(sprintf('%s%s',pathname, filename));
            end;

            %geometrical information about scalp
            TR = triangulation(ff+1,vv);
            surf_center= incenter(TR);
            surf_norm = faceNormal(TR);


            [eS, TriPS, TriMS] = mt(TR);
            if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;
            etc_render_fsbrain.surf_obj(end+1).vertex=vv;
            etc_render_fsbrain.surf_obj(end).face=ff;
            etc_render_fsbrain.surf_obj(end).TR=TR;
            etc_render_fsbrain.surf_obj(end).surf_center=surf_center;
            etc_render_fsbrain.surf_obj(end).surf_norm=surf_norm;
            etc_render_fsbrain.surf_obj(end).eS=eS;
            etc_render_fsbrain.surf_obj(end).TriPS=TriPS;
            etc_render_fsbrain.surf_obj(end).TriMS=TriMS;

            cc=colororder;
            cc_idx=length(etc_render_fsbrain.surf_obj);
            cc_idx=mod(cc_idx+1,size(cc,1));
            if(cc_idx==0) cc_idx=size(cc,1); end;
            etc_render_fsbrain.surf_obj(end).color=cc(cc_idx,:);
            etc_render_fsbrain.surf_obj(end).flag_show=1;
            etc_render_fsbrain.surf_obj(end).filename=sprintf('%s%s',pathname, filename);
            etc_render_fsbrain.surf_obj(end).category=app.surf_category(6);

            figure(etc_render_fsbrain.fig_brain);
            etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',vv,'faces',ff+1,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp
            
            axis vis3d tight;


            app.UITable.ColumnName={'file','category','on/off','color'};
            new_entry={etc_render_fsbrain.surf_obj(end).filename,app.surf_category(6),boolean(1),[1 0 0]};

            if(isempty(app.UITable.Data))
                %app.UITable.Data=cell2table(new_entry)


                file_surf{1,1}=etc_render_fsbrain.surf_obj(end).filename;
                flag_show_surf{1,1}=boolean(1);
                color_surf{1,1}=[1 0 0];
                t = table(file_surf,app.surf_category(6),flag_show_surf,color_surf);
            
                app.UITable.Data=t;
                
            else
                app.UITable.Data = [app.UITable.Data;new_entry];
            end;     

            %surface color 
            s = uistyle('BackgroundColor',cc(cc_idx,:));
            addStyle(app.UITable,s,'cell',[size(app.UITable.Data,1),4]);

            %update brain surface and volume figures;
            etc_render_fsbrain_handle('draw_pointer');

            %update tms_nav figure;
            if(isfield(etc_render_fsbrain,'app_tms_nav'))
                if(~isempty(etc_render_fsbrain.app_tms_nav))
                    if(isvalid(etc_render_fsbrain.app_tms_nav))
                        etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.SurfaceDropDown));
                    end;
                end;
            end;
 
            %update tms_nav figure;
            if(isfield(etc_render_fsbrain,'app_tms_bem'))
                if(~isempty(etc_render_fsbrain.app_tms_bem))
                    if(isvalid(etc_render_fsbrain.app_tms_bem))
                        etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_bem,struct('Source', etc_render_fsbrain.app_tms_nav.SurfaceDropDown));
                    end;
                end;
            end;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SurfacecontoursPanel = uipanel(app.UIFigure);
            app.SurfacecontoursPanel.Title = 'Surface contours';
            app.UITable = uitable(app.SurfacecontoursPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.AddButton = uibutton(app.SurfacecontoursPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_surf_contour

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end