classdef etc_render_fsbrain_tms_nav < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TMSNavFigure               matlab.ui.Figure
        BrainsightTrgExportButton  matlab.ui.control.Button
        EfieldPanel                matlab.ui.container.Panel
        EfieldDropDown             matlab.ui.control.DropDown
        EcompLabel                 matlab.ui.control.Label
        EfieldShowCheckBox         matlab.ui.control.CheckBox
        EfieldCalclLamp            matlab.ui.control.Lamp
        CalcButton                 matlab.ui.control.Button
        TMScoilPanel               matlab.ui.container.Panel
        TMSCoilSizeDropDown        matlab.ui.control.DropDown
        sizeDropDownLabel          matlab.ui.control.Label
        TMSCoilDropDown            matlab.ui.control.DropDown
        CreateButton               matlab.ui.control.Button
        StrcoilAutoCheckBox        matlab.ui.control.CheckBox
        UpdatestrcoilButton        matlab.ui.control.Button
        StrcoilShowCheckBox        matlab.ui.control.CheckBox
        StrcoilLamp                matlab.ui.control.Lamp
        LoadstrcoilButton          matlab.ui.control.Button
        CoilShowCheckBox           matlab.ui.control.CheckBox
        VecUpCheckBox              matlab.ui.control.CheckBox
        VecNormCheckBox            matlab.ui.control.CheckBox
        CoilLamp                   matlab.ui.control.Lamp
        xButton                    matlab.ui.control.Button
        LoadcoilButton             matlab.ui.control.Button
        HeadmodelPanel             matlab.ui.container.Panel
        LoadpreparedmodelButton    matlab.ui.control.Button
        DefModelLamp               matlab.ui.control.Lamp
        DefineheadmodelButton      matlab.ui.control.Button
        PrepModelLamp              matlab.ui.control.Lamp
        PrepareheadmodelButton     matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        ImportButton               matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        TMStargetPanel             matlab.ui.container.Panel
        TargetlistLabel            matlab.ui.control.Label
        BrainsightTrgImportButton  matlab.ui.control.Button
        TrgDelButton               matlab.ui.control.Button
        TrgAddButton               matlab.ui.control.Button
        UITrgTable                 matlab.ui.control.Table
        ScannerEditField           matlab.ui.control.EditField
        MNIEditField_2Label_2      matlab.ui.control.Label
        LoadxfmButton              matlab.ui.control.Button
        rotatedegEditField         matlab.ui.control.NumericEditField
        offsetmmEditField          matlab.ui.control.NumericEditField
        horizdegEditField          matlab.ui.control.NumericEditField
        vertdegEditField           matlab.ui.control.NumericEditField
        rotatedegSlider            matlab.ui.control.Slider
        rotatedegSliderLabel       matlab.ui.control.Label
        offsetmmSlider             matlab.ui.control.Slider
        offsetmmSliderLabel        matlab.ui.control.Label
        tilthorizdegSlider         matlab.ui.control.Slider
        tilthorizdegSliderLabel    matlab.ui.control.Label
        tiltvertdegSlider          matlab.ui.control.Slider
        tiltvertdegSliderLabel     matlab.ui.control.Label
        vertexindexEditField       matlab.ui.control.EditField
        vertexindexEditFieldLabel  matlab.ui.control.Label
        XYZEditField               matlab.ui.control.EditField
        XYZEditFieldLabel          matlab.ui.control.Label
        CRSEditField               matlab.ui.control.EditField
        CRSEditFieldLabel          matlab.ui.control.Label
        MNIEditField               matlab.ui.control.EditField
        MNIEditField_2Label        matlab.ui.control.Label
        GoButton                   matlab.ui.control.Button
        HeadbrainboundariesPanel   matlab.ui.container.Panel
        SButton                    matlab.ui.control.Button
        SurfaceDropDown            matlab.ui.control.DropDown
        surfaceDropDownLabel       matlab.ui.control.Label
        ShowscalpCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)

        %head model preparation variabls
        t=[];
        P=[];
        normals=[];
        Center=[];
        Area=[];
        Indicator=[];
        name=[];
        tissue=[];
        cond=[];
        enclosingTissueIdx=[];
        condin=[];
        condout=[];
        contrast=[];
        tneighbor=[];
        RnumberE=[];
        ineighborE=[];
        EC=[];

        file_combinedMeshMAT='';
        file_combinedMeshPMAT='';

    end
    
    properties (Access = public)
        flag_show_up=0;
        flag_show_norm=0;
        up_obj=[]; % Description
        norm_obj=[]; % Description

        coil_center_no_tune=[]; %TMS coil center before any tuning

        strcoil_obj=[]; % Description
        strcoil_obj_xfm=eye(4); %transformation matrix at the moment when strcoil is loaded.

        tmscoilsize=1; %scaling for TMS coil object

        %for BEM preparation
        file_tissue_index='';


        %E-field
        efield=[];

        %target
        target_vertex_index=[];
        target_tilt_vert=0;
        target_tilt_horiz=0;
        target_offset=0;
        target_rotate=0;        
    end
    
    methods (Access = private)
        
        function results = tune_vert(app)
            value = app.tiltvertdegSlider.Value;

            global etc_render_fsbrain;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            if(isempty(click_idx)) return; end;
            target=etc_render_fsbrain.vertex_coords(click_idx,:);
            

            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3)./1e3;
            R_c_mm(1:3,4)=-target(1:3);

            %coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            coil_axis=cross(etc_render_fsbrain.object.UserData.Axis(:),etc_render_fsbrain.object.UserData.Up(:));
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            
            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default

            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);

            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);

            %determine the rotation between two vectors;
            theta=value./180*pi-deg;
            %theta=value.*pi./180-deg;
            %fprintf('additional %1.0f (deg) rotation from untuned orientation is requested.\n',theta*180/pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);
            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('Finally, coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);

            
            %automatically update strcoil
            if(app.StrcoilAutoCheckBox.Value)
                try
                    tmp=app.strcoil_obj.Vertices;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    xfm_tmp=app.strcoil_obj_xfm;
                    xfm_tmp(1:3,4)=xfm_tmp(1:3,4)*1e3; %into mm
                    tmp=xfm*inv(xfm_tmp)*tmp;

                    app.strcoil_obj.Vertices=tmp(1:3,:)'; %update
                    app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;

                    tmp=app.strcoil_obj.Vertices./1e3;
                    assignin('base','tmp',tmp);
                    evalin('base','strcoil.Pwire=tmp;');
                    fprintf('variable [strcoil] update at the workspace.\n');
                    app.TextArea.Value{end+1}='variable [strcoil] updated at the workspace.';
                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;
                catch
                    fprintf('Error in updating strcoil!\n');
                    app.TextArea.Value{end+1}='Error in updating strcoil!\n';
                end;
            end;            

        end
        
        function results = tune_horiz(app)
            value = app.tilthorizdegSlider.Value;
                       
            global etc_render_fsbrain;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            if(isempty(click_idx)) return; end;
            target=etc_render_fsbrain.vertex_coords(click_idx,:);


            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3)./1e3;
            R_c_mm(1:3,4)=-target(1:3);

            %coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            coil_axis=etc_render_fsbrain.object.UserData.Up(:);
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            
            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default

            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);

            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);

            %determine the rotation between two vectors;
            theta=value./180*pi-deg;
            %theta=value.*pi./180-deg;
            %fprintf('additional %1.0f (deg) rotation from untuned orientation is requested.\n',theta*180/pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);
            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('Finally, coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);


            %automatically update strcoil
            if(app.StrcoilAutoCheckBox.Value)
                try
                    tmp=app.strcoil_obj.Vertices;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    xfm_tmp=app.strcoil_obj_xfm;
                    xfm_tmp(1:3,4)=xfm_tmp(1:3,4)*1e3; %into mm
                    tmp=xfm*inv(xfm_tmp)*tmp;

                    app.strcoil_obj.Vertices=tmp(1:3,:)'; %update
                    app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;

                    tmp=app.strcoil_obj.Vertices./1e3;
                    assignin('base','tmp',tmp);
                    evalin('base','strcoil.Pwire=tmp;');
                    fprintf('variable [strcoil] update at the workspace.\n');
                    app.TextArea.Value{end+1}='variable [strcoil] updated at the workspace.';
                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;
                catch
                    fprintf('Error in updating strcoil!\n');
                    app.TextArea.Value{end+1}='Error in updating strcoil!\n';
                end;
            end;            
        end
        
        function results = tune_offset(app)
            value = app.offsetmmSlider.Value;

            global etc_render_fsbrain;

            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            target=app.coil_center_no_tune./1e3;         


            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3);
            R_c_mm(1:3,4)=-target(1:3).*1e3;

            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            desired_vec=-value./1e3.*tmp(:);
            now_vec=etc_render_fsbrain.object.UserData.Origin(:)./1e3-target(:);

            diff_vec=desired_vec-now_vec;
            tmp=diff_vec;
            
            R_offset=eye(4);
            R_offset_mm=eye(4);
            R_offset(1:3,4)=tmp(:);
            R_offset_mm(1:3,4)=tmp(:).*1e3;


            etc_render_fsbrain.object_xfm=R_offset*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(R_offset_mm*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=(R_offset_mm*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=(R_offset_mm*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=(R_offset_mm*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(eye(4)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(eye(4)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %automatically update strcoil
            if(app.StrcoilAutoCheckBox.Value)
                try
                    tmp=app.strcoil_obj.Vertices;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    xfm_tmp=app.strcoil_obj_xfm;
                    xfm_tmp(1:3,4)=xfm_tmp(1:3,4)*1e3; %into mm
                    tmp=xfm*inv(xfm_tmp)*tmp;

                    app.strcoil_obj.Vertices=tmp(1:3,:)'; %update
                    app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;

                    tmp=app.strcoil_obj.Vertices./1e3;
                    assignin('base','tmp',tmp);
                    evalin('base','strcoil.Pwire=tmp;');
                    fprintf('variable [strcoil] update at the workspace.\n');
                    app.TextArea.Value{end+1}='variable [strcoil] updated at the workspace.';
                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;
                catch
                    fprintf('Error in updating strcoil!\n');
                    app.TextArea.Value{end+1}='Error in updating strcoil!\n';
                end;
            end;
        end
        
        function results = tune_rotate(app)
            value = app.rotatedegSlider.Value;


            global etc_render_fsbrain;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %aligning UP toward +z

            coil_center=etc_render_fsbrain.object.UserData.Origin(:);
            R_c_mm=eye(4);
            R_c=eye(4);
            R_c_mm(1:3,4)=-coil_center(1:3);
            R_c(1:3,4)=-coil_center(1:3)./1e3;
            
            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

%             X1=etc_render_fsbrain.object.UserData.Origin(1);
%             Y1=etc_render_fsbrain.object.UserData.Origin(2);
%             Z1=etc_render_fsbrain.object.UserData.Origin(3);
% 
%             X2=0;
%             Y2=0;
%             Z2=100;
%             vtop=[X2-X1; Y2-Y1; Z2-Z1]';
            vtop=etc_render_fsbrain.object.UserData.Up_orig(:);
            vtop_perp=vtop(:)-sum(vtop(:).*tmp(:)).*tmp(:);
           
            vup=etc_render_fsbrain.object.UserData.Up(:);


            %determine the rotation between two vectors;
            xx = cross(vtop_perp,vup);
            c = sign(dot(xx,etc_render_fsbrain.object.UserData.Axis_orig)) * norm(xx);
            deg = atan2d(c,dot(vtop_perp,vup)).*pi./180;
            theta=(deg-value./180*pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            %vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            vv=((R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);
            vv=(inv(R_rotz)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);
            vv=(inv(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %end of aligning UP toward +z
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %automatically update strcoil
            if(app.StrcoilAutoCheckBox.Value)
                try
                    tmp=app.strcoil_obj.Vertices;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    xfm_tmp=app.strcoil_obj_xfm;
                    xfm_tmp(1:3,4)=xfm_tmp(1:3,4)*1e3; %into mm
                    tmp=xfm*inv(xfm_tmp)*tmp;

                    app.strcoil_obj.Vertices=tmp(1:3,:)'; %update
                    app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;

                    tmp=app.strcoil_obj.Vertices./1e3;
                    assignin('base','tmp',tmp);
                    evalin('base','strcoil.Pwire=tmp;');
                    fprintf('variable [strcoil] update at the workspace.\n');
                    app.TextArea.Value{end+1}='variable [strcoil] updated at the workspace.';
                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;
                catch
                    fprintf('Error in updating strcoil!\n');
                    app.TextArea.Value{end+1}='Error in updating strcoil!\n';
                end;
            end;

        end
        
        function results = target_apply_xfm(app, coil_center, coil_orientation, coil_up)

            results=0;

            %resettting tuning parameters
            app.rotatedegSlider.Value=0;
            app.offsetmmSlider.Value=0;
            app.tilthorizdegSlider.Value=0;
            app.tiltvertdegSlider.Value=0;

            app.vertdegEditField.Value=0;
            app.horizdegEditField.Value=0;
            app.offsetmmEditField.Value=0;
            app.rotatedegEditField.Value=0;

            global etc_render_fsbrain;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % TMS coil placement


            %coil_center; % TMS coil center; in mm
            %coil_orientation; %TMS coil axis; unit vector 
            %fprintf('distance between the target and coil center = %2.2f (mm)\n',norm(coil_orientation));
            %app.TextArea.Value{end+1}=sprintf('distance between the target and coil center = %2.2f (mm)\n',norm(coil_orientation));

            %coil_orientation=coil_orientation./norm(coil_orientation);
            %scalp_orientation=surf_norm(min_idx,:);

            % derive the rotation matrix between two vectors (from, to)
            % https://math.stackexchange.com/questions/180418/calculate-rotation-matrix-to-align-vector-a-to-vector-b-in-3d
            ssc = @(v) [0 -v(3) v(2); v(3) 0 -v(1); -v(2) v(1) 0];
            RU = @(A,B) eye(3) + ssc(cross(A,B)) + ssc(cross(A,B))^2*(1-dot(A,B))/(norm(cross(A,B))^2);

            %fprintf('before moving: coil origin: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));
            %fprintf('before moving: coil origin target: %s \n',mat2str(coil_center(:).',3));
            
            %etc_render_fsbrain.object.UserData.Axis(:).'

            coil_shift=-etc_render_fsbrain.object.UserData.Origin(:)+coil_center(:);
            n=norm(etc_render_fsbrain.object.UserData.Axis(:)'-coil_orientation);
            if(n>eps)
                RR=RU(etc_render_fsbrain.object.UserData.Axis(:)',coil_orientation(:)');
            else
                RR=eye(3);
            end;
            if(find(isnan(RR(:)))) keyboard; end;
            if(find(isnan(coil_shift(:)))) keyboard; end;

            % update object_xfm matrix
            R=eye(4);
            R(1:3,4)=etc_render_fsbrain.object.UserData.Origin(:);
            R_tx_now_mm=R;
            R_tx_now=R_tx_now_mm;
            R_tx_now(1:3,4)=R_tx_now(1:3,4)./1e3;
            

            R=eye(4);
            R(1:3,1:3)=RR;
            R_rot=R';

            R=eye(4);
            R(1:3,4)=coil_shift(:)./1e3; %in m
            R_trans=R;

            R=eye(4);
            R(1:3,4)=coil_shift(:); %in mm
            R_trans_mm=R;

            if(isempty(etc_render_fsbrain.object_xfm))
                etc_render_fsbrain.object_xfm=R_trans*R_tx_now*inv(R_rot)*inv(R_tx_now);
            else
                etc_render_fsbrain.object_xfm=R_trans*R_tx_now*inv(R_rot)*inv(R_tx_now)*etc_render_fsbrain.object_xfm;
            end;
            %transform object in visualiation
            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rot)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);
            etc_render_fsbrain.object.UserData.Axis(:)';

            norm_dir=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData]; norm_dir=diff(norm_dir,1,2); norm_dir=norm_dir./norm(norm_dir);
            etc_render_fsbrain.object.UserData.Axis=norm_dir(:);
            etc_render_fsbrain.object.UserData.Axis(:)';
            
            tmp=(inv(R_rot)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);
            etc_render_fsbrain.object.UserData.Up(:)';


            up_dir=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData]; up_dir=diff(up_dir,1,2); up_dir=up_dir./norm(up_dir);
            etc_render_fsbrain.object.UserData.Up=up_dir(:);
            etc_render_fsbrain.object.UserData.Up(:)';


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %aligning UP toward coil_up

            coil_center=etc_render_fsbrain.object.UserData.Origin(:);
            R_c_mm=eye(4);
            R_c_mm(1:3,4)=-coil_center(1:3);
            R_c=R_c_mm;
            R_c(1:3,4)=R_c(1:3,4)./1e3;

            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            %X1=etc_render_fsbrain.object.UserData.Origin(1);
            %Y1=etc_render_fsbrain.object.UserData.Origin(2);
            %Z1=etc_render_fsbrain.object.UserData.Origin(3);

            %X2=0;
            %Y2=0;
            %Z2=100;
            %vtop=[X2-X1; Y2-Y1; Z2-Z1]';
            vtop=coil_up(:);
            vtop_perp=vtop(:)-sum(vtop(:).*tmp(:)).*tmp(:);
           
            vup=etc_render_fsbrain.object.UserData.Up;


            %determine the rotation between two vectors;
            xx = cross(vtop_perp,vup);
            c = sign(dot(xx,etc_render_fsbrain.object.UserData.Axis)) * norm(xx);
            deg = atan2d(c,dot(vtop_perp,vup)).*pi./180;

            %[azimuth,elevation,r] = cart2sph(x,y,z);
            %deg=azimuth;
            theta=(deg);
            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;
            

            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);
            etc_render_fsbrain.object.UserData.Axis(:)';
            norm_dir=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData]; norm_dir=diff(norm_dir,1,2); norm_dir=norm_dir./norm(norm_dir);
            etc_render_fsbrain.object.UserData.Axis=norm_dir(:);
            etc_render_fsbrain.object.UserData.Axis(:)';

            etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);
            etc_render_fsbrain.object.UserData.Up(:)';
            up_dir=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData]; up_dir=diff(up_dir,1,2); up_dir=up_dir./norm(up_dir);
            etc_render_fsbrain.object.UserData.Up=up_dir(:);
            etc_render_fsbrain.object.UserData.Up(:)';

            etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;
            
            %end of aligning UP toward coil_up
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.rotatedegSlider.Value=0;
            app.offsetmmSlider.Value=0;
            app.tilthorizdegSlider.Value=0;
            app.tiltvertdegSlider.Value=0;

            app.EfieldCalclLamp.Color='r';
            
            app.coil_center_no_tune=etc_render_fsbrain.object.UserData.Origin;

            app.target_vertex_index=etc_render_fsbrain.click_coord;


%             fprintf('Origin: %s\n',mat2str(etc_render_fsbrain.object.UserData.Origin,4));
%             fprintf('Origin_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Origin_orig,4));
%             fprintf('Axis_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis_orig,4));
%             fprintf('Axis: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis,4));
%             norm_dir=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData]; norm_dir=diff(norm_dir,1,2); norm_dir=norm_dir./norm(norm_dir);
%             fprintf('Axis object direction: %s\n',mat2str(norm_dir,4));
% 
%             fprintf('Up_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up_orig,4));
%             fprintf('Up: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up,4));
%             up_dir=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData]; up_dir=diff(up_dir,1,2); up_dir=up_dir./norm(up_dir);
%             fprintf('Up object direction: %s\n',mat2str(up_dir,4));
%             fprintf('-------------\n');            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %updating strcoil
            if(~isempty(app.strcoil_obj))

                try
                    tmp=app.strcoil_obj.Vertices;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    xfm_tmp=app.strcoil_obj_xfm;
                    xfm_tmp(1:3,4)=xfm_tmp(1:3,4)*1e3; %into mm
                    tmp=xfm*inv(xfm_tmp)*tmp;

                    app.strcoil_obj.Vertices=tmp(1:3,:)'; %update
                    app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;

                    tmp=app.strcoil_obj.Vertices./1e3;
                    assignin('base','tmp',tmp);
                    evalin('base','strcoil.Pwire=tmp;');
                    fprintf('variable [strcoil] update at the workspace.\n');
                    app.TextArea.Value{end+1}='variable [strcoil] updated at the workspace.';
                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;

                catch
                    fprintf('Error in updating strcoil!\n');
                    app.TextArea.Value{end+1}='Error in updating strcoil!\n';
                end;
            end;

            app.EfieldCalclLamp.Color='r';

            results=1;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_render_fsbrain;

            app.SurfaceDropDown.Items={};
            if(isfield(etc_render_fsbrain,'surf_obj'))
                for idx=1:length(etc_render_fsbrain.surf_obj)
                    [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(idx).filename);
                    app.SurfaceDropDown.Items{idx}=ff;
                    app.SurfaceDropDown.Value=ff;
                    if(isfield(etc_render_fsbrain.surf_obj(idx),'patch_handle'))
                        if(~isvalid(etc_render_fsbrain.surf_obj(idx).patch_handle))
                            [vv,ff]=read_surf(etc_render_fsbrain.surf_obj(idx).filename);

                            if(isfield(etc_render_fsbrain.surf_obj(idx),'patch_handle'))
                                
                            else
                                etc_render_fsbrain.surf_obj(idx).patch_handle=patch('vertices',vv,'faces',ff+1,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(idx).color,'facealpha',0.2); %show scalp
                            end;

                            %enable head model light
                            app.DefModelLamp.Color='g';

                        end;
                    end;
                end;
                if(isempty(app.SurfaceDropDown.Items))
                    app.ShowscalpCheckBox.Enable='off';
                else
                    app.ShowscalpCheckBox.Enable='on';
                end;

                %BEM is prepared?
                if(isfield(app,'t'))
                    if(~isempty(app.t))
                        app.PrepModelLamp.Color='g';
                    end;
                end;
            else
                app.ShowscalpCheckBox.Enable='off';
            end;

            app.ShowscalpCheckBox.Value=0;
            if(isfield(etc_render_fsbrain,'surf_obj'))
                for idx=1:length(etc_render_fsbrain.surf_obj)
                    if(isfield(etc_render_fsbrain.surf_obj(idx),'patch_handle'))
                        if(isvalid(etc_render_fsbrain.surf_obj(idx).patch_handle))
                            if(etc_render_fsbrain.surf_obj(idx).patch_handle.Visible)
                                app.ShowscalpCheckBox.Value=1;
                            end;
                        end;
                    end;
                end;
            end;

            app.CoilLamp.Color='r';
            app.CoilShowCheckBox.Value=0;
            if(isfield(etc_render_fsbrain,'object'))
                if(~isempty(etc_render_fsbrain.object)) %coil patch exists
                    if(isvalid(etc_render_fsbrain.object))
                        app.CoilLamp.Color='g';
                        if(etc_render_fsbrain.object.Visible)
                            app.CoilShowCheckBox.Value=1;
                        end;
                    end;
                end;
            end;

            evalin('base','global tmp; tmp=exist(''strcoil'',''var''); if(tmp) tmp=~isempty(strcoil); end; ');
            global tmp;
            if(tmp)
                try
                    app.TextArea.Value{end+1}='variable [strcoil] exists at the workspace.';

                    %remember this to be used for updating later
                    if(isfield(etc_render_fsbrain,'object_xfm'))
                        app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;
                    else
                        app.strcoil_obj_xfm=eye(4);
                    end;

                    if(~isempty(app.strcoil_obj))
                        if(isvalid(app.strcoil_obj))
                            try
                                delete(app.strcoil_obj);
                            catch
                            end;
                        end;
                    end;
                    strcoil= evalin('base', 'strcoil');
                    app.strcoil_obj=patch('faces', strcoil.Ewire, 'vertices',strcoil.Pwire.*1e3, 'EdgeColor', 'g', 'FaceColor','b');

                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;
                catch
                    fprintf('Error in initializing strcoil object.\n');
                    app.StrcoilLamp.Color='r';
                    app.StrcoilShowCheckBox.Value=0;
                end;
            end;

            if(isfield(etc_render_fsbrain,'tissue_def_file'))
                app.file_tissue_index=etc_render_fsbrain.tissue_def_file;
                app.DefModelLamp.Color='y';

                try
                    [name, tissue, cond, enclosingTissueIdx, enclosingTissueName] = etc_render_fsbrain_tissue_read(app.file_tissue_index);
                    tissue_name='';
                    tissue_mat='';
                    tissue_cond='';
                    tissue_enclosing_tissue='';


                    etc_render_fsbrain.tissue_obj=[];
                    enclosing_tissue_list=categorical({'FreeSpace'});

                    if(isfield(etc_render_fsbrain,'surf_obj'))
                        for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                            if(isfield(etc_render_fsbrain.surf_obj(surf_idx),'patch_handle'))
                                if(~isempty(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                    if(isvalid(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                        try
                                            delete(etc_render_fsbrain.surf_obj(surf_idx).patch_handle);
                                        catch
                                        end;
                                    end
                                end;
                            end;
                        end;
                        etc_render_fsbrain.surf_obj=[];
                    else
                        etc_render_fsbrain.surf_obj=[]; 
                    end;

                    for tissue_idx=1:length(name)
                        tissue_name{tissue_idx,1}=tissue{tissue_idx};
                        etc_render_fsbrain.tissue_obj(tissue_idx).tissue_name=tissue_name{tissue_idx,1};

                        tissue_mat{tissue_idx,1}=name{tissue_idx};
                        etc_render_fsbrain.tissue_obj(tissue_idx).tissue_mat=tissue_mat{tissue_idx,1};

                        tissue_cond{tissue_idx,1}=cond(tissue_idx);
                        etc_render_fsbrain.tissue_obj(tissue_idx).tissue_cond=tissue_cond{tissue_idx,1};

                        tissue_enclosing_tissue{tissue_idx,1}=enclosingTissueName{tissue_idx};
                        etc_render_fsbrain.tissue_obj(tissue_idx).tissue_enclosing_tissue=tissue_enclosing_tissue{tissue_idx,1};

                        %new boundary
                        [a,idx]=ismember(enclosing_tissue_list,tissue_name{tissue_idx,1});
                        if(isempty(find(idx)))
                            enclosing_tissue_list(end+1)=tissue_name{tissue_idx,1};
                        end;

                        %update surf_obj
                        d=load(etc_render_fsbrain.tissue_obj(end).tissue_mat);


                        if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;

                        etc_render_fsbrain.surf_obj(tissue_idx).vertex=d.P;
                        etc_render_fsbrain.surf_obj(tissue_idx).face=d.t;
                        etc_render_fsbrain.surf_obj(tissue_idx).TR=triangulation(d.t,d.P);;
                        etc_render_fsbrain.surf_obj(tissue_idx).surf_center=incenter(etc_render_fsbrain.surf_obj(end).TR);
                        etc_render_fsbrain.surf_obj(tissue_idx).surf_norm=d.normals;
                        [eS, TriPS, TriMS] = mt(etc_render_fsbrain.surf_obj(tissue_idx).TR);
                        etc_render_fsbrain.surf_obj(tissue_idx).eS=eS;
                        etc_render_fsbrain.surf_obj(tissue_idx).TriPS=TriPS;
                        etc_render_fsbrain.surf_obj(tissue_idx).TriMS=TriMS;

                        cc=colororder;
                        cc_idx=length(etc_render_fsbrain.surf_obj);
                        cc_idx=mod(cc_idx+1,size(cc,1));
                        if(cc_idx==0) cc_idx=size(cc,1); end;
                        etc_render_fsbrain.surf_obj(tissue_idx).color=cc(cc_idx,:);
                        etc_render_fsbrain.surf_obj(tissue_idx).flag_show=1;
                        etc_render_fsbrain.surf_obj(tissue_idx).filename=name{tissue_idx};
                        etc_render_fsbrain.surf_obj(tissue_idx).category=tissue{tissue_idx};

                        figure(etc_render_fsbrain.fig_brain);
                        etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',d.P,'faces',d.t,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp

                        axis vis3d tight;

                    end;

                    app.DefModelLamp.Color='g';


                catch
                end;
            else
                app.DefModelLamp.Color='r';
            end;


            if(isfield(etc_render_fsbrain,'click_coord'))
                if(~isempty(etc_render_fsbrain.click_coord))

                    vv=etc_render_fsbrain.vertex_coords;
                    dist=sqrt(sum((vv-repmat([etc_render_fsbrain.click_coord(1),etc_render_fsbrain.click_coord(2),etc_render_fsbrain.click_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);

                    app.vertexindexEditField.Value=num2str(min_dist_idx,'%1.0f ');

                    app.XYZEditField.Value=num2str(etc_render_fsbrain.click_coord(:)','%1.1f ');

                    app.CRSEditField.Value=num2str(etc_render_fsbrain.click_vertex_vox(:)','%1.0f ');

                    app.MNIEditField.Value=num2str(etc_render_fsbrain.click_vertex_point_tal(:)','%1.0f ');

                    tmp=etc_render_fsbrain.vol.vox2ras*[etc_render_fsbrain.click_vertex_vox(:)' 1]';

                    app.ScannerEditField.Value=num2str(tmp(1:3)','%1.0f ');
                end;
            end;


            switch app.TMSCoilSizeDropDown.Value
                case '100%'
                    app.tmscoilsize=1;
                case '50%'
                    app.tmscoilsize=.5;
                case '20%'
                    app.tmscoilsize=.2;
                case '10%'
                    app.tmscoilsize=.1;
            end;

            if(isfield(etc_render_fsbrain,'efield'))
                if(~isempty(etc_render_fsbrain.efield))
                    try
                        app.efield=etc_render_fsbrain.efield;

                        app.EfieldCalclLamp.Color='g';

                        if(~isempty(etc_render_fsbrain.overlay_value))
                            app.EfieldShowCheckBox.Value=1;
                        end;
                    catch
                    end;
                end;
            end;


        end

        % Callback function
        function loadscalpButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.*','surface'});
            if(filename==0) return; end;


            [vv,ff]=read_surf(sprintf('%s/%s',pathname, filename));

            %geometrical information about scalp
            TR = triangulation(ff+1,vv);
            surf_center= incenter(TR);
            surf_norm = faceNormal(TR);


            [eS, TriPS, TriMS] = mt(TR);
            if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;
            etc_render_fsbrain.surf_obj(end+1).vertex=vv;
            etc_render_fsbrain.surf_obj(end).face=ff;
            etc_render_fsbrain.surf_obj(end).TR=TR;
            etc_render_fsbrain.surf_obj(end).surf_center=surf_center;
            etc_render_fsbrain.surf_obj(end).surf_norm=surf_norm;
            etc_render_fsbrain.surf_obj(end).eS=eS;
            etc_render_fsbrain.surf_obj(end).TriPS=TriPS;
            etc_render_fsbrain.surf_obj(end).TriMS=TriMS;
            etc_render_fsbrain.surf_obj(end).color=app.ScalpColorButton.BackgroundColor;
            etc_render_fsbrain.surf_obj(end).flag_show=1;
            etc_render_fsbrain.surf_obj(end).filename=sprintf('%s/%s',pathname, filename);

            app.ShowscalpCheckBox.Value=1;

            etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',vv,'faces',ff+1,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp
            
            axis vis3d tight;

            etc_render_fsbrain_handle('draw_pointer');

        end

        % Callback function
        function ScalpColorButtonPushed(app, event)
            c = uisetcolor;
            if(length(c)>1)
                app.ScalpColorButton.BackgroundColor=c;

                global etc_render_fsbrain;
                etc_render_fsbrain.surf_obj(end).color=c;
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    etc_render_fsbrain.surf_obj(end).patch_handle.FaceColor=c;
                end;
                etc_render_fsbrain_handle('draw_pointer');
            end;
        end

        % Value changed function: ShowscalpCheckBox
        function ShowscalpCheckBoxValueChanged(app, event)
            value = app.ShowscalpCheckBox.Value;

            global etc_render_fsbrain;

            surface_list={};
            for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(surf_idx).filename);
                surface_list{end+1}=ff;
            end;
            [a,b]=ismember(app.SurfaceDropDown.Value,surface_list); %determine which surface is selected

            if(a<eps) return; end; %no matched surface!

            if(value)
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    set(etc_render_fsbrain.surf_obj(b).patch_handle,'visible','on');
                end;
            else
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    set(etc_render_fsbrain.surf_obj(b).patch_handle,'visible','off');
                end;                
            end;
        end

        % Close request function: TMSNavFigure
        function TMSNavFigureCloseRequest(app, event)
            
            if(~isempty(app.strcoil_obj))
                delete(app.strcoil_obj);
                app.strcoil_obj=[];
            end;

            try
                delete(app.norm_obj);
            catch
            end;
            try
                delete(app.up_obj);
            catch
            end;
            try
                delete(app.scalp_obj);
            catch
            end;
            try
                delete(app)
            catch
            end;
            
        end

        % Value changed function: MNIEditField
        function MNIEditFieldValueChanged(app, event)
            value = app.MNIEditField.Value;
            
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);
                    mni=[coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.vox2ras)*inv(etc_render_fsbrain.vol_pre_xfm)*inv(etc_render_fsbrain.talxfm)*mni;
                    %click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*inv(etc_render_fsbrain.vol_pre_xfm)*inv(etc_render_fsbrain.talxfm)*mni;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;


        end

        % Value changed function: CRSEditField
        function CRSEditFieldValueChanged(app, event)
            value = app.CRSEditField.Value;
            
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);
                    click_vertex_vox=[coord];
                    tmp=[click_vertex_vox 1]';
                    mni=etc_render_fsbrain.talxfm*etc_render_fsbrain.vol_pre_xfm*etc_render_fsbrain.vol.vox2ras*tmp;
                    %mni=etc_render_fsbrain.talxfm*etc_render_fsbrain.vol_pre_xfm*etc_render_fsbrain.vol.tkrvox2ras*tmp;
                    mni=min(1:3)';

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

        end

        % Value changed function: XYZEditField
        function XYZEditFieldValueChanged(app, event)
            value = app.XYZEditField.Value;

            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);

                    surface_coord=[coord];
                    tmp=[surface_coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*tmp;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

        end

        % Value changed function: vertexindexEditField
        function vertexindexEditFieldValueChanged(app, event)
            value = app.vertexindexEditField.Value;
                       
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord_idx= str2num(value);
                    vv=etc_render_fsbrain.vertex_coords;
                    coord=vv(coord_idx,:);

                    surface_coord=[coord];
                    tmp=[surface_coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*tmp;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % TMS coil placement
            global etc_render_fsbrain;

            if(norm(app.CoilLamp.Color-[1 0 0])<eps) return; end;

            if(isempty(app.vertexindexEditField.Value)) return; end;

            if(~isfield(etc_render_fsbrain,'surf_obj'))
                fprintf('no surface defined!\n');
                return; 
            else
                if(isempty(etc_render_fsbrain.surf_obj)) 
                    fprintf('no surface defined!\n');
                    return;
                end;
            end;

            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            target=etc_render_fsbrain.vertex_coords(click_idx,:);
            

            surface_list={};
            for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(surf_idx).filename);
                surface_list{end+1}=ff;
            end;
            [a,scalp_idx]=ismember(app.SurfaceDropDown.Value,surface_list); %determine which surface is selected

            if(a<eps) return; end; %no matched surface!

            surf_center=etc_render_fsbrain.surf_obj(scalp_idx).surf_center;
            surf_norm=etc_render_fsbrain.surf_obj(scalp_idx).surf_norm;

            %calculate target-coil distance
            dist=sqrt(sum(bsxfun(@minus,surf_center,target).^2,2));
            [min_dist,min_idx]=min(dist);

            %show coil position with the minimal distance
            %cc=get(gca,'colororder');
            %hq=quiver3(surf_center(min_idx,1),surf_center(min_idx,2),surf_center(min_idx,3),surf_norm(min_idx,1),surf_norm(min_idx,2),surf_norm(min_idx,3),15,'color','r');

            coil_center=surf_center(min_idx,:); % in mm
            coil_orientation=target-coil_center;
            fprintf('distance between the target and coil center = %2.2f (mm)\n',norm(coil_orientation));
            app.TextArea.Value{end+1}=sprintf('distance between the target and coil center = %2.2f (mm)\n',norm(coil_orientation));

            coil_orientation=coil_orientation./norm(coil_orientation);
            scalp_orientation=surf_norm(min_idx,:);

            % derive the rotation matrix between two vectors (from, to)
            % https://math.stackexchange.com/questions/180418/calculate-rotation-matrix-to-align-vector-a-to-vector-b-in-3d
            ssc = @(v) [0 -v(3) v(2); v(3) 0 -v(1); -v(2) v(1) 0];
            RU = @(A,B) eye(3) + ssc(cross(A,B)) + ssc(cross(A,B))^2*(1-dot(A,B))/(norm(cross(A,B))^2);

            %fprintf('before moving: coil origin: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));
            %fprintf('before moving: coil origin target: %s \n',mat2str(coil_center(:).',3));
            
            %etc_render_fsbrain.object.UserData.Axis(:).'

            coil_shift=-etc_render_fsbrain.object.UserData.Origin(:)+coil_center(:);
            n=norm(etc_render_fsbrain.object.UserData.Axis(:)'-coil_orientation);
            if(n>eps)
                RR=RU(etc_render_fsbrain.object.UserData.Axis(:)',coil_orientation(:)');
            else
                RR=eye(3);
            end;
            if(find(isnan(RR(:)))) keyboard; end;
            if(find(isnan(coil_shift(:)))) keyboard; end;

            % update object_xfm matrix
            R=eye(4);
            R(1:3,4)=etc_render_fsbrain.object.UserData.Origin(:);
            R_tx_now_mm=R;
            R_tx_now=R_tx_now_mm;
            R_tx_now(1:3,4)=R_tx_now(1:3,4)./1e3;
            

            R=eye(4);
            R(1:3,1:3)=RR;
            R_rot=R';

            R=eye(4);
            R(1:3,4)=coil_shift(:)./1e3; %in m
            R_trans=R;

            R=eye(4);
            R(1:3,4)=coil_shift(:); %in mm
            R_trans_mm=R;

            if(isempty(etc_render_fsbrain.object_xfm))
                etc_render_fsbrain.object_xfm=R_trans*R_tx_now*inv(R_rot)*inv(R_tx_now);
            else
                etc_render_fsbrain.object_xfm=R_trans*R_tx_now*inv(R_rot)*inv(R_tx_now)*etc_render_fsbrain.object_xfm;
            end;
            %transform object in visualiation
            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);
            fprintf('after moving: coil origin: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));

            tmp=(inv(R_rot)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);
            etc_render_fsbrain.object.UserData.Axis(:)';

            if(~isempty(app.norm_obj))
                norm_dir=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData]; norm_dir=diff(norm_dir,1,2); norm_dir=norm_dir./norm(norm_dir);
                etc_render_fsbrain.object.UserData.Axis=norm_dir(:);
                etc_render_fsbrain.object.UserData.Axis(:)';
            end;

            tmp=(inv(R_rot)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);
            etc_render_fsbrain.object.UserData.Up(:)';

            if(~isempty(app.up_obj))
                up_dir=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData]; up_dir=diff(up_dir,1,2); up_dir=up_dir./norm(up_dir);
                etc_render_fsbrain.object.UserData.Up=up_dir(:);
                etc_render_fsbrain.object.UserData.Up(:)';
            end;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %aligning UP toward +z

            coil_center=etc_render_fsbrain.object.UserData.Origin(:);
            R_c_mm=eye(4);
            R_c_mm(1:3,4)=-coil_center(1:3);
            R_c=R_c_mm;
            R_c(1:3,4)=R_c(1:3,4)./1e3;

            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            X1=etc_render_fsbrain.object.UserData.Origin(1);
            Y1=etc_render_fsbrain.object.UserData.Origin(2);
            Z1=etc_render_fsbrain.object.UserData.Origin(3);

            X2=0;
            Y2=0;
            Z2=100;
            vtop=[X2-X1; Y2-Y1; Z2-Z1]';
            vtop_perp=vtop-sum(vtop.*tmp).*tmp;
           
            vup=etc_render_fsbrain.object.UserData.Up;


            %determine the rotation between two vectors;
            xx = cross(vtop_perp,vup);
            c = sign(dot(xx,etc_render_fsbrain.object.UserData.Axis)) * norm(xx);
            deg = atan2d(c,dot(vtop_perp,vup)).*pi./180;

            %[azimuth,elevation,r] = cart2sph(x,y,z);
            %deg=azimuth;
            theta=(deg);
            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;
            

            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);
            etc_render_fsbrain.object.UserData.Axis(:)';

            if(~isempty(app.norm_obj))
                norm_dir=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData]; norm_dir=diff(norm_dir,1,2); norm_dir=norm_dir./norm(norm_dir);
                etc_render_fsbrain.object.UserData.Axis=norm_dir(:);
                etc_render_fsbrain.object.UserData.Axis(:)';
            end;

            etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);
            etc_render_fsbrain.object.UserData.Up(:)';

            if(~isempty(app.up_obj))
                up_dir=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData]; up_dir=diff(up_dir,1,2); up_dir=up_dir./norm(up_dir);
                etc_render_fsbrain.object.UserData.Up=up_dir(:);
                etc_render_fsbrain.object.UserData.Up(:)';
            end;

            etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;

            fprintf('After moving: coil origin target: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));
            app.TextArea.Value{end+1}=sprintf('After moving: coil origin target: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));
            
            %end of aligning UP toward +z
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.rotatedegSlider.Value=0;
            app.offsetmmSlider.Value=0;
            app.tilthorizdegSlider.Value=0;
            app.tiltvertdegSlider.Value=0;

            app.vertdegEditField.Value=0;
            app.horizdegEditField.Value=0;
            app.offsetmmEditField.Value=0;
            app.rotatedegEditField.Value=0;


            app.EfieldCalclLamp.Color='r';
            
            app.coil_center_no_tune=etc_render_fsbrain.object.UserData.Origin;

            app.target_vertex_index=etc_render_fsbrain.click_coord;


            %automatically update strcoil
            if(app.StrcoilAutoCheckBox.Value)
                try
                    tmp=app.strcoil_obj.Vertices;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    xfm_tmp=app.strcoil_obj_xfm;
                    xfm_tmp(1:3,4)=xfm_tmp(1:3,4)*1e3; %into mm
                    tmp=xfm*inv(xfm_tmp)*tmp;

                    app.strcoil_obj.Vertices=tmp(1:3,:)'; %update
                    app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;

                    tmp=app.strcoil_obj.Vertices./1e3;
                    assignin('base','tmp',tmp);
                    evalin('base','strcoil.Pwire=tmp;');
                    fprintf('variable [strcoil] update at the workspace.\n');
                    app.TextArea.Value{end+1}='variable [strcoil] updated at the workspace.';
                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;
                catch
                    fprintf('Error in updating strcoil!\n');
                    app.TextArea.Value{end+1}='Error in updating strcoil!\n';
                end;
            end;

%             fprintf('Origin: %s\n',mat2str(etc_render_fsbrain.object.UserData.Origin,4));
%             fprintf('Origin_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Origin_orig,4));
%             fprintf('Axis_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis_orig,4));
%             fprintf('Axis: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis,4));
%             norm_dir=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData]; norm_dir=diff(norm_dir,1,2); norm_dir=norm_dir./norm(norm_dir);
%             fprintf('Axis object direction: %s\n',mat2str(norm_dir,4));
% 
%             fprintf('Up_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up_orig,4));
%             fprintf('Up: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up,4));
%             up_dir=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData]; up_dir=diff(up_dir,1,2); up_dir=up_dir./norm(up_dir);
%             fprintf('Up object direction: %s\n',mat2str(up_dir,4));
%             fprintf('-------------\n');
        end

        % Button pushed function: LoadcoilButton
        function LoadcoilButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.mat','TMS coil mat'});
            if(filename==0) return; end;

            Coil = load(sprintf('%s%s',pathname,filename));

            try
                if(isfield(Coil,'P')&isfield(Coil,'t')) app.CoilLamp.Color='g'; else return; end;

                Coil.P=Coil.P.*1e3; %in mm
                figure(etc_render_fsbrain.fig_brain);
                coil_patch=patch('vertices',Coil.P,'faces',Coil.t,'edgecolor','none','facecolor',[ 0.9294    0.6941    0.1255]);
                coil_patch.UserData.Origin=[0 0 0]'; %origin of the coil at +z = 0 mm
                coil_patch.UserData.Axis=[0 0 -1]'; %directional vector; coil plane normal
                coil_patch.UserData.Up=[0 1 0]'; %directional vector; coil upward

                if(isfield(etc_render_fsbrain,'object'))
                    if(~isempty(etc_render_fsbrain.object))
                        try
                            delete(etc_render_fsbrain.object);
                        catch
                        end;
                        
                        etc_render_fsbrain.object=[];
                        etc_render_fsbrain.object_xfm=[];
                    end;
                end;

                etc_render_fsbrain.object=coil_patch;

                if(~isempty(coil_patch))
                    etc_render_fsbrain.object_Vertices_orig=etc_render_fsbrain.object.Vertices;
                    %if(~isempty(object_xfm))
                    %    etc_render_fsbrain.object_xfm=object_xfm;
                    %else
                        etc_render_fsbrain.object_xfm=eye(4);
                    %end;

                    if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        etc_render_fsbrain.object.UserData.Origin_orig=etc_render_fsbrain.object.UserData.Origin;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                        etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Up'))
                        etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;
                    end;


                    if(isempty(app.up_obj))
                    else
                        delete(app.up_obj);
                    end;
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Up(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Up(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Up(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.up_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-g', 'lineWidth', 2);

                    flag_show_up=1;
                    app.VecUpCheckBox.Value=1;

                    if(isempty(app.norm_obj))
                    else
                        delete(app.norm_obj);
                    end;
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.norm_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-m', 'lineWidth', 5);

                    flag_show_norm=1;
                    app.VecNormCheckBox.Value=1;


                    app.CoilShowCheckBox.Value=1;
                    
                else
                    etc_render_fsbrain.object_xfm=[];
                    if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        etc_render_fsbrain.object.UserData.Origin_orig=etc_render_fsbrain.object.UserData.Origin;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                        etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Up'))
                        etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;
                    end;
                end;

                %resettting tuning parameters
                app.rotatedegSlider.Value=0;
                app.offsetmmSlider.Value=0;
                app.tilthorizdegSlider.Value=0;
                app.tiltvertdegSlider.Value=0;

                app.vertdegEditField.Value=0;
                app.horizdegEditField.Value=0;
                app.offsetmmEditField.Value=0;
                app.rotatedegEditField.Value=0;
                
                app.coil_center_no_tune=etc_render_fsbrain.object.UserData.Origin;

                app.TextArea.Value{end+1}=sprintf('Variable Coil successfully loaded!\n');
                

            catch
                fprintf('Error in loading coil MAT file [%s]!\n',sprintf('%s%s',pathname,filename));

                app.TextArea.Value{end+1}=sprintf('Error in loading coil MAT file [%s]!\n',sprintf('%s%s',pathname,filename));
            
            end;

        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            etc_render_fsbrain_handle('kb','cc','S');
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            etc_render_fsbrain_handle('kb','cc','x');
        end

        % Button pushed function: LoadstrcoilButton
        function LoadstrcoilButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.mat','TMS coil mat'});
            if(filename==0) return; end;

            Coil = load(sprintf('%s%s',pathname,filename));

            try
                if(isfield(Coil,'strcoil'))
                    tmp=Coil.strcoil.Pwire.*1e3;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    tmp=xfm*tmp;

                    %remember this to be used for updating later
                    app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;

                    if(~isempty(app.strcoil_obj))
                        if(isvalid(app.strcoil_obj))
                            try
                                delete(app.strcoil_obj);
                            catch
                            end;
                        end;
                    end;
                    app.strcoil_obj=patch('faces', Coil.strcoil.Ewire, 'vertices', tmp(1:3,:)', 'EdgeColor', 'g', 'FaceColor','b');
                 

                    assignin('base','strcoil',Coil.strcoil);
                    
                    tmp=app.strcoil_obj.Vertices./1e3;
                    assignin('base','tmp',tmp);
                    evalin('base','strcoil.Pwire=tmp;');
                    
                    fprintf('variable [strcoil] loaded to the workspace.\n');
                    app.TextArea.Value{end+1}='variable [strcoil] loaded to the workspace.';
                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;

                    app.StrcoilAutoCheckBox.Value=1;

                else
                    fprintf('No variable [strcoil] loaded to the workspace!\n');
                    app.TextArea.Value{end+1}='No variable [strcoil] loaded to the workspace!\n';
                    
                    return;
                end;
            catch
                fprintf('Error in loading strcoil MAT file [%s]!\n',sprintf('%s%s',pathname,filename));
                app.TextArea.Value{end+1}='Error in loading strcoil MAT file [%s]!\n';
            end;
        end

        % Value changed function: VecNormCheckBox
        function VecNormCheckBoxValueChanged(app, event)
            value = app.VecNormCheckBox.Value;
            
            global etc_render_fsbrain;

            app.flag_show_norm=value;
            if(value)
                if(isempty(app.norm_obj))
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.norm_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-m', 'lineWidth', 5);
                else
                    set(app.norm_obj,'visible','on');
                end;
            else
                if(~isempty(app.norm_obj))
                    set(app.norm_obj,'visible','off');
                end;
            end;
        end

        % Value changed function: VecUpCheckBox
        function VecUpCheckBoxValueChanged(app, event)
            value = app.VecUpCheckBox.Value;
            
            global etc_render_fsbrain;

            app.flag_show_up=value;
            if(value)
                if(isempty(app.up_obj))
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Up(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Up(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Up(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.up_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-g', 'lineWidth', 2);
                else
                    set(app.up_obj,'visible','on');
                end;
            else
                if(~isempty(app.up_obj))
                    set(app.up_obj,'visible','off');
                end;
            end;

        end

        % Value changed function: rotatedegSlider
        function rotatedegSliderValueChanged(app, event)
            value = app.rotatedegSlider.Value;

            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.rotatedegSlider.Value=0;
                return; 
            end;

            app.rotatedegEditField.Value=value;

            tune_rotate(app);
        end

        % Value changed function: tiltvertdegSlider
        function tiltvertdegSliderValueChanged(app, event)
            value = app.tiltvertdegSlider.Value;
            
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.tiltvertdegSlider.Value=0;
                return; 
            end;
            
            app.vertdegEditField.Value=value;

            tune_vert(app);
        end

        % Value changed function: offsetmmSlider
        function offsetmmSliderValueChanged(app, event)
            value = app.offsetmmSlider.Value;

            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.offsetmmSlider.Value=0;
                return; 
            end;
            
            app.offsetmmEditField.Value=value;

            tune_offset(app);
        end

        % Value changed function: tilthorizdegSlider
        function tilthorizdegSliderValueChanged(app, event)
            value = app.tilthorizdegSlider.Value;
    
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.tilthorizdegSlider.Value=0;
                return; 
            end;

            app.horizdegEditField.Value=value;

            tune_horiz(app);
        end

        % Value changed function: vertdegEditField
        function vertdegEditFieldValueChanged(app, event)
            value = app.vertdegEditField.Value;
            
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.vertdegEditField.Value=0;
                return; 
            end;

            app.tiltvertdegSlider.Value=value;

            tune_vert(app);
        end

        % Value changed function: horizdegEditField
        function horizdegEditFieldValueChanged(app, event)
            value = app.horizdegEditField.Value;
  
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.horizdegEditField.Value=0;
                return; 
            end;

            app.tilthorizdegSlider.Value=value;

            tune_horiz(app);            
        end

        % Value changed function: offsetmmEditField
        function offsetmmEditFieldValueChanged(app, event)
            value = app.offsetmmEditField.Value;
            
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.offsetmmEditField.Value=0;
                return; 
            end;
            
            app.offsetmmSlider.Value=value;

            tune_offset(app);               
        end

        % Value changed function: rotatedegEditField
        function rotatedegEditFieldValueChanged(app, event)
            value = app.rotatedegEditField.Value;

            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.rotatedegEditField.Value=0;
                return; 
            end;
            
            app.rotatedegSlider.Value=value;

            tune_rotate(app);
        end

        % Value changed function: CoilShowCheckBox
        function CoilShowCheckBoxValueChanged(app, event)
            value = app.CoilShowCheckBox.Value;
            
            global etc_render_fsbrain;

            if(~value)
                if(isfield(etc_render_fsbrain,'object'))
                    etc_render_fsbrain.object.Visible='off';
                end;


                strcoil_value = app.StrcoilShowCheckBox.Value;


                if(strcoil_value)
                    app.StrcoilShowCheckBox.Value=false;
                    if(~isempty(app.strcoil_obj))
                        app.strcoil_obj.Visible='off';
                    end;
                end;

            else
                if(isfield(etc_render_fsbrain,'object'))
                    etc_render_fsbrain.object.Visible='on';
                end;                
            end;
        end

        % Value changed function: StrcoilShowCheckBox
        function StrcoilShowCheckBoxValueChanged(app, event)
            value = app.StrcoilShowCheckBox.Value;
            
            global etc_render_fsbrain;

            if(~value)
                if(~isempty(app.strcoil_obj))
                    app.strcoil_obj.Visible='off';
                end;
            else
                if(~isempty(app.strcoil_obj))
                    app.strcoil_obj.Visible='on';
                end;                
            end;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            tms_nav_object=[];

            global etc_render_fsbrain;

            tstr=datestr(datetime('now'),'mmddyy_HHMMss');
            filename=sprintf('tms_nav_%s.mat',tstr);
         
            [filename, pathname, filterindex] = uiputfile('*.mat','TMS navigation mat',filename);
            if(filename==0) return; end;

            object=etc_render_fsbrain.object;
            object_xfm=etc_render_fsbrain.object_xfm;
            
            strcoil_obj=app.strcoil_obj;
            strcoil_obj_xfm=app.strcoil_obj_xfm;
            
            w=evalin('base','whos');
            flag_exist = ismember('strcoil',{w(:).name});

            if(flag_exist)
                strcoil = evalin('base', 'strcoil');
            else
                strcoil =[];
            end;

            up_obj=app.up_obj;
            norm_obj=app.norm_obj;
            
            coil_center_no_tune=app.coil_center_no_tune;

            if(isfield(etc_render_fsbrain,'surf_obj'))
                surf_obj=etc_render_fsbrain.surf_obj;
            else
                surf_obj=[];
            end;

            %head model
            tissue_def_file=etc_render_fsbrain.tissue_def_file;

            head_model.t=app.t;
            head_model.P=app.P;
            head_model.normals=app.normals;
            head_model.Center=app.Center;
            head_model.Area=app.Area;
            head_model.Indicator=app.Indicator;
            head_model.name=app.name;
            head_model.tissue=app.tissue;
            head_model.cond=app.cond;
            head_model.enclosingTissueIdx=app.enclosingTissueIdx;
            head_model.condin=app.condin;
            head_model.condout=app.condout;
            head_model.contrast=app.contrast;
            head_model.tneighbor=app.tneighbor;
            head_model.RnumberE=app.RnumberE;
            head_model.ineighborE=app.ineighborE;
            head_model.EC=app.EC;

            head_model.file_combinedMeshMAT=app.file_combinedMeshMAT;
            head_model.file_combinedMeshPMAT=app.file_combinedMeshPMAT;

            %target 
            if(isfield(etc_render_fsbrain,'click_coord'))
                target.vertex_index=etc_render_fsbrain.click_coord;
            else
                target.vertex_index=[];
            end;
            target.tilt_vert=app.vertdegEditField.Value;;
            target.tilt_horiz=app.horizdegEditField.Value;;
            target.offset=app.offsetmmEditField.Value;
            target.rotate=app.rotatedegEditField.Value;

            target.list=app.UITrgTable.Data;

            %efield
            efield=app.efield;

            save(filename,'surf_obj','object','tissue_def_file','head_model','target','efield','object_xfm','strcoil','strcoil_obj','strcoil_obj_xfm','up_obj','norm_obj','coil_center_no_tune');


        end

        % Button pushed function: UpdatestrcoilButton
        function UpdatestrcoilButtonPushed(app, event)
            global etc_render_fsbrain;

            try
                tmp=app.strcoil_obj.Vertices;
                tmp(:,end+1)=1;
                tmp=tmp';
                xfm=etc_render_fsbrain.object_xfm;
                xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                xfm_tmp=app.strcoil_obj_xfm;
                xfm_tmp(1:3,4)=xfm_tmp(1:3,4)*1e3; %into mm
                tmp=xfm*inv(xfm_tmp)*tmp;

                app.strcoil_obj.Vertices=tmp(1:3,:)'; %update
                app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;
                
                tmp=app.strcoil_obj.Vertices./1e3;
                assignin('base','tmp',tmp);
                evalin('base','strcoil.Pwire=tmp;');
                fprintf('variable [strcoil] update at the workspace.\n');
                app.TextArea.Value{end+1}='variable [strcoil] updated at the workspace.';
                app.StrcoilLamp.Color='g';
                app.StrcoilShowCheckBox.Value=1;

            catch
                fprintf('Error in updating strcoil!\n');
                app.TextArea.Value{end+1}='Error in updating strcoil!\n';
            end;


        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
             if(app.PrepModelLamp.Color==[1 0 0])
                return;
             end;
             if(app.StrcoilLamp.Color==[1 0 0])
                return;
             end;

             global etc_render_fsbrain;

             coords=etc_render_fsbrain.vertex_coords./1e3;;

             app.EfieldCalclLamp.Color='y';


             strcoil=evalin('base', 'strcoil');

             [status, app.efield]=etc_tms_efield_surf(app.t, app.P, app.normals, app.Center, app.Area, app.Indicator, app.name, app.tissue, app.cond, app.enclosingTissueIdx, app.condin, app.condout, app.contrast, app.tneighbor, app.RnumberE, app.ineighborE, app.EC, coords, 'tissue_to_plot','GM_LH','flag_save',1,'strcoil',strcoil);

             etc_render_fsbrain.overlay_vertex=app.efield.vertices;

             switch(app.EfieldDropDown.Value)
                 case 'Total'
                     etc_render_fsbrain.overlay_value=app.efield.Etotal;
                 case 'Normal'
                     etc_render_fsbrain.overlay_value=app.efield.Enormal;
                 case 'Tangent'
                     etc_render_fsbrain.overlay_value=app.efield.Etangent;
             end;

             etc_render_fsbrain.overlay_smooth=[];
             etc_render_fsbrain.overlay_source=1;
             if(isempty(etc_render_fsbrain.overlay_vol_stc))
                 etc_render_fsbrain_overlay_vol_init;
             else
                 etc_render_fsbrain.overlay_vol_stc=etc_render_fsbrain.overlay_value(:);
             end;


             if(app.EfieldShowCheckBox.Value==1)

                 etc_render_fsbrain.overlay_flag_render=1;

                 etc_render_fsbrain_handle('update_overlay_vol');

                 etc_render_fsbrain_handle('redraw');

                 etc_render_fsbrain_handle('draw_pointer');
             end;

             app.EfieldCalclLamp.Color='g';

        end

        % Value changed function: SurfaceDropDown
        function SurfaceDropDownValueChanged(app, event)
                   
            global etc_render_fsbrain;

            value=app.SurfaceDropDown.Value;
            [a,b]=ismember(app.SurfaceDropDown.Items, value);
            idx=find(b);
            
            if(isfield(etc_render_fsbrain.surf_obj(idx),'patch_handle'))
                if(etc_render_fsbrain.surf_obj(idx).patch_handle.Visible==1)                    
                    app.ShowscalpCheckBox.Value=true;
                else
                    app.ShowscalpCheckBox.Value=false;
                end;
            end;

        end

        % Button pushed function: PrepareheadmodelButton
        function PrepareheadmodelButtonPushed(app, event)

            try
                if(app.PrepModelLamp.Color=='r') return; end;


                global etc_render_fsbrain;
                if(isfield(etc_render_fsbrain,'tissue_def_file'))
                    app.file_tissue_index=etc_render_fsbrain.tissue_def_file;

                    app.PrepModelLamp.Color='y';
                    app.TextArea.Value{end+1}=sprintf('Preparing BEM ...\n');

                    [status, app.t, app.P, app.normals, app.Center, app.Area, app.Indicator, app.name, app.tissue, app.cond, app.enclosingTissueIdx, app.condin, app.condout, app.contrast, app.tneighbor, app.RnumberE, app.ineighborE, app.EC, app.file_combinedMeshMAT, app.file_combinedMeshPMAT] =etc_tms_efield_prep_model(app.file_tissue_index,'flag_display',1,'flag_save',1);

                    app.TextArea.Value{end+1}=sprintf('BEM prepared successfully.\n');
                    app.PrepModelLamp.Color='g';
                end;
            catch
                app.TextArea.Value{end+1}=sprintf('Error in BEM preparation.\n');

                app.PrepModelLamp.Color='r';
            end;

        end

        % Callback function
        function makeheadmodelButtonPushed(app, event)

        end

        % Callback function
        function HeadModelDropDownValueChanged(app, event)
            
        end

        % Button pushed function: DefineheadmodelButton
        function DefineheadmodelButtonPushed(app, event)
            
            etc_render_fsbrain_handle('kb','cc','T');

        end

        % Button pushed function: LoadxfmButton
        function LoadxfmButtonPushed(app, event)
            [filename, pathname, filterindex] = uigetfile({'*.mat','coil trnasformation mat'});
            if(filename==0) return; end;

            load(sprintf('%s%s',pathname,filename));
            if(~exist('object_xfm','var'))
                fprintf('error in loading [%s]!\n A variable named ''object_xfm'' must exist!\n',filename);
                return;
            end;

%             object_xfm=eye(4);
%             object_xfm(1:3, 1:3)=orientations;
%             object_xfm(1:3,4)=surface_coord(:)./1e3;

            results = target_apply_xfm(app, object_xfm(1:3,4).*1e3, -object_xfm(1:3,3), object_xfm(1:3,2));


        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            

            global etc_render_fsbrain;

            try
                

                [filename, pathname, filterindex] = uigetfile({'*.*','surface'});
                if(filename==0) return; end;
                file_import=sprintf('%s%s',pathname,filename);

                import_obj=load(file_import);


                %% TMS coil object
                try


                    if(isfield(etc_render_fsbrain,'object'))
                        if(~isempty(etc_render_fsbrain.object))
                            try
                                delete(etc_render_fsbrain.object);
                            catch
                            end;
                        end;
                    end;

                    etc_render_fsbrain.object=[];
                    etc_render_fsbrain.object_xfm=[];

                    
                    app.CoilLamp.Color='r';
                    app.CoilShowCheckBox.Value=0;
                    app.VecUpCheckBox.Value=0;
                    app.VecNormCheckBox.Value=0;
                    
                    %importing coil

                    figure(etc_render_fsbrain.fig_brain);
                    
                    
                    etc_render_fsbrain.object=patch('vertices',import_obj.object.Vertices,'faces',import_obj.object.Faces,'edgecolor','none','facecolor',[ 0.9294    0.6941    0.1255]);
                    

                    %coil_patch.UserData.Origin=import_obj.coil_center_no_tune(:)'; %origin of the coil at +z = 0 mm
                    
                    etc_render_fsbrain.object.UserData.Origin=import_obj.object.UserData.Origin;
                    etc_render_fsbrain.object.UserData.Axis=import_obj.object.UserData.Axis;
                    etc_render_fsbrain.object.UserData.Up=import_obj.object.UserData.Up;

                    etc_render_fsbrain.object.UserData.Origin_orig=import_obj.object.UserData.Origin_orig;
                    etc_render_fsbrain.object.UserData.Axis_orig=import_obj.object.UserData.Axis_orig;
                    etc_render_fsbrain.object.UserData.Up_orig=import_obj.object.UserData.Up_orig;


                    if(~isempty(etc_render_fsbrain.object))

                        if(isempty(app.up_obj))
                        else
                            delete(app.up_obj);
                        end;
                        X1=etc_render_fsbrain.object.UserData.Origin(1);
                        Y1=etc_render_fsbrain.object.UserData.Origin(2);
                        Z1=etc_render_fsbrain.object.UserData.Origin(3);

                        X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Up_orig(1).*100; %100 mm line
                        Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Up_orig(2).*100; %100 mm line
                        Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Up_orig(3).*100; %100 mm line
                        object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                        app.up_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-g', 'lineWidth', 2);

                        flag_show_up=1;
                        app.VecUpCheckBox.Value=1;

                        if(isempty(app.norm_obj))
                        else
                            delete(app.norm_obj);
                        end;
                        X1=etc_render_fsbrain.object.UserData.Origin(1);
                        Y1=etc_render_fsbrain.object.UserData.Origin(2);
                        Z1=etc_render_fsbrain.object.UserData.Origin(3);

                        X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis_orig(1).*100; %100 mm line
                        Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis_orig(2).*100; %100 mm line
                        Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis_orig(3).*100; %100 mm line
                        object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                        app.norm_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-m', 'lineWidth', 5);

                        flag_show_norm=1;
                        app.VecNormCheckBox.Value=1;


                        %etc_render_fsbrain.object_xfm=eye(4);
                        etc_render_fsbrain.object_xfm=import_obj.object_xfm;
                        if(~isempty(app.strcoil_obj))
                            if(app.StrcoilAutoCheckBox.Value)
                                app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;
                            end;
                        end;


                        app.CoilShowCheckBox.Value=1;

                    else
                        etc_render_fsbrain.object_xfm=[];
                        if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                            etc_render_fsbrain.object.UserData.Origin_orig=etc_render_fsbrain.object.UserData.Origin;
                        end;
                        if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                            etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;
                        end;
                        if(isfield(etc_render_fsbrain.object.UserData,'Up'))
                            etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;
                        end;
                    end;


%                     %update transformation matrix;
%                     etc_render_fsbrain.object_xfm=import_obj.object_xfm;
%                     coil_xfm_now_mm=import_obj.object_xfm;
%                     coil_xfm_now_mm(1:3,4)=coil_xfm_now_mm(1:3,4).*1e3;
% 
%                     %updating coil
%                     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %transform object in visualiation
% 
%                     if(~isempty(app.norm_obj))
%                         tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
%                         tmp=(coil_xfm_now_mm*tmp);
%                         app.norm_obj.XData=tmp(1,:);
%                         app.norm_obj.YData=tmp(2,:);
%                         app.norm_obj.ZData=tmp(3,:);
%                     end;
% 
%                     if(~isempty(app.up_obj))
%                         tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
%                         tmp=(coil_xfm_now_mm*tmp);
%                         app.up_obj.XData=tmp(1,:);
%                         app.up_obj.YData=tmp(2,:);
%                         app.up_obj.ZData=tmp(3,:);
%                     end;
% 
% 
% 
%                     %transform data
%                     etc_render_fsbrain.object.UserData.Origin=import_obj.coil_center_no_tune;
% 
%                     xfm_r=eye(4);
%                     xfm_r(1:3,1:3)=import_obj.object_xfm(1:3,1:3);
% 
%                     tmp=(xfm_r*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
%                     etc_render_fsbrain.object.UserData.Axis=tmp(1:3);
% 
%                     tmp=(xfm_r*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
%                     etc_render_fsbrain.object.UserData.Up=tmp(1:3);


                    app.CoilLamp.Color='g';

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                    %resettting tuning parameters
                    app.rotatedegSlider.Value=0;
                    app.offsetmmSlider.Value=0;
                    app.tilthorizdegSlider.Value=0;
                    app.tiltvertdegSlider.Value=0;

                    app.vertdegEditField.Value=0;
                    app.horizdegEditField.Value=0;
                    app.offsetmmEditField.Value=0;
                    app.rotatedegEditField.Value=0;

                    app.coil_center_no_tune=etc_render_fsbrain.object.UserData.Origin;

                    app.TextArea.Value{end+1}=sprintf('Coil object successfully imported!\n');

                    etc_render_fsbrain.object_xfm

                    fprintf('Axis_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis_orig,4));
                    fprintf('Axis: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis,4));
                    fprintf('Up_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up_orig,4));
                    fprintf('Up: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up,4));
                    fprintf('-------------\n');


                catch
                    fprintf('Error in importing coil object!\n');

                    app.TextArea.Value{end+1}=sprintf('Error in importing coil object!\n');
                end;
                

                %% strcoil object
                try

                    if(~isempty(app.strcoil_obj))
                        try
                            delete(app.strcoil_obj);
                        catch
                        end;

                        app.strcoil_obj=[];
                        etc_render_fsbrain.strcoil_obj_xfm=[];
                    end;

                    app.StrcoilLamp.Color='r';

                    figure(etc_render_fsbrain.fig_brain);

                    %importing strcoil
                    if(isfield(import_obj,'strcoil_obj'))
                        if(~isempty(import_obj.strcoil_obj))

                            app.strcoil_obj_xfm=import_obj.strcoil_obj_xfm;

                            app.strcoil_obj=patch('faces', import_obj.strcoil_obj.Faces, 'vertices', import_obj.strcoil_obj.Vertices, 'EdgeColor', 'g', 'FaceColor','b');

                            assignin('base','strcoil',import_obj.strcoil);

                            tmp=app.strcoil_obj.Vertices./1e3;
                            evalin('base','strcoil.Pwire=tmp;');

                            fprintf('variable [strcoil] loaded to the workspace.\n');
                            app.TextArea.Value{end+1}='variable [strcoil] loaded to the workspace.';
                            app.StrcoilLamp.Color='g';
                            app.StrcoilShowCheckBox.Value=1;

                        else
                            fprintf('No variable [strcoil] loaded to the workspace!\n');
                            app.TextArea.Value{end+1}='No variable [strcoil] loaded to the workspace!\n';
                        end;
                    end;


                catch
                    fprintf('Error in importing strcoil object!\n');

                    app.TextArea.Value{end+1}=sprintf('Error in importing strcoil object!\n');
                end;


                %% head model definition
                if(~isfield(import_obj,'tissue_def_file')) 
                    fprintf('variable ''tissue_def_file'' not exist in [%s]!\n',file_import);
                    return;
                end;


                [name, tissue, cond, enclosingTissueIdx, enclosingTissueName] = etc_render_fsbrain_tissue_read(import_obj.tissue_def_file);

                answer = questdlg('Replace or append?','Loading tissue table ...','Replace','Append','Cancel','Replace');
                tissue_name='';
                tissue_mat='';
                tissue_cond='';
                tissue_enclosing_tissue='';

                if(~exist(import_obj.tissue_def_file,'file'))
                    fprintf('file [%s] not exist!\n',import_obj.tissue_def_file);
                    return;
                end;

                etc_render_fsbrain.tissue_def_file=import_obj.tissue_def_file;

                switch answer
                    case 'Replace'
                        etc_render_fsbrain.tissue_obj=[];

                        app.SurfaceDropDown.Items={};
                        %app.enclosing_tissue_list=categorical({'FreeSpace'});

                        if(isfield(etc_render_fsbrain,'surf_obj'))
                            for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                                if(isfield(etc_render_fsbrain.surf_obj(surf_idx),'patch_handle'))
                                    if(~isempty(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                        if(isvalid(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                            try
                                                delete(etc_render_fsbrain.surf_obj(surf_idx).patch_handle);
                                            catch
                                            end;
                                        end
                                    end;
                                end;
                            end;
                            etc_render_fsbrain.surf_obj=[];
                        else
                            etc_render_fsbrain.surf_obj=[];
                        end;

                        for tissue_idx=1:length(name)
                            tissue_name{tissue_idx,1}=tissue{tissue_idx};
                            etc_render_fsbrain.tissue_obj(tissue_idx).tissue_name=tissue_name{tissue_idx,1};

                            tissue_mat{tissue_idx,1}=name{tissue_idx};
                            etc_render_fsbrain.tissue_obj(tissue_idx).tissue_mat=tissue_mat{tissue_idx,1};

                            tissue_cond{tissue_idx,1}=cond(tissue_idx);
                            etc_render_fsbrain.tissue_obj(tissue_idx).tissue_cond=tissue_cond{tissue_idx,1};

                            tissue_enclosing_tissue{tissue_idx,1}=enclosingTissueName{tissue_idx};
                            etc_render_fsbrain.tissue_obj(tissue_idx).tissue_enclosing_tissue=tissue_enclosing_tissue{tissue_idx,1};

                            %new boundary
                            [pp,ff]=fileparts(tissue_mat{tissue_idx,1});
                            [a,idx]=ismember(app.SurfaceDropDown.Items,ff);
                            if(isempty(find(idx)))
                                  app.SurfaceDropDown.Items{end+1}=ff;
                            end;

                            %update surf_obj
                            d=load(etc_render_fsbrain.tissue_obj(end).tissue_mat);


                            if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;

                            etc_render_fsbrain.surf_obj(tissue_idx).vertex=d.P;
                            etc_render_fsbrain.surf_obj(tissue_idx).face=d.t;
                            etc_render_fsbrain.surf_obj(tissue_idx).TR=triangulation(d.t,d.P);;
                            etc_render_fsbrain.surf_obj(tissue_idx).surf_center=incenter(etc_render_fsbrain.surf_obj(end).TR);
                            etc_render_fsbrain.surf_obj(tissue_idx).surf_norm=d.normals;
                            [eS, TriPS, TriMS] = mt(etc_render_fsbrain.surf_obj(tissue_idx).TR);
                            etc_render_fsbrain.surf_obj(tissue_idx).eS=eS;
                            etc_render_fsbrain.surf_obj(tissue_idx).TriPS=TriPS;
                            etc_render_fsbrain.surf_obj(tissue_idx).TriMS=TriMS;

                            cc=colororder;
                            cc_idx=length(etc_render_fsbrain.surf_obj);
                            cc_idx=mod(cc_idx+1,size(cc,1));
                            if(cc_idx==0) cc_idx=size(cc,1); end;
                            etc_render_fsbrain.surf_obj(tissue_idx).color=cc(cc_idx,:);
                            etc_render_fsbrain.surf_obj(tissue_idx).flag_show=1;
                            etc_render_fsbrain.surf_obj(tissue_idx).filename=name{tissue_idx};
                            etc_render_fsbrain.surf_obj(tissue_idx).category=tissue{tissue_idx};

                            figure(etc_render_fsbrain.fig_brain);
                            etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',d.P,'faces',d.t,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp

                            %axis vis3d tight;

                        end;
                        %t = table(tissue_name,tissue_mat,tissue_cond,tissue_enclosing_tissue);

                        %app.UITrgTable.Data=t;

                        app.DefModelLamp.Color='g';
                        app.PrepModelLamp.Color='r';

                    case 'Append'
                        if(~isfield(etc_render_fsbrain','tissue_obj'))
                            etc_render_fsbrain.tissue_obj=[];
                        end;
                        %app.enclosing_tissue_list=categorical({'FreeSpace'});

                        if(isfield(etc_render_fsbrain,'surf_obj'))
                            for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                                if(isfield(etc_render_fsbrain.surf_obj(surf_idx),'patch_handle'))
                                    if(~isempty(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                        if(isvalid(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                            try
                                                delete(etc_render_fsbrain.surf_obj(surf_idx).patch_handle);
                                            catch
                                            end;
                                        end
                                    end;
                                end;
                            end;
                            etc_render_fsbrain.surf_obj=[];
                        else
                            etc_render_fsbrain.surf_obj=[];
                        end;

                        for tissue_idx=1:length(name)
                            tissue_name{tissue_idx,1}=tissue{tissue_idx};
                            etc_render_fsbrain.tissue_obj(end+1).tissue_name=tissue_name{tissue_idx,1};

                            tissue_mat{tissue_idx,1}=name{tissue_idx};
                            etc_render_fsbrain.tissue_obj(end).tissue_mat=tissue_mat{tissue_idx,1};

                            tissue_cond{tissue_idx,1}=cond(tissue_idx);
                            etc_render_fsbrain.tissue_obj(end).tissue_cond=tissue_cond{tissue_idx,1};

                            tissue_enclosing_tissue{tissue_idx,1}=enclosingTissueName{tissue_idx};
                            etc_render_fsbrain.tissue_obj(end).tissue_enclosing_tissue=tissue_enclosing_tissue{tissue_idx,1};

                            [pp,ff]=fileparts(tissue_mat{tissue_idx,1});
                            [a,idx]=ismember(app.SurfaceDropDown.Items,ff);
                            if(isempty(find(idx)))
                                  app.SurfaceDropDown.Items{end+1}=ff;
                            end;

                            %update surf_obj
                            d=load(etc_render_fsbrain.tissue_obj(end).tissue_mat);


                            if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;
                            etc_render_fsbrain.surf_obj(end+1).vertex=d.P;
                            etc_render_fsbrain.surf_obj(end).face=d.t;
                            etc_render_fsbrain.surf_obj(end).TR=triangulation(d.t,d.P);;
                            etc_render_fsbrain.surf_obj(end).surf_center=incenter(etc_render_fsbrain.surf_obj(end).TR);
                            etc_render_fsbrain.surf_obj(end).surf_norm=d.normals;
                            [eS, TriPS, TriMS] = mt(etc_render_fsbrain.surf_obj(end).TR);
                            etc_render_fsbrain.surf_obj(end).eS=eS;
                            etc_render_fsbrain.surf_obj(end).TriPS=TriPS;
                            etc_render_fsbrain.surf_obj(end).TriMS=TriMS;

                            cc=colororder;
                            cc_idx=length(etc_render_fsbrain.surf_obj);
                            cc_idx=mod(cc_idx+1,size(cc,1));
                            if(cc_idx==0) cc_idx=size(cc,1); end;
                            etc_render_fsbrain.surf_obj(end).color=cc(cc_idx,:);
                            etc_render_fsbrain.surf_obj(end).flag_show=1;
                            etc_render_fsbrain.surf_obj(end).filename=name{tissue_idx};
                            etc_render_fsbrain.surf_obj(end).category=tissue{tissue_idx};

                            figure(etc_render_fsbrain.fig_brain);
                            etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',d.P,'faces',d.t,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp

                            %axis vis3d tight;
                        end;

                        app.DefModelLamp.Color='g';
                        app.PrepModelLamp.Color='r';
                end;
                if(isempty(app.SurfaceDropDown.Items))
                    app.ShowscalpCheckBox.Value=false;
                    app.ShowscalpCheckBox.Enable='off';
                else
                    app.ShowscalpCheckBox.Value=true;
                    app.ShowscalpCheckBox.Enable='on';
                end;
                
                %% head model preparation

                try
                    %if(sum(abs(app.PrepModelLamp.Color-[1 0 0]))<eps) return; end;
                    app.PrepModelLamp.Color='r';

                    flag_calc=0;

                    if(isfield(import_obj.head_model,'file_combinedMeshMAT'))
                        if(exist(import_obj.head_model.file_combinedMeshMAT,'file'))
                            fprintf('loading prepared BEM [%s] (combinedMesh.mat) ...\n',import_obj.head_model.file_combinedMeshMAT);
                            app.TextArea.Value{end+1}=sprintf('loading prepared BEM [%s] (combinedMesh.mat) ...\n',import_obj.head_model.file_combinedMeshMAT);

                            d=load(import_obj.head_model.file_combinedMeshMAT);
                            app.t=d.t;
                            app.P=d.P;
                            app.normals=d.normals;
                            app.Center=d.Center;
                            app.Area=d.Area;
                            app.Indicator=d.Indicator;
                            app.name=d.name;
                            app.tissue=d.tissue;
                            app.cond=d.cond;
                            app.enclosingTissueIdx=d.enclosingTissueIdx;
                            app.condin=d.condin;
                            app.condout=d.condout;
                            app.contrast=d.contrast;

                            app.file_combinedMeshMAT=import_obj.head_model.file_combinedMeshMAT;

                            flag_calc=flag_calc+1;
                        end;
                    else
                        try
                            app.TextArea.Value{end+1}=sprintf('loading prepared BEM from the imported file ...\n');

                            app.t=import_obj.head_model.t;
                            app.P=import_obj.head_model.P;
                            app.normals=import_obj.head_model.normals;
                            app.Center=import_obj.head_model.Center;
                            app.Area=import_obj.head_model.Area;
                            app.Indicator=import_obj.head_model.Indicator;
                            app.name=import_obj.head_model.name;
                            app.tissue=import_obj.head_model.tissue;
                            app.cond=import_obj.head_model.cond;
                            app.enclosingTissueIdx=import_obj.head_model.enclosingTissueIdx;
                            app.condin=import_obj.head_model.condin;
                            app.condout=import_obj.head_model.condout;
                            app.contrast=import_obj.head_model.contrast;


                            flag_calc=flag_calc+1;

                        catch
                            fprintf('error in loading prepared BEM from the imported file!\n');
                        end;
                    end;
                    if(isfield(import_obj.head_model,'file_combinedMeshPMAT'))
                        if(exist(import_obj.head_model.file_combinedMeshPMAT,'file'))
                            fprintf('loading prepared BEM [%s] (combinedMeshP.mat) ...\n',import_obj.head_model.file_combinedMeshPMAT);
                            app.TextArea.Value{end+1}=sprintf('loading prepared BEM [%s] (combinedMeshP.mat)...\n',import_obj.head_model.file_combinedMeshPMAT);

                            app.tneighbor=import_obj.head_model.tneighbor;
                            app.RnumberE=import_obj.head_model.RnumberE;
                            app.ineighborE=import_obj.head_model.ineighborE;
                            app.EC=import_obj.head_model.EC;

                            flag_calc=flag_calc+1;
                        end;
                    else
                        fprintf('loading prepared BEM from the imported file ...\n');

                        try
                            app.tneighbor=d.tneighbor;
                            app.RnumberE=d.RnumberE;
                            app.ineighborE=d.ineighborE;
                            app.EC=d.EC;
    
                            app.file_combinedMeshPMAT=import_obj.head_model.file_combinedMeshPMAT;

                            flag_calc=flag_calc+1;
                        catch
                            fprintf('error in loading prepared BEM from the imported file!\n');
                        end;
                    end;

                    %not loading prepared BEM...
                    if(flag_calc<1.5)
                        app.PrepModelLamp.Color='y';
                        fprintf('Preparing BEM....\n');
                        app.TextArea.Value{end+1}=sprintf('Preparing BEM ...\n');

                        [status, app.t, app.P, app.normals, app.Center, app.Area, app.Indicator, app.name, app.tissue, app.cond, app.enclosingTissueIdx, app.condin, app.condout, app.contrast, app.tneighbor, app.RnumberE, app.ineighborE, app.EC, app.file_combinedMeshMAT, app.file_combinedMeshPMAT] =etc_tms_efield_prep_model(app.file_tissue_index,'flag_display',1,'flag_save',1);

                        app.PrepModelLamp.Color='g';
                        fprintf('BEM prepared successfully.\n');
                        app.TextArea.Value{end+1}=sprintf('BEM prepared successfully.\n');
                    else
                        app.PrepModelLamp.Color='g';
                    end;
                catch
                    fprintf('Error in BEM preparation.\n');
                    app.TextArea.Value{end+1}=sprintf('Error in BEM preparation.\n');

                    app.PrepModelLamp.Color='r';
                end;

                %% E-field
                try
                    app.EfieldCalclLamp.Color='r';

                    if(isfield(import_obj,'efield'))
                        if(~isempty(import_obj.efield))

                            app.efield=import_obj.efield;
                            
                            app.EfieldCalclLamp.Color='g';

                            etc_render_fsbrain.overlay_vertex=app.efield.vertices;
                            etc_render_fsbrain.overlay_value=app.efield.Etotal;
                            etc_render_fsbrain.overlay_smooth=[];
                            etc_render_fsbrain.overlay_flag_render=1;

                            etc_render_fsbrain.overlay_source=1;
                            etc_render_fsbrain_overlay_vol_init;
                            %etc_render_fsbrain_overlay_vol_update;
                            etc_render_fsbrain_handle('update_overlay_vol');

                            etc_render_fsbrain_handle('redraw');

                            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',etc_render_fsbrain.click_vertex,'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);

                            app.EfieldShowCheckBox.Value=1;

                        end;
                    end;
                catch
                end;


                %% TMS target
                try
                    etc_render_fsbrain.click_vertex=import_obj.target.vertex_index;

                    if(~isempty(etc_render_fsbrain.click_vertex))
                        coord= etc_render_fsbrain.click_vertex;

                        surface_coord=coord(:)';
                        tmp=[surface_coord 1]';
                        click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*tmp;
                        click_vertex_vox=click_vertex_vox(1:3)';

                        vv=etc_render_fsbrain.orig_vertex_coords;
                        dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                        [min_dist,min_dist_idx]=min(dist);
                        %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                        etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);

                        %parameters for tuning TMS target; As the loaded
                        %TMS target has been tuned, no need to apply the
                        %tuning again except updating the GUI.
                        app.tilthorizdegSlider.Value=import_obj.target.tilt_horiz;
                        app.tiltvertdegSlider.Value=import_obj.target.tilt_horiz;
                        app.offsetmmSlider.Value=import_obj.target.offset;
                        app.rotatedegSlider.Value=import_obj.target.rotate;

                        app.horizdegEditField.Value=import_obj.target.tilt_horiz;
                        app.vertdegEditField.Value=import_obj.target.tilt_horiz;;
                        app.offsetmmEditField.Value=import_obj.target.offset;
                        app.rotatedegEditField.Value=import_obj.target.rotate;
                    end;

                    app.UITrgTable.Data=import_obj.target.list;

                catch
                end;
            catch
                fprintf('error in importing [%s]!\n',file_import);
                return;
            end;
        end

        % Button pushed function: LoadpreparedmodelButton
        function LoadpreparedmodelButtonPushed(app, event)
            global etc_render_fsbrain

            try

                %%%%% CombinedMesh.mat
                fprintf('loading mesh MAT (combinedMesh.mat)...\n');

                [filename, pathname, filterindex] = uigetfile({'*.mat','combinedMesh.mat'});
                if(filename==0) return; end;

                d=load(sprintf('%s%s',pathname,filename));

                app.TextArea.Value{end+1}=sprintf('Loading [%s%s]...\n',pathname,filename);

                if(~isfield(d,'t'))
                    fprintf('Variable ''t'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''t'' not existed in prepared model file.\n';
                    return;
                else
                    app.t=d.t;
                end;
                
                if(~isfield(d,'P'))
                    fprintf('Variable ''P'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''P'' not existed in prepared model file.\n';
                    return;
                else
                    app.P=d.P;
                end;
                
                if(~isfield(d,'normals'))
                    fprintf('Variable ''normals'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''normals'' not existed in prepared model file.\n';
                    return;
                else
                    app.normals=d.normals;
                end;
                
                if(~isfield(d,'Center'))
                    fprintf('Variable ''Center'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''Center'' not existed in prepared model file.\n';
                    return;
                else
                    app.Center=d.Center;
                end;

                if(~isfield(d,'Area'))
                    fprintf('Variable ''Area'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''Area'' not existed in prepared model file.\n';
                    return;
                else
                    app.Area=d.Area;
                end;

                if(~isfield(d,'Indicator'))
                    fprintf('Variable ''Indicator'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''Indicator'' not existed in prepared model file.\n';
                    return;
                else
                    app.Indicator=d.Indicator;
                end;

                if(~isfield(d,'cond'))
                    fprintf('Variable ''cond'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''cond'' not existed in prepared model file.\n';
                    return;
                else
                    app.cond=d.cond;
                end;           

                if(~isfield(d,'enclosingTissueIdx'))
                    fprintf('Variable ''enclosingTissueIdx'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''enclosingTissueIdx'' not existed in prepared model file.\n';
                    return;
                else
                    app.enclosingTissueIdx=d.enclosingTissueIdx;
                end;                 
 
                if(~isfield(d,'condin'))
                    fprintf('Variable ''condin'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''condin'' not existed in prepared model file.\n';
                    return;
                else
                    app.condin=d.condin;
                end;   
 
                if(~isfield(d,'condout'))
                    fprintf('Variable ''condout'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''condout'' not existed in prepared model file.\n';
                    return;
                else
                    app.condout=d.condout;
                end;
                
                if(~isfield(d,'contrast'))
                    fprintf('Variable ''contrast'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''contrast'' not existed in prepared model file.\n';
                    return;
                else
                    app.contrast=d.contrast;
                end;
                
                if(~isfield(d,'name'))
                    fprintf('Variable ''name'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''name'' not existed in prepared model file.\n';
                    return;
                else
                    app.name=d.name;
                end;

                if(~isfield(d,'tissue'))
                    fprintf('Variable ''tissue'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''tissue'' not existed in prepared model file.\n';
                    return;
                else
                    app.tissue=d.tissue;
                end;

                app.file_combinedMeshMAT=sprintf('%s%s',pathname,filename);


                %%%%% CombinedMeshP.mat
                fprintf('loading mesh P MAT (combinedMeshP.mat)...\n');

                [filename, pathname, filterindex] = uigetfile({'*.mat','combinedMeshP.mat'});
                if(filename==0) return; end;

                d=load(sprintf('%s%s',pathname,filename));

                app.TextArea.Value{end+1}=sprintf('Loading [%s%s]...\n',pathname,filename);

                if(~isfield(d,'EC'))
                    fprintf('Variable ''EC'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''EC'' not existed in prepared model file.\n';
                    return;
                else
                    app.EC=d.EC;
                end;

                if(~isfield(d,'RnumberE'))
                    fprintf('Variable ''RnumberE'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''RnumberE'' not existed in prepared model file.\n';
                    return;
                else
                    app.RnumberE=d.RnumberE;
                end;                

                if(~isfield(d,'ineighborE'))
                    fprintf('Variable ''ineighborE'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''ineighborE'' not existed in prepared model file.\n';
                    return;
                else
                    app.ineighborE=d.ineighborE;
                end;

                if(~isfield(d,'tneighbor'))
                    fprintf('Variable ''tneighbor'' not existed in prepared model file.\n');
                    app.TextArea.Value{end+1}='Variable ''tneighbor'' not existed in prepared model file.\n';
                    return;
                else
                    app.tneighbor=d.tneighbor;
                end;

                app.file_combinedMeshPMAT=sprintf('%s%s',pathname,filename);

                app.DefModelLamp.Color='g';
                app.PrepModelLamp.Color='g';


            catch
                fprintf('error in loading prepared head model!\n');
                app.TextArea.Value{end+1}='error in loading prepared head model!\n';
            end

        end

        % Value changed function: EfieldDropDown
        function EfieldDropDownValueChanged(app, event)
            value = app.EfieldDropDown.Value;


            global etc_render_fsbrain;


            if(app.EfieldShowCheckBox.Value==1)

                etc_render_fsbrain.overlay_vertex=app.efield.vertices;

                switch(app.EfieldDropDown.Value)
                    case 'Total'
                        etc_render_fsbrain.overlay_value=app.efield.Etotal;
                    case 'Normal'
                        etc_render_fsbrain.overlay_value=app.efield.Enormal;
                    case 'Tangent'
                        etc_render_fsbrain.overlay_value=app.efield.Etangent;
                end;

                etc_render_fsbrain.overlay_smooth=[];
                etc_render_fsbrain.overlay_source=1;
                if(isempty(etc_render_fsbrain.overlay_vol_stc))
                    etc_render_fsbrain_overlay_vol_init;
                else
                    etc_render_fsbrain.overlay_vol_stc=etc_render_fsbrain.overlay_value(:);
                end;


                if(app.EfieldShowCheckBox.Value==1)

                    %                     etc_render_fsbrain.overlay_flag_render=1;
                    %
                    %                     etc_render_fsbrain_handle('update_overlay_vol');
                    %
                    %                     etc_render_fsbrain_handle('redraw');
                    %
                    %                     etc_render_fsbrain_handle('draw_pointer');
                    etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
                    etc_render_fsbrain_handle('kb','cv','cv'); %update colorbar

                    etc_render_fsbrain.overlay_flag_render=value;

                    etc_render_fsbrain_handle('update_overlay_vol');
                    etc_render_fsbrain_handle('redraw');
                    etc_render_fsbrain_handle('draw_pointer');

                end;

                %etc_render_fsbrain.overlay_vol_stc=etc_render_fsbrain.overlay_value;

                %etc_render_fsbrain_handle('update_overlay_vol');

                %etc_render_fsbrain_handle('redraw');

                %etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',etc_render_fsbrain.click_vertex,'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
            end;
        end

        % Value changed function: EfieldShowCheckBox
        function EfieldShowCheckBoxValueChanged(app, event)
            value = app.EfieldShowCheckBox.Value;

            global etc_render_fsbrain

            etc_render_fsbrain.overlay_vol_flag_render=value;
            %etc_render_fsbrain_handle('redraw');
            etc_render_fsbrain_handle('draw_pointer','surface_coord',etc_render_fsbrain.click_coord,'min_dist_idx',[],'click_vertex_vox',etc_render_fsbrain.click_vertex_vox);
            etc_render_fsbrain_handle('kb','cv','cv'); %update colorbar

            etc_render_fsbrain.overlay_flag_render=value;

            etc_render_fsbrain_handle('update_overlay_vol');
            etc_render_fsbrain_handle('redraw');
            etc_render_fsbrain_handle('draw_pointer');


        end

        % Button pushed function: BrainsightTrgImportButton
        function BrainsightTrgImportButtonPushed(app, event)


            [filename, pathname, filterindex] = uigetfile({'*.txt','Braingsight target'});
            if(filename==0) return; end;


            try
                %read targets from the file
                fn=sprintf('%s/%s',pathname, filename);
                fprintf('loading [%s] ...\n',fn);
                app.TextArea.Value{end+1}=sprintf('loading [%s] ...\n',fn);
                [name,xx,yy,zz,m0n0,	m0n1,	m0n2,	m1n0,	m1n1,	m1n2,	m2n0,	m2n1,	m2n2]=textread(sprintf('%s/%s',pathname, filename),'%s\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f','commentstyle','shell','whitespace','\t');
            

                for trg_idx=1:length(name)
                    global etc_render_fsbrain;

                    name_now{1,1}=name{trg_idx};

                    if(~isempty(app.UITrgTable.Data))
                        idx=ismember(app.UITrgTable.Data{:,1}, name{trg_idx});
                    else
                        idx=[];
                    end;

                    if(isempty(find(idx)))
                        if(~isempty(etc_render_fsbrain.object_xfm))


                            tmp=[xx(trg_idx) yy(trg_idx) zz(trg_idx)];
                            coords{1,1}=mat2str(tmp(1:3),4);

                            orientations_tmp=[m0n0(trg_idx), m0n1(trg_idx), m0n2(trg_idx),...
                                m1n0(trg_idx), m1n1(trg_idx), m1n2(trg_idx),...
                                m2n0(trg_idx), m2n1(trg_idx), m2n2(trg_idx)];

                            orientations{1,1}=mat2str(orientations_tmp(:),4);

                            t = table(name_now,coords(:)', orientations, [0 0 0 0]); %no tuning parameters
                            t.Properties.VariableNames=app.UITrgTable.Data.Properties.VariableNames;

                            if(isempty(app.UITrgTable.Data))
                                app.UITrgTable.Data=t;
                            else
                                app.UITrgTable.Data = [app.UITrgTable.Data;t];
                            end;
                        end;


                        str=sprintf('loaded target [%d]: <%s>:: (%2.2f, %2.2f, %2.2f) {%2.2f %2.2f %2.2f; %2.2f %2.2f %2.2f; %2.2f %2.2f %2.2f}',trg_idx, name{trg_idx},...
                            xx(trg_idx), yy(trg_idx), zz(trg_idx),...
                            m0n0(trg_idx), m0n1(trg_idx), m0n2(trg_idx),...
                            m1n0(trg_idx), m1n1(trg_idx), m1n2(trg_idx),...
                            m2n0(trg_idx), m2n1(trg_idx), m2n2(trg_idx));
                        fprintf('%s\n',str)
                        app.TextArea.Value{end+1}=str;


                    else
                        fprintf('[%s] already included in the target list!\n',name{trg_idx});
                    end;
                end;

                global etc_render_fsbrain;

                %the last target is used
                scanner_coord=[xx(end) yy(end) zz(end)];
                click_vertex_vox=inv(etc_render_fsbrain.vol.vox2ras)*[xx(end) yy(end) zz(end) 1]';
                click_vertex_vox=click_vertex_vox(1:3);


                fprintf('scanner coordinate %s < --> MRI volume coordinate [C, R, S] = [%1.1f %1.1f %1.1f] \n',mat2str(scanner_coord(1:3),4),click_vertex_vox(1),click_vertex_vox(2),click_vertex_vox(3));

                %update target
                try

                    app.CRSEditField.Value=sprintf('%1.1f %1.1f %1.1f',click_vertex_vox(1),click_vertex_vox(2),click_vertex_vox(3));

                    coord= click_vertex_vox;
                    click_vertex_vox=[coord];
                    tmp=[click_vertex_vox(:)' 1]';

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;


                object_xfm=eye(4);
                object_xfm(1:3, 1:3)=orientations;
                object_xfm(1:3,4)=surface_coord(:)./1e3;

                results = target_apply_xfm(app, object_xfm(1:3,4).*1e3, -object_xfm(1:3,3), object_xfm(1:3,2));


            fprintf('Origin: %s\n',mat2str(etc_render_fsbrain.object.UserData.Origin,4));
            fprintf('Origin_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Origin_orig,4));
            fprintf('Axis_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis_orig,4));
            fprintf('Axis: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis,4));
            norm_dir=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData]; norm_dir=diff(norm_dir,1,2); norm_dir=norm_dir./norm(norm_dir);
            fprintf('Axis object direction: %s\n',mat2str(norm_dir,4));

            fprintf('Up_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up_orig,4));
            fprintf('Up: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up,4));
            up_dir=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData]; up_dir=diff(up_dir,1,2); up_dir=up_dir./norm(up_dir);
            fprintf('Up object direction: %s\n',mat2str(up_dir,4));
            fprintf('-------------\n');
            catch

            end;


        end

        % Button pushed function: BrainsightTrgExportButton
        function BrainsightTrgExportButtonPushed(app, event)
            global etc_render_fsbrain;

            tstr=datestr(datetime('now'),'mmddyy_HHMMss');
            filename=sprintf('tms_nav_to_brainsight_%s.txt',tstr);
         
            [filename, pathname, filterindex] = uiputfile('*.txt','Txt for Brainsight targets',filename);
            if(filename==0) return; end;

            try
                fp=fopen(sprintf('%s%s',pathname,filename),'w');

                fprintf(fp,'# Version: 14\n');
                fprintf(fp,'# Coordinate system: NIfTI:Q:Scanner\n');
                fprintf(fp,'# Created by: Matlab\n');
                fprintf(fp,'# Units: millimetres, degrees, milliseconds, and microvolts\n');
                fprintf(fp,'# Encoding: UTF-8\n');
                fprintf(fp,'# Notes: Each column is delimited by a tab. Each value within a column is delimited by a semicolon.\n');
                fprintf(fp,'# Target Name	Loc. X	Loc. Y	Loc. Z	m0n0	m0n1	m0n2	m1n0	m1n1	m1n2	m2n0	m2n1	m2n2\n');


%                 name='tms_target';
% 
%                 tmp0=inv(etc_render_fsbrain.vol.tkrvox2ras)*[etc_render_fsbrain.object_xfm(1:3,4).*1e3; 1];
%                 tmp=(etc_render_fsbrain.vol.vox2ras)*[tmp0(1:3); 1];
% 
%                 xx=tmp(1);
%                 yy=tmp(2);
%                 zz=tmp(3);
%                 m0n0=etc_render_fsbrain.object_xfm(1,1);
%                 m0n1=etc_render_fsbrain.object_xfm(2,1);
%                 m0n2=etc_render_fsbrain.object_xfm(3,1);
% 
%                 m1n0=etc_render_fsbrain.object_xfm(1,2);
%                 m1n1=etc_render_fsbrain.object_xfm(2,2);
%                 m1n2=etc_render_fsbrain.object_xfm(3,2);
% 
%                 m2n0=etc_render_fsbrain.object_xfm(1,3);
%                 m2n1=etc_render_fsbrain.object_xfm(2,3);
%                 m2n2=etc_render_fsbrain.object_xfm(3,3);

                for idx=1:size(app.UITrgTable.Data,1)
                    coords=app.UITrgTable.Data(idx,2);
                    coords=str2num(coords{1,1}{1});

                    xx=coords(1);
                    yy=coords(2);
                    zz=coords(3);
                    
                    orientations=app.UITrgTable.Data(selection(1),3);
                    orientations=reshape(str2num(orientations{1,1}{1}),[3 3])';

                    m0n0=orientations(1,1);
                    m0n1=orientations(2,1);
                    m0n2=orientations(3,1);
                    m1n0=orientations(1,2);
                    m1n1=orientations(2,2);
                    m1n2=orientations(3,2);
                    m2n0=orientations(1,3);
                    m2n1=orientations(2,3);
                    m2n2=orientations(3,3);
                    
                    fprintf(fp,'%s\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\n',...
                    name,xx,yy,zz,m0n0,	m0n1,	m0n2,	m1n0,	m1n1,	m1n2,	m2n0,	m2n1,	m2n2);
                end;

                fclose(fp);
            catch

            end;


        end

        % Value changed function: ScannerEditField
        function ScannerEditFieldValueChanged(app, event)
            value = app.ScannerEditField.Value;
           
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);
                    
                    click_vertex_vox=inv(etc_render_fsbrain.vol.vox2ras)*[coord(:); 1];
                    click_vertex_vox=click_vertex_vox(1:3);

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;


        end

        % Button pushed function: TrgAddButton
        function TrgAddButtonPushed(app, event)
            
            global etc_render_fsbrain;

            name_now{1,1}=sprintf('target%03d',size(app.UITrgTable.Data,1)+1);

            if(~isempty(etc_render_fsbrain.object_xfm))

                tmp0=inv(etc_render_fsbrain.vol.tkrvox2ras)*[etc_render_fsbrain.object_xfm(1:3,4).*1e3; 1];
                tmp=(etc_render_fsbrain.vol.vox2ras)*[tmp0(1:3); 1];
                coords{1,1}=mat2str(tmp(1:3),4);

                orientations_tmp=etc_render_fsbrain.object_xfm(1:3,1:3);
                orientations{1,1}=mat2str(orientations_tmp(:)',4);

                tune_param=[app.vertdegEditField.Value, app.horizdegEditField.Value, app.offsetmmEditField.Value, app.rotatedegEditField.Value];

                t = table(name_now,coords(:)', orientations, tune_param);

                if(isempty(app.UITrgTable.Data))
                    app.UITrgTable.Data=t;
                else
                    app.UITrgTable.Data = [app.UITrgTable.Data;t];
                end;
            end;
        end

        % Selection changed function: UITrgTable
        function UITrgTableSelectionChanged(app, event)
            selection = app.UITrgTable.Selection;
            
            global etc_render_fsbrain;

            if(~isempty(selection))
                scanner_coord=app.UITrgTable.Data(selection(1),2);
                scanner_coord=str2num(scanner_coord{1,1}{1});

                orientations=app.UITrgTable.Data(selection(1),3);
                orientations=reshape(str2num(orientations{1,1}{1}),[3 3]);

                tune_param=app.UITrgTable.Data(selection(1),4);
                tune_param=tune_param{1,1};
                
                
                global etc_render_fsbrain;

                %the last target is used
                click_vertex_vox=inv(etc_render_fsbrain.vol.vox2ras)*[scanner_coord(:)' 1]';
                click_vertex_vox=click_vertex_vox(1:3);


                fprintf('scanner coordinate %s < --> MRI volume coordinate [C, R, S] = [%1.1f %1.1f %1.1f] \n',mat2str(scanner_coord(1:3),4),click_vertex_vox(1),click_vertex_vox(2),click_vertex_vox(3));

                %update target
                try

                    app.CRSEditField.Value=sprintf('%1.1f %1.1f %1.1f',click_vertex_vox(1),click_vertex_vox(2),click_vertex_vox(3));

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;

                object_xfm=eye(4);
                object_xfm(1:3, 1:3)=orientations;
                object_xfm(1:3,4)=surface_coord(:)./1e3;

                results = target_apply_xfm(app, object_xfm(1:3,4).*1e3, -object_xfm(1:3,3), object_xfm(1:3,2));


                app.horizdegEditField.Value=tune_param(1);
                app.vertdegEditField.Value=tune_param(2);
                app.offsetmmEditField.Value=tune_param(3);
                app.rotatedegEditField.Value=tune_param(4);

                app.tilthorizdegSlider.Value=tune_param(1);
                app.tiltvertdegSlider.Value=tune_param(2);
                app.offsetmmSlider.Value=tune_param(3);
                app.rotatedegSlider.Value=tune_param(4);

%             fprintf('Origin: %s\n',mat2str(etc_render_fsbrain.object.UserData.Origin,4));
%             fprintf('Origin_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Origin_orig,4));
%             fprintf('Axis_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis_orig,4));
%             fprintf('Axis: %s\n',mat2str(etc_render_fsbrain.object.UserData.Axis,4));
%             norm_dir=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData]; norm_dir=diff(norm_dir,1,2); norm_dir=norm_dir./norm(norm_dir);
%             fprintf('Axis object direction: %s\n',mat2str(norm_dir,4));
% 
%             fprintf('Up_orig: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up_orig,4));
%             fprintf('Up: %s\n',mat2str(etc_render_fsbrain.object.UserData.Up,4));
%             up_dir=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData]; up_dir=diff(up_dir,1,2); up_dir=up_dir./norm(up_dir);
%             fprintf('Up object direction: %s\n',mat2str(up_dir,4));
%             fprintf('-------------\n');


            end;

        end

        % Key press function: UITrgTable
        function UITrgTableKeyPress(app, event)
            key = event.Key;

            switch key
                case {'delete','backspace'}
                    if(~isempty(event.Source.Selection))
                        app.UITrgTable.Data(event.Source.Selection(1),:)=[];
                    end;
            end;

        end

        % Button pushed function: TrgDelButton
        function TrgDelButtonPushed(app, event)
            selection = app.UITrgTable.Selection;
            if(~isempty(selection))
                app.UITrgTable.Data(selection(1),:)=[];
            end;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            fprintf('Creating TMS coil model [%s]...\n',app.TMSCoilDropDown.Value);
            app.TextArea.Value{end+1}=sprintf('Creating TMS coil model [%s]...\n',app.TMSCoilDropDown.Value);

            switch app.TMSCoilDropDown.Value

                case 'MagVenture_MRiB91'
                    %   The figure-eight coil includes elliptical turns/loops. The coil axis is
                    %   the z-axis. We construct one part of the coil first.
                    %   When crossing the xz-plane, the i   ntersection points for the loop
                    %   centerlines are
                    x0  = 1e-3*[20.2500   25.7500   31.2500   36.7500   ...
                        20.2500   25.7500   31.2500   36.7500];
                    %   When crossing the yz-plane, the intersection points for the loop
                    %   centerlines are
                    y0  = 1e-3*[28.7500   34.2500   39.7500   45.2500  ...
                        28.7500   34.2500   39.7500   45.2500];
                    %   When crossing the xz- or yz-planes, the intersection points for the
                    %   loop centerlines are (this is a z-offset)
                    z0  = 1e-3*[-4.6000   -4.6000   -4.6000   -4.6000  ...
                        4.6000    4.6000    4.6000    4.6000];

                    %   Other parameters
                    a    = 3.50e-3;     %   z-side, m  (for a rectangle cross-section)
                    b    = 2.20e-3;     %   x-side, m  (for a rectangle cross-section)
                    M    = 32;          %   number of cross-section subdivisions
                    N    = 128;          %   number of perimeter subdivisions
                    flag = 2;           %   rectangular cross-section
                    sk   = 1;           %   surface current distribution (skin layer)

                    %   Construct the coil mesh for one part
                    [Pwire, Ewire, Swire, P, t, tind] = meshcoil(x0, y0, z0, M, N, a, b, flag, sk);

                    %   Construct the entire coil as a combination of two parts
                    %   Separate two coils along the x-axis
                    offset = -39.5e-3;
                    Pwire1 = Pwire; Pwire1(:, 1) = Pwire1(:, 1) - offset;
                    Pwire2 = Pwire; Pwire2(:, 1) = Pwire2(:, 1) + offset;
                    Ewire1 = Ewire;
                    Ewire2 = Ewire+size(Pwire, 1);

                    strcoil.Pwire = [Pwire1; Pwire2];
                    strcoil.Pwire(:, 3) = strcoil.Pwire(:, 3) - min(strcoil.Pwire(:, 3));
                    strcoil.Ewire = [Ewire1; Ewire2];
                    strcoil.Swire = [+Swire; -Swire]; %  swap current direction for the second part

                    P1 = P; P1(:, 1) = P(:, 1) - offset;
                    P2 = P; P2(:, 1) = P(:, 1) + offset;
                    t1 = t;
                    t2 = t+size(P, 1);
                    P = [P1; P2];
                    P(:, 3) = P(:, 3) - min(P(:, 3));
                    t    = [t1; t2];
                    tind = [tind; tind+max(tind)];

                case 'MagVenture_Cool_B35'

                    %   The coil is in the form of two non-interconnected spiral arms. The
                    %   conductor centerline model is given first
                    turns = 32;
                    theta = [0:pi/20:turns*2*pi-pi/2];
                    a0 = 0.0115; b0 = (23e-3 - a0)/(turns*2*pi-pi/2);
                    r = a0 + b0*theta;                 %   Archimedean spiral
                    x = r.*cos(theta);                 %   first half
                    y = r.*sin(theta);                 %   first half

                    % plot(x, y, '*-'); axis equal; grid on; title('Conductor centerline');
                    % return

                    %   Other parameters
                    a    = 15e-3;       %   z-side, m  (for a rectangle cross-section)
                    b    = 0.2e-3;      %   x-side, m  (for a rectangle cross-section)
                    M    = 20;          %   number of cross-section subdivisions
                    flag = 2;           %   rect. cross-section
                    sk   = 0;           %   Litz wire

                    %   Create CAD and wire models for the single conductor
                    Pcenter(:, 1) = x';
                    Pcenter(:, 2) = y';
                    Pcenter(:, 3) = a/2;
                    strcoil       = meshwire(Pcenter, a, b, M, flag, sk);
                    [P, t]        = meshsurface(Pcenter, a, b, M, flag);  %   CAD mesh (optional, slow)
                    tind          = 1*ones(size(t, 1), 1);

                    Ewire       = [];
                    Pwire       = [];
                    Swire       = [];
                    Pa          = [];
                    ta          = [];

                    %   Construct two CAD and wire models
                    strcoil.Swire       = [strcoil.Swire; strcoil.Swire];
                    strcoil.Ewire       = [strcoil.Ewire; strcoil.Ewire+size(strcoil.Pwire, 1)];
                    Pwire1              = strcoil.Pwire;
                    Pwire2              = strcoil.Pwire;
                    Pwire1(:, 1)        = -Pwire1(:, 1);
                    Pwire1(:, 1)        = Pwire1(:, 1) - 23e-3;
                    Pwire2(:, 1)        = Pwire2(:, 1) + 23e-3;
                    strcoil.Pwire       = [Pwire1; Pwire2];
                    strcoil.Pwire(:, 3) = strcoil.Pwire(:, 3) - min(strcoil.Pwire(:, 3));

                    t          = [t; t+size(P, 1)];
                    tind       = [tind; 2*tind];
                    P1         = P;
                    P2         = P;
                    P1(:, 1)   = -P1(:, 1);
                    P1(:, 1)   = P1(:, 1) - 23e-3;
                    P2(:, 1)   = P2(:, 1) + 23e-3;
                    P          = [P1; P2];
                    P(:, 3)    = P(:, 3) - min(P(:, 3));

                case 'MagVenture_D_B80'
                    %   The coil is in the form of two interconnected spiral arms. The
                    %   conductor centerline model is given first

                    %%   First arm
                    theta = [8*pi/2:pi/50:12*pi];
                    a0 = 0.024; b0 = 0.00061;
                    r = a0 + b0*theta;                  %   Archimedean spiral
                    x1 = r.*cos(theta);                 %   first half
                    y1 = r.*sin(theta);                 %   first half
                    x2 = 2*x1(end) - x1(end-1:-1:1);    %   second half
                    y2 = 2*y1(end) - y1(end-1:-1:1);    %   second half
                    x = [x1 x2];  y = [y1 y2];          %   join both halves
                    x = x - mean(x);                    %   center the curve

                    % plot(x, y, '*-'); axis equal; grid on; title('Conductor centerline')

                    %   Other parameters
                    a    = 6.0e-3;     %   z-side, m  (for a rectangle cross-section)
                    b    = 2.0e-3;     %   x-side, m  (for a rectangle cross-section)
                    M    = 20;          %   number of cross-section subdivisions
                    flag = 2;           %   rect. cross-section
                    sk   = 1;           %   surface current distribution (skin layer)

                    %   Create CAD and wire models for the single conductor
                    Pcenter(:, 1) = x';
                    Pcenter(:, 2) = y';
                    Pcenter(:, 3) = -a/2;
                    strcoil1       = meshwire(Pcenter, a, b, M, flag, sk);
                    [P1, t1]        = meshsurface(Pcenter, a, b, M, flag);  %   CAD mesh (optional, slow)
                    tind1          = 1*ones(size(t1, 1), 1);

                    %%   Second arm
                    theta = [8*pi/2:pi/50:10*pi];
                    a0 = 0.027; b0 = 0.00061;
                    r = a0 + b0*theta;                  %   Archimedean spiral
                    x1 = r.*cos(theta);                 %   first half
                    y1 = r.*sin(theta);                 %   first half
                    x2 = 2*x1(end) - x1(end-1:-1:1);    %   second half
                    y2 = 2*y1(end) - y1(end-1:-1:1);    %   second half
                    x = [x1 x2];  y = [y1 y2];          %   join both halves
                    x = x - mean(x);                    %   center the curve

                    % plot(x, y, '*-'); axis equal; grid on; title('Conductor centerline')

                    %   Other parameters
                    a    = 6.0e-3;     %   z-side, m  (for a rectangle cross-section)
                    b    = 2.0e-3;     %   x-side, m  (for a rectangle cross-section)
                    M    = 20;          %   number of cross-section subdivisions
                    flag = 2;           %   rect. cross-section
                    sk   = 1;           %   surface current distribution (skin layer)

                    %   Create CAD and wire models for the single conductor
                    clear Pcenter;
                    Pcenter(:, 1) = x';
                    Pcenter(:, 2) = -y';
                    Pcenter(:, 3) = +a/2;
                    strcoil2       = meshwire(Pcenter, a, b, M, flag, sk);
                    [P2, t2]      = meshsurface(Pcenter, a, b, M, flag);  %   CAD mesh (optional, slow)
                    tind2         = 2*ones(size(t2, 1), 1);

                    %%  Create the complete model
                    Ewire       = [];
                    Pwire       = [];
                    Swire       = [];
                    Pa          = [];
                    ta          = [];

                    %   Construct two CAD and wire models
                    strcoil.Swire       = [strcoil1.Swire; -strcoil2.Swire];
                    strcoil.Ewire       = [strcoil1.Ewire; strcoil2.Ewire+size(strcoil1.Pwire, 1)];
                    strcoil.Pwire       = [strcoil1.Pwire; strcoil2.Pwire];
                    strcoil.Pwire(:, 3) = strcoil.Pwire(:, 3) - min(strcoil.Pwire(:, 3));

                    t          = [t1; t2+size(P1, 1)];
                    tind       = [tind1; tind2];
                    P          = [P1; P2];
                    P(:, 3)    = P(:, 3) - min(P(:, 3));

                    %   Deform the entire structure
                    alpha = pi/6;
                    strcoil.Pwire(:, 3) = strcoil.Pwire(:, 3) - sin(alpha)*abs(strcoil.Pwire(:, 1));
                    strcoil.Pwire(:, 1) =                       cos(alpha)*strcoil.Pwire(:, 1);
                    P(:, 3)             = P(:, 3) - sin(alpha)*abs(P(:, 1));
                    P(:, 1)             =           cos(alpha)*P(:, 1);

                case 'MagVenture_C_B60'
                    %   The coil is in the form of two interconnected spiral arms. The
                    %   conductor centerline model is given first
                    theta = [3*pi/2:pi/25:12*pi];
                    a0 = 0.017; b0 = 0.0006;
                    r = a0 + b0*theta;                  %   Archimedean spiral
                    x1 = r.*cos(theta);                 %   first half
                    y1 = r.*sin(theta);                 %   first half
                    x2 = 2*x1(end) - x1(end-1:-1:1);    %   second half
                    y2 = 2*y1(end) - y1(end-1:-1:1);    %   second half
                    x = [x1 x2];  y = [y1 y2];          %   join both halves
                    x = x - mean(x);                    %   center the curve
                    P(:, 1) = x; P(:, 2) = y; P(:, 3) = 0;
                    P = meshrotate2(P, [0 0 1], +0.04);
                    x = P(:, 1); y = P(:, 2);

                    %plot(x, y, '*-'); axis equal; grid on; title('Conductor centerline')

                    %   Other parameters
                    a    = 3.60e-3;     %   z-side, m  (for a rectangle cross-section)
                    b    = 2.61e-3;     %   x-side, m  (for a rectangle cross-section)
                    M    = 20;          %   number of cross-section subdivisions
                    flag = 2;           %   rect. cross-section
                    sk   = 1;           %   surface current distribution (skin layer)

                    %   Create CAD and wire models for the single conductor
                    Pcenter(:, 1) = x';
                    Pcenter(:, 2) = y';
                    Pcenter(:, 3) = a/2;
                    strcoil       = meshwire(Pcenter, a, b, M, flag, sk);
                    [P, t]        = meshsurface(Pcenter, a, b, M, flag);  %   CAD mesh (optional, slow)
                    tind          = 1*ones(size(t, 1), 1);

                    Ewire       = [];
                    Pwire       = [];
                    Swire       = [];
                    Pa          = [];
                    ta          = [];

                    %   Construct two CAD and wire models
                    strcoil.Swire       = [strcoil.Swire; strcoil.Swire];
                    strcoil.Ewire       = [strcoil.Ewire; strcoil.Ewire+size(strcoil.Pwire, 1)];
                    Pwire               = strcoil.Pwire;
                    Pwire(:, 3)         = Pwire(:, 3) + 9.2e-3;
                    strcoil.Pwire       = [strcoil.Pwire; Pwire];
                    strcoil.Pwire(:, 3) = strcoil.Pwire(:, 3) - min(strcoil.Pwire(:, 3));

                    t          = [t; t+size(P, 1)];
                    tind       = [tind; 2*tind];
                    Pup        = P;
                    Pup(:, 3)  = Pup(:, 3) + 9.2e-3;
                    P          = [P; Pup];
                    P(:, 3)    = P(:, 3) - min(P(:, 3));
                case 'FigureEight'
                    %   The coil includes one single conductor in the form of two
                    %   interconnected spiral arms. The conductor centerline model is given
                    %   first
                    theta = [0:pi/25:10*pi];
                    a0 = 0.03; b0 = 0.0006;
                    r = a0 + b0*theta;                  %   Archimedean spiral
                    x1 = r.*cos(theta);                 %   first half
                    y1 = r.*sin(theta);                 %   first half
                    x2 = 2*x1(end) - x1(end-1:-1:1);    %   second half
                    y2 = 2*y1(end) - y1(end-1:-1:1);    %   second half
                    x = [x1 x2];  y = [y1 y2];          %   join both halves
                    x = x - mean(x);                    %   center the curve

                    %   Other parameters
                    a    = 0.002;       %   conductor diameter
                    M    = 16;          %   number of cross-section subdivisions
                    flag = 1;           %   circular cross-section
                    sk   = 1;           %   surface current distribution (skin layer)

                    %   Create CAD and wire models for the single conductor
                    Pcenter(:, 1) = x';
                    Pcenter(:, 2) = y';
                    Pcenter(:, 3) = a/2;
                    strcoil       = meshwire(Pcenter, a, a, M, flag, sk); % Wire model
                    [P, t]        = meshsurface(Pcenter, a, a, M, flag);  % CAD mesh (optional, slow)
                    tind          = ones(size(t, 1), 1);

                case 'SingleRung'
                    %   The coil includes one single conductor in the form of a straight segment.
                    %   The conductor centerline model is given first
                    par = linspace(0, 1, 100);
                    L   = 0.02;                         %   conductor length in m
                    x = L*par;                          %   segment
                    y = 0*par;                          %   segment

                    %   Other parameters
                    a    = 0.002;       %   conductor diameter in m
                    M    = 16;          %   number of cross-section subdivisions
                    flag = 1;           %   circular cross-section
                    sk   = 1;           %   surface current distribution (skin layer)

                    %   Create CAD and wire models for the single conductor
                    Pcenter(:, 1) = x';
                    Pcenter(:, 2) = y';
                    Pcenter(:, 3) = a/2;
                    strcoil       = meshwire(Pcenter, a, a, M, flag, sk); % Wire model
                    [P, t]        = meshsurface(Pcenter, a, a, M, flag);  % CAD mesh (optional, slow)
                    tind          = ones(size(t, 1), 1);

                case 'SingleRing'
                    %   The coil includes one single conductor in the form of a ring.
                    %   The conductor centerline model is given first
                    theta = [0:pi/50:2*pi];
                    r = 0.02;                          %   ring radius in m
                    x = r.*cos(theta);                 %   ring
                    y = r.*sin(theta);                 %   ring

                    %   Other parameters
                    a    = 0.002;       %   conductor diameter in m
                    M    = 16;          %   number of cross-section subdivisions
                    flag = 1;           %   circular cross-section
                    sk   = 1;           %   surface current distribution (skin layer)

                    %   Create CAD and wire models for the single conductor
                    Pcenter(:, 1) = x';
                    Pcenter(:, 2) = y';
                    Pcenter(:, 3) = a/2;
                    strcoil       = meshwire(Pcenter, a, a, M, flag, sk); % Wire model
                    [P, t]        = meshsurface(Pcenter, a, a, M, flag);  % CAD mesh (optional, slow)
                    tind          = ones(size(t, 1), 1);

            end;

            fprintf('TMS coil model [%s] created. Saving [coil.mat] and [coilCAD.mat]...\n',app.TMSCoilDropDown.Value);
            app.TextArea.Value{end+1}=sprintf('TMS coil model [%s] created. Saving [coil.mat] and [coilCAD.mat]...\n',app.TMSCoilDropDown.Value);
            
            save('coil', 'strcoil');
            save('coilCAD', 'P', 't', 'tind');  %   optional, slow
        end

        % Cell edit callback: UITrgTable
        function UITrgTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITrgTable
        function UITrgTableCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Display data changed function: UITrgTable
        function UITrgTableDisplayDataChanged(app, event)
            newDisplayData = app.UITrgTable.DisplayData;
            
        end

        % Value changed function: TMSCoilSizeDropDown
        function TMSCoilSizeDropDownValueChanged(app, event)
            value = app.TMSCoilSizeDropDown.Value;

            size_orig=app.tmscoilsize;
            switch value
                case '100%'
                    app.tmscoilsize=1;
                case '50%'
                    app.tmscoilsize=.5;
                case '20%'
                    app.tmscoilsize=.2;
                case '10%'
                    app.tmscoilsize=.1;
            end;

            global etc_render_fsbrain;
            try
            if(isfield(etc_render_fsbrain,'object'))
                        Vtmp=etc_render_fsbrain.object.Vertices;
                        Vtmp=Vtmp-repmat(etc_render_fsbrain.object.UserData.Origin,[size(Vtmp,1),1]);
                        Vtmp=Vtmp./size_orig.*app.tmscoilsize;
                        Vtmp=Vtmp+repmat(etc_render_fsbrain.object.UserData.Origin,[size(Vtmp,1),1]);
                        
                        etc_render_fsbrain.object.Vertices=Vtmp;

            end;
            catch
                error('something is wrong in setting the TMS coil object scale....\n');
            end;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TMSNavFigure = uifigure('Visible', 'off');
            app.TMSNavFigure.Name = 'TMS coil navigation';
            app.TMSNavFigure.CloseRequestFcn = createCallbackFcn(app, @TMSNavFigureCloseRequest, true);
            app.HeadbrainboundariesPanel = uipanel(app.TMSNavFigure);
            app.HeadbrainboundariesPanel.Title = 'Head/brain boundaries';
            app.ShowscalpCheckBox = uicheckbox(app.HeadbrainboundariesPanel);
            app.ShowscalpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowscalpCheckBoxValueChanged, true);
            app.ShowscalpCheckBox.Text = '';
            app.surfaceDropDownLabel = uilabel(app.HeadbrainboundariesPanel);
            app.surfaceDropDownLabel.Text = 'surface';
            app.SurfaceDropDown = uidropdown(app.HeadbrainboundariesPanel);
            app.SurfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceDropDownValueChanged, true);
            app.SButton = uibutton(app.HeadbrainboundariesPanel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.TMStargetPanel = uipanel(app.TMSNavFigure);
            app.TMStargetPanel.Title = 'TMS target';
            app.GoButton = uibutton(app.TMStargetPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.MNIEditField_2Label = uilabel(app.TMStargetPanel);
            app.MNIEditField_2Label.Text = 'MNI';
            app.MNIEditField = uieditfield(app.TMStargetPanel, 'text');
            app.MNIEditField.ValueChangedFcn = createCallbackFcn(app, @MNIEditFieldValueChanged, true);
            app.CRSEditFieldLabel = uilabel(app.TMStargetPanel);
            app.CRSEditFieldLabel.Text = 'CRS';
            app.CRSEditField = uieditfield(app.TMStargetPanel, 'text');
            app.CRSEditField.ValueChangedFcn = createCallbackFcn(app, @CRSEditFieldValueChanged, true);
            app.XYZEditFieldLabel = uilabel(app.TMStargetPanel);
            app.XYZEditFieldLabel.Text = 'XYZ';
            app.XYZEditField = uieditfield(app.TMStargetPanel, 'text');
            app.XYZEditField.ValueChangedFcn = createCallbackFcn(app, @XYZEditFieldValueChanged, true);
            app.vertexindexEditFieldLabel = uilabel(app.TMStargetPanel);
            app.vertexindexEditFieldLabel.Text = 'vertex index';
            app.vertexindexEditField = uieditfield(app.TMStargetPanel, 'text');
            app.vertexindexEditField.ValueChangedFcn = createCallbackFcn(app, @vertexindexEditFieldValueChanged, true);
            app.tiltvertdegSliderLabel = uilabel(app.TMStargetPanel);
            app.tiltvertdegSliderLabel.Text = {'tilt vert.'; '(deg)'};
            app.tiltvertdegSlider = uislider(app.TMStargetPanel);
            app.tiltvertdegSlider.Limits = [-30 30];
            app.tiltvertdegSlider.ValueChangedFcn = createCallbackFcn(app, @tiltvertdegSliderValueChanged, true);
            app.tilthorizdegSliderLabel = uilabel(app.TMStargetPanel);
            app.tilthorizdegSliderLabel.Text = {'tilt horiz.'; '(deg)'};
            app.tilthorizdegSlider = uislider(app.TMStargetPanel);
            app.tilthorizdegSlider.Limits = [-30 30];
            app.tilthorizdegSlider.ValueChangedFcn = createCallbackFcn(app, @tilthorizdegSliderValueChanged, true);
            app.offsetmmSliderLabel = uilabel(app.TMStargetPanel);
            app.offsetmmSliderLabel.Text = {'offset'; '(mm)'};
            app.offsetmmSlider = uislider(app.TMStargetPanel);
            app.offsetmmSlider.Limits = [-40 40];
            app.offsetmmSlider.ValueChangedFcn = createCallbackFcn(app, @offsetmmSliderValueChanged, true);
            app.rotatedegSliderLabel = uilabel(app.TMStargetPanel);
            app.rotatedegSliderLabel.Text = {'rotate'; '(deg)'};
            app.rotatedegSlider = uislider(app.TMStargetPanel);
            app.rotatedegSlider.Limits = [-180 180];
            app.rotatedegSlider.ValueChangedFcn = createCallbackFcn(app, @rotatedegSliderValueChanged, true);
            app.vertdegEditField = uieditfield(app.TMStargetPanel, 'numeric');
            app.vertdegEditField.ValueChangedFcn = createCallbackFcn(app, @vertdegEditFieldValueChanged, true);
            app.horizdegEditField = uieditfield(app.TMStargetPanel, 'numeric');
            app.horizdegEditField.ValueChangedFcn = createCallbackFcn(app, @horizdegEditFieldValueChanged, true);
            app.offsetmmEditField = uieditfield(app.TMStargetPanel, 'numeric');
            app.offsetmmEditField.ValueChangedFcn = createCallbackFcn(app, @offsetmmEditFieldValueChanged, true);
            app.rotatedegEditField = uieditfield(app.TMStargetPanel, 'numeric');
            app.rotatedegEditField.ValueChangedFcn = createCallbackFcn(app, @rotatedegEditFieldValueChanged, true);
            app.LoadxfmButton = uibutton(app.TMStargetPanel, 'push');
            app.LoadxfmButton.ButtonPushedFcn = createCallbackFcn(app, @LoadxfmButtonPushed, true);
            app.LoadxfmButton.Text = 'Load xfm';
            app.MNIEditField_2Label_2 = uilabel(app.TMStargetPanel);
            app.MNIEditField_2Label_2.Text = 'Scanner';
            app.ScannerEditField = uieditfield(app.TMStargetPanel, 'text');
            app.ScannerEditField.ValueChangedFcn = createCallbackFcn(app, @ScannerEditFieldValueChanged, true);
            app.UITrgTable = uitable(app.TMStargetPanel);
            app.UITrgTable.ColumnName = {'Target name'; 'Scanner coord.'; 'Orientations'; 'Tune'};
            app.UITrgTable.RowName = {};
            app.UITrgTable.ColumnSortable = [true false false];
            app.UITrgTable.ColumnEditable = [true false false];
            app.UITrgTable.CellEditCallback = createCallbackFcn(app, @UITrgTableCellEdit, true);
            app.UITrgTable.CellSelectionCallback = createCallbackFcn(app, @UITrgTableCellSelection, true);
            app.UITrgTable.DisplayDataChangedFcn = createCallbackFcn(app, @UITrgTableDisplayDataChanged, true);
            app.UITrgTable.SelectionChangedFcn = createCallbackFcn(app, @UITrgTableSelectionChanged, true);
            app.UITrgTable.KeyPressFcn = createCallbackFcn(app, @UITrgTableKeyPress, true);
            app.TrgAddButton = uibutton(app.TMStargetPanel, 'push');
            app.TrgAddButton.ButtonPushedFcn = createCallbackFcn(app, @TrgAddButtonPushed, true);
            app.TrgAddButton.Text = '+';
            app.TrgDelButton = uibutton(app.TMStargetPanel, 'push');
            app.TrgDelButton.ButtonPushedFcn = createCallbackFcn(app, @TrgDelButtonPushed, true);
            app.TrgDelButton.Text = '-';
            app.BrainsightTrgImportButton = uibutton(app.TMStargetPanel, 'push');
            app.BrainsightTrgImportButton.ButtonPushedFcn = createCallbackFcn(app, @BrainsightTrgImportButtonPushed, true);
            app.BrainsightTrgImportButton.Text = 'From Brainsight';
            app.TargetlistLabel = uilabel(app.TMStargetPanel);
            app.TargetlistLabel.Text = 'Target list';
            app.ExportButton = uibutton(app.TMSNavFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.TMSNavFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.TextArea = uitextarea(app.TMSNavFigure);
            app.HeadmodelPanel = uipanel(app.TMSNavFigure);
            app.HeadmodelPanel.Title = 'Head model';
            app.PrepareheadmodelButton = uibutton(app.HeadmodelPanel, 'push');
            app.PrepareheadmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareheadmodelButtonPushed, true);
            app.PrepareheadmodelButton.Text = 'Prepare head model';
            app.PrepModelLamp = uilamp(app.HeadmodelPanel);
            app.DefineheadmodelButton = uibutton(app.HeadmodelPanel, 'push');
            app.DefineheadmodelButton.ButtonPushedFcn = createCallbackFcn(app, @DefineheadmodelButtonPushed, true);
            app.DefineheadmodelButton.Text = 'Define head model';
            app.DefModelLamp = uilamp(app.HeadmodelPanel);
            app.LoadpreparedmodelButton = uibutton(app.HeadmodelPanel, 'push');
            app.LoadpreparedmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpreparedmodelButtonPushed, true);
            app.LoadpreparedmodelButton.Text = 'Load prepared model';
            app.TMScoilPanel = uipanel(app.TMSNavFigure);
            app.TMScoilPanel.Title = 'TMS coil';
            app.LoadcoilButton = uibutton(app.TMScoilPanel, 'push');
            app.LoadcoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcoilButtonPushed, true);
            app.LoadcoilButton.Text = 'Load coil';
            app.xButton = uibutton(app.TMScoilPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.CoilLamp = uilamp(app.TMScoilPanel);
            app.VecNormCheckBox = uicheckbox(app.TMScoilPanel);
            app.VecNormCheckBox.ValueChangedFcn = createCallbackFcn(app, @VecNormCheckBoxValueChanged, true);
            app.VecNormCheckBox.Text = 'norm.';
            app.VecUpCheckBox = uicheckbox(app.TMScoilPanel);
            app.VecUpCheckBox.ValueChangedFcn = createCallbackFcn(app, @VecUpCheckBoxValueChanged, true);
            app.VecUpCheckBox.Text = 'up';
            app.CoilShowCheckBox = uicheckbox(app.TMScoilPanel);
            app.CoilShowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilShowCheckBoxValueChanged, true);
            app.CoilShowCheckBox.Text = '';
            app.LoadstrcoilButton = uibutton(app.TMScoilPanel, 'push');
            app.LoadstrcoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadstrcoilButtonPushed, true);
            app.LoadstrcoilButton.Text = 'Load strcoil';
            app.StrcoilLamp = uilamp(app.TMScoilPanel);
            app.StrcoilShowCheckBox = uicheckbox(app.TMScoilPanel);
            app.StrcoilShowCheckBox.ValueChangedFcn = createCallbackFcn(app, @StrcoilShowCheckBoxValueChanged, true);
            app.StrcoilShowCheckBox.Text = '';
            app.UpdatestrcoilButton = uibutton(app.TMScoilPanel, 'push');
            app.UpdatestrcoilButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatestrcoilButtonPushed, true);
            app.UpdatestrcoilButton.Text = 'U';
            app.StrcoilAutoCheckBox = uicheckbox(app.TMScoilPanel);
            app.StrcoilAutoCheckBox.Text = 'AT';
            app.CreateButton = uibutton(app.TMScoilPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.TMSCoilDropDown = uidropdown(app.TMScoilPanel);
            app.TMSCoilDropDown.Items = {'SingleRing', 'SingleRung', 'FigureEight', 'MagVenture_C_B60', 'MagVenture_D_B80', 'MagVenture_Cool_B35', 'MagVenture_MRiB91'};
            app.TMSCoilDropDown.Value = 'MagVenture_MRiB91';
            app.sizeDropDownLabel = uilabel(app.TMScoilPanel);
            app.sizeDropDownLabel.Text = 'size';
            app.TMSCoilSizeDropDown = uidropdown(app.TMScoilPanel);
            app.TMSCoilSizeDropDown.Items = {'100%', '50%', '20%', '10%'};
            app.TMSCoilSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @TMSCoilSizeDropDownValueChanged, true);
            app.TMSCoilSizeDropDown.Value = '100%';
            app.EfieldPanel = uipanel(app.TMSNavFigure);
            app.EfieldPanel.Title = 'E-field';
            app.CalcButton = uibutton(app.EfieldPanel, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calc.';
            app.EfieldCalclLamp = uilamp(app.EfieldPanel);
            app.EfieldShowCheckBox = uicheckbox(app.EfieldPanel);
            app.EfieldShowCheckBox.ValueChangedFcn = createCallbackFcn(app, @EfieldShowCheckBoxValueChanged, true);
            app.EfieldShowCheckBox.Text = '';
            app.EcompLabel = uilabel(app.EfieldPanel);
            app.EcompLabel.Text = 'E-comp.';
            app.EfieldDropDown = uidropdown(app.EfieldPanel);
            app.EfieldDropDown.Items = {'Total', 'Normal', 'Tangent'};
            app.EfieldDropDown.ValueChangedFcn = createCallbackFcn(app, @EfieldDropDownValueChanged, true);
            app.EfieldDropDown.Value = 'Total';
            app.BrainsightTrgExportButton = uibutton(app.TMSNavFigure, 'push');
            app.BrainsightTrgExportButton.ButtonPushedFcn = createCallbackFcn(app, @BrainsightTrgExportButtonPushed, true);
            app.BrainsightTrgExportButton.Text = 'To Brainsight';
            % Show the figure after all components are created
            app.TMSNavFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_tms_nav

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TMSNavFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TMSNavFigure)
        end
    end
end