classdef etc_render_fsbrain_tms_tissue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TMSheadmodeltissuesetupUIFigure  matlab.ui.Figure
        LoadButton                    matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        TissueGeometryLabel           matlab.ui.control.Label
        ChoosesurfaceButton           matlab.ui.control.Button
        LoadMATButton                 matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        CreateButton                  matlab.ui.control.Button
        EnclosingTissueDropDown       matlab.ui.control.DropDown
        EnclosingTissueDropDownLabel  matlab.ui.control.Label
        CondEditField                 matlab.ui.control.EditField
        CondEditFieldLabel            matlab.ui.control.Label
        TissueEditField               matlab.ui.control.EditField
        TissueEditFieldLabel          matlab.ui.control.Label
        commonconductivitySmTextArea  matlab.ui.control.TextArea
        commonconductivitySmTextAreaLabel  matlab.ui.control.Label
        AddButton                     matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = public)
        enclosing_tissue_list=categorical({'FreeSpace'});
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_render_fsbrain;


            app.EnclosingTissueDropDown.Items=app.enclosing_tissue_list;
            app.EnclosingTissueDropDown.Value=app.enclosing_tissue_list(1);


            if(isfield(etc_render_fsbrain,'tissue_obj'))
                tissue_name={};
                tissue_mat={};
                tissue_cond={};
                tissue_enclosing_tissue={};
                for tissue_idx=1:length(etc_render_fsbrain.tissue_obj)
                    tissue_name{tissue_idx,1}=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_name;
                    tissue_mat{tissue_idx,1}=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_mat;
                    tissue_cond{tissue_idx,1}=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_cond;

                    [a,idx]=ismember(app.enclosing_tissue_list,etc_render_fsbrain.tissue_obj(tissue_idx).tissue_enclosing_tissue);
                    if(isempty(find(idx)))
                        app.enclosing_tissue_list(end+1)=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_enclosing_tissue;
                    end;

                    [a,idx]=ismember(app.enclosing_tissue_list,etc_render_fsbrain.tissue_obj(tissue_idx).tissue_enclosing_tissue);
                    tissue_enclosing_tissue{tissue_idx,1}=app.enclosing_tissue_list(find(idx));
                end;
                t = table(tissue_name,tissue_mat,tissue_cond,tissue_enclosing_tissue);

                %t.flag_show_surf = categorical(t.flag_show_surf,{'On','Off'},'Ordinal',true);
                app.UITable.Data=t;
            end;

            app.UITable.ColumnName={'Tissue','Geometry MAT','Cond.','Enclosing tissue'};

            app.UITable.ColumnEditable = [false false true false]; %only conductivity can be changed afterward
            if(isfield(etc_render_fsbrain,'tissue_obj'))
                for tissue_idx=1:length(etc_render_fsbrain.tissue_obj)
                    %s = uistyle('BackgroundColor',etc_render_fsbrain.surf_obj(surf_idx).color);
                    %addStyle(app.UITable,s,'cell',[surf_idx,4]);
                end;
            end;

            app.EnclosingTissueDropDown.Items=app.enclosing_tissue_list;
            app.EnclosingTissueDropDown.Value=app.enclosing_tissue_list(1);


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            global etc_render_fsbrain;


            %make sure a geometry has been set properly (mat file or from
            %surface object)
            if(strcmp(app.TissueGeometryLabel.Text,'<unspecified>'))
                return;
            end;

            %make sure no duplicates
            if(isfield(etc_render_fsbrain,'tissue_obj'))
                mat_files={};
                for tissue_idx=1:length(etc_render_fsbrain.tissue_obj)
                     mat_files{tissue_idx}=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_mat;
                end;
                if(ismember(app.LoadMATButton.UserData,mat_files)) 
                %if(ismember(app.TissueGeometryLabel.UserData,mat_files)) 
                    f = msgbox('Duplicated MAT file!')
                    return; 
                end;

                tn={};
                for tissue_idx=1:length(etc_render_fsbrain.tissue_obj)
                     tn{tissue_idx}=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_name;
                end;
                if(ismember(app.TissueEditField.Value,tn)) 
                    f = msgbox('Duplicated tissue name!')
                    return; 
                end;
            
            end;

            try
                data=load(app.LoadMATButton.UserData);
                %data=load(app.TissueGeometryLabel.UserData);
            catch
                return;
            end;


            if(~isfield(etc_render_fsbrain,'tissue_obj')) etc_render_fsbrain.tissue_obj=[]; end;
            etc_render_fsbrain.tissue_obj(end+1).tissue_name=app.TissueEditField.Value;
            etc_render_fsbrain.tissue_obj(end).tissue_mat=app.LoadMATButton.UserData;
            %etc_render_fsbrain.tissue_obj(end).tissue_mat=app.TissueGeometryLabel.UserData;
            etc_render_fsbrain.tissue_obj(end).tissue_cond=str2num(app.CondEditField.Value);
            etc_render_fsbrain.tissue_obj(end).tissue_enclosing_tissue=app.EnclosingTissueDropDown.Value;


            tissue_name=[];
            tissue_mat=[];
            tissue_cond=[];
            tissue_enclosing_tissue=[];

            tissue_name{1,1}=etc_render_fsbrain.tissue_obj(end).tissue_name;
            [dummy,fstem]=fileparts(etc_render_fsbrain.tissue_obj(end).tissue_mat);
            tissue_mat{1,1}=fstem;
            tissue_cond{1,1}=etc_render_fsbrain.tissue_obj(end).tissue_cond;
            [a,idx]=ismember(etc_render_fsbrain.tissue_obj(end).tissue_enclosing_tissue,app.enclosing_tissue_list);
            tissue_enclosing_tissue{1,1}=app.enclosing_tissue_list(idx);
            t = table(tissue_name,tissue_mat,tissue_cond,tissue_enclosing_tissue);

            if(isempty(app.UITable.Data))
                app.UITable.Data=t;
            else
                app.UITable.Data = [app.UITable.Data;t];
            end;     

            %new boundary
            [a,idx]=ismember(app.enclosing_tissue_list,tissue_name);
            if(isempty(find(idx)))
                app.enclosing_tissue_list(end+1)=tissue_name;
                app.EnclosingTissueDropDown.Items=app.enclosing_tissue_list;
            end;

            app.TissueGeometryLabel.Text='<unspecified>';


%             %surface color 
%             s = uistyle('BackgroundColor',cc(cc_idx,:));
%             addStyle(app.UITable,s,'cell',[size(app.UITable.Data,1),4]);
% 
%             %update brain surface and volume figures;
%             etc_render_fsbrain_handle('draw_pointer');
% 
%             %update tms_nav figure;
%             if(isfield(etc_render_fsbrain,'app_tms_nav'))
%                 if(~isempty(etc_render_fsbrain.app_tms_nav))
%                     if(isvalid(etc_render_fsbrain.app_tms_nav))
%                         etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.SurfaceDropDown));
%                     end;
%                 end;
%             end;
            
        end

        % Button pushed function: LoadMATButton
        function LoadMATButtonPushed(app, event)
            [filename, pathname, filterindex] = uigetfile({'*.mat','MAT file'});
            if(filename==0) return; end;

            global etc_render_fsbrain;

            %make sure no duplicates
            if(isfield(etc_render_fsbrain,'tissue_obj'))
                mat_files={};
                for tissue_idx=1:length(etc_render_fsbrain.tissue_obj)
                     mat_files{tissue_idx}=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_mat;
                end;
                if(ismember(filename,mat_files)) return; end;
             end;

            %app.LoadMATButton.Text=filename;
            app.TissueGeometryLabel.Text=filename;

            %app.LoadMATButton.UserData=sprintf('%s%s',pathname,filename);
            
            %etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.DefModelLamp),'g','r');

        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            key = event.Key;

            switch key
                case {'delete','backspace'}
                    global etc_render_fsbrain;
                    
                    if(~isempty(event.Source.Selection))
                        etc_render_fsbrain.tissue_obj(event.Source.Selection(1))=[];
                        app.enclosing_tissue_list=setdiff(app.enclosing_tissue_list, app.UITable.Data{event.Source.Selection(1),1}{1});
                        app.UITable.Data(event.Source.Selection(1),:)=[];
                    end;
            end;

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
            global etc_render_fsbrain;

            if(~isempty(selection))

                app.TissueEditField.Value=etc_render_fsbrain.tissue_obj(selection(1)).tissue_name;
                [dummy,fstem]=fileparts(etc_render_fsbrain.tissue_obj(selection(1)).tissue_mat);
                app.LoadMATButton.Text=fstem;
                app.CondEditField.Value=num2str(etc_render_fsbrain.tissue_obj(selection(1)).tissue_cond);
                [a,idx]=ismember(etc_render_fsbrain.tissue_obj(selection(1)).tissue_enclosing_tissue,app.enclosing_tissue_list);
                app.EnclosingTissueDropDown.Value=string(app.enclosing_tissue_list(idx));


                if(selection(2)==4) %enclosing tissue
                    app.EnclosingTissueDropDown.Items=app.enclosing_tissue_list;


                    [a,idx]=ismember(etc_render_fsbrain.tissue_obj(selection(1)).tissue_enclosing_tissue,app.enclosing_tissue_list);
                    etc_render_fsbrain.tissue_obj(selection(1)).tissue_enclosing_tissue=app.enclosing_tissue_list(idx);
                    tissue_enclosing_tissue{1,1}=app.enclosing_tissue_list(idx);
                    t=table(tissue_enclosing_tissue);
                    app.UITable.Data(selection(1),selection(2))=t;

                    
                    %                    c = uisetcolor;
%                    if(length(c)>1)
%                        s = uistyle('BackgroundColor',c);
%                        addStyle(app.UITable,s,'cell',selection);
%                        global etc_render_fsbrain;
%                        etc_render_fsbrain.surf_obj(selection(1)).color=c;
%                        etc_render_fsbrain_handle('draw_pointer');
%                    end;
                end;
            end;

        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            global etc_render_fsbrain;

            try
                [file,path,indx] = uiputfile('tissue_index.txt');
                fn=sprintf('%s%s',path,file);
                if(file==0) return; end;

                fp=fopen(fn,'w');

                for tissue_idx=1:length(etc_render_fsbrain.tissue_obj)
                    tn=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_name;
                    [filepath,fstem,ext]=fileparts(etc_render_fsbrain.tissue_obj(tissue_idx).tissue_mat);
                    ff=sprintf('%s%s',fstem,ext);
                    cond=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_cond;
                    tbt=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_enclosing_tissue;

                    fprintf(fp,'>%s : %s : %1.4f : %s \n',tn,ff,cond,tbt);
    
                    app.TextArea.Value{end+1}=sprintf('>%s : %s : %1.4f : %s \n',tn,ff,cond,tbt);
                end;

                fclose(fp);

                etc_render_fsbrain.tissue_def_file=sprintf('%s%s',path,file);


            catch
                 fprintf('error in writing tissue file!\n');

            end;
        end

        % Callback function
        function SurfaceButtonPushed(app, event)
            global etc_render_fsbrain;

            if(isfield(etc_render_fsbrain,'fig_brain'))
                etc_render_fsbrain_handle('kb','cc','S');
            else
                return;
            end;
        end

        % Callback function
        function BoundarygeometryButtonGroupSelectionChanged(app, event)
%             selectedButton = app.BoundarygeometryButtonGroup.SelectedObject;
%             if app.LoadMATButton.Value==1
% 
%                 [filename, pathname, filterindex] = uigetfile({'*.mat','MAT file'});
%                 if(filename==0) return; end;
% 
%                 global etc_render_fsbrain;
% 
%                 %make sure no duplicates
%                 if(isfield(etc_render_fsbrain,'tissue_obj'))
%                     mat_files={};
%                     for tissue_idx=1:length(etc_render_fsbrain.tissue_obj)
%                         mat_files{tissue_idx}=etc_render_fsbrain.tissue_obj(tissue_idx).tissue_mat;
%                     end;
%                     if(ismember(filename,mat_files)) return; end;
%                 end;
% 
%                 app.TissueGeometryLabel.Text=filename;
% 
%                 app.TissueGeometryLabel.UserData=sprintf('%s%s',pathname,filename);
% 
%             elseif app.CreatefromsurfaceButton.Value==1
%                 global etc_render_fsbrain;
% 
% 
%             end;
        end

        % Button pushed function: ChoosesurfaceButton
        function ChoosesurfaceButtonPushed(app, event)
            
            global etc_render_fsbrain;

            f_idx=etc_render_fsbrain_choose_surf_dialog;

            app.TissueGeometryLabel.Text='<unspecified>';

            if(~isempty(f_idx))
                [dummy,fstem]=fileparts(etc_render_fsbrain.surf_obj(f_idx).filename);
                app.TissueGeometryLabel.Text=fstem;


                    %save files for e-field modeling
                    P=etc_render_fsbrain.surf_obj(f_idx).vertex;
                    t=etc_render_fsbrain.surf_obj(f_idx).face+1;
                    normals=etc_render_fsbrain.surf_obj(f_idx).surf_norm;

                    fn=sprintf('%s_%s.mat',etc_render_fsbrain.subject,fstem);
                    save(fn,'P','t','normals');

                    fprintf('[%s] saved!',fn);
                    app.TextArea.Value{end+1}=sprintf('[%s] saved!',fn);

                    app.LoadMATButton.UserData=fn;

            end;

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global etc_render_fsbrain;


            [filename, pathname, filterindex] = uigetfile({'*.txt','tissue txt file'});
            if(filename==0) return; end;


            [name, tissue, cond, enclosingTissueIdx, enclosingTissueName] = etc_render_fsbrain_tissue_read(sprintf('%s%s',pathname,filename));

            answer = questdlg('Replace or append?','Loading tissue table ...','Replace','Append','Cancel','Replace');
            tissue_name='';
            tissue_mat='';
            tissue_cond='';
            tissue_enclosing_tissue='';

            etc_render_fsbrain.tissue_def_file=sprintf('%s%s',pathname,filename);
            
            
            switch answer
                case 'Replace'
                    etc_render_fsbrain.tissue_obj=[];
                    app.enclosing_tissue_list=categorical({'FreeSpace'});

                    if(isfield(etc_render_fsbrain,'surf_obj'))
                        for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                            if(isfield(etc_render_fsbrain.surf_obj(surf_idx),'patch_handle'))
                                if(~isempty(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                    if(isvalid(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                        try
                                            delete(etc_render_fsbrain.surf_obj(surf_idx).patch_handle);
                                        catch
                                        end;
                                    end
                                end;
                            end;
                        end;
                        etc_render_fsbrain.surf_obj=[];
                    else
                        etc_render_fsbrain.surf_obj=[]; 
                    end;

                    for tissue_idx=1:length(name)
                        tissue_name{tissue_idx,1}=tissue{tissue_idx};
                        etc_render_fsbrain.tissue_obj(tissue_idx).tissue_name=tissue_name{tissue_idx,1};

                        tissue_mat{tissue_idx,1}=name{tissue_idx};
                        etc_render_fsbrain.tissue_obj(tissue_idx).tissue_mat=tissue_mat{tissue_idx,1};

                        tissue_cond{tissue_idx,1}=cond(tissue_idx);
                        etc_render_fsbrain.tissue_obj(tissue_idx).tissue_cond=tissue_cond{tissue_idx,1};

                        tissue_enclosing_tissue{tissue_idx,1}=enclosingTissueName{tissue_idx};
                        etc_render_fsbrain.tissue_obj(tissue_idx).tissue_enclosing_tissue=tissue_enclosing_tissue{tissue_idx,1};

                        %new boundary
                        [a,idx]=ismember(app.enclosing_tissue_list,tissue_name{tissue_idx,1});
                        if(isempty(find(idx)))
                            app.enclosing_tissue_list(end+1)=tissue_name{tissue_idx,1};
                            app.EnclosingTissueDropDown.Items=app.enclosing_tissue_list;
                        end;

                        %update surf_obj
                        d=load(etc_render_fsbrain.tissue_obj(end).tissue_mat);


                        if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;

                        etc_render_fsbrain.surf_obj(tissue_idx).vertex=d.P;
                        etc_render_fsbrain.surf_obj(tissue_idx).face=d.t;
                        etc_render_fsbrain.surf_obj(tissue_idx).TR=triangulation(d.t,d.P);;
                        etc_render_fsbrain.surf_obj(tissue_idx).surf_center=incenter(etc_render_fsbrain.surf_obj(end).TR);
                        etc_render_fsbrain.surf_obj(tissue_idx).surf_norm=d.normals;
                        [eS, TriPS, TriMS] = mt(etc_render_fsbrain.surf_obj(tissue_idx).TR);
                        etc_render_fsbrain.surf_obj(tissue_idx).eS=eS;
                        etc_render_fsbrain.surf_obj(tissue_idx).TriPS=TriPS;
                        etc_render_fsbrain.surf_obj(tissue_idx).TriMS=TriMS;

                        cc=colororder;
                        cc_idx=length(etc_render_fsbrain.surf_obj);
                        cc_idx=mod(cc_idx+1,size(cc,1));
                        if(cc_idx==0) cc_idx=size(cc,1); end;
                        etc_render_fsbrain.surf_obj(tissue_idx).color=cc(cc_idx,:);
                        etc_render_fsbrain.surf_obj(tissue_idx).flag_show=1;
                        etc_render_fsbrain.surf_obj(tissue_idx).filename=name{tissue_idx};
                        etc_render_fsbrain.surf_obj(tissue_idx).category=tissue{tissue_idx};

                        figure(etc_render_fsbrain.fig_brain);
                        etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',d.P,'faces',d.t,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp

                        axis vis3d tight;

                    end;
                    t = table(tissue_name,tissue_mat,tissue_cond,tissue_enclosing_tissue);

                    app.UITable.Data=t;


                    %update tms_nav figure;
                    if(isfield(etc_render_fsbrain,'app_tms_nav'))
                        if(~isempty(etc_render_fsbrain.app_tms_nav))
                            if(isvalid(etc_render_fsbrain.app_tms_nav))
                                etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.SurfaceDropDown));
                                etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.DefModelLamp),'g','r');

                            end;
                        end;
                    end;

                    
                case 'Append'
                    if(~isfield(etc_render_fsbrain','tissue_obj'))
                        etc_render_fsbrain.tissue_obj=[];
                    end;
                    %app.enclosing_tissue_list=categorical({'FreeSpace'});

                    if(isfield(etc_render_fsbrain,'surf_obj'))
                        for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                            if(isfield(etc_render_fsbrain.surf_obj(surf_idx),'patch_handle'))
                                if(~isempty(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                    if(isvalid(etc_render_fsbrain.surf_obj(surf_idx).patch_handle))
                                        try
                                            delete(etc_render_fsbrain.surf_obj(surf_idx).patch_handle);
                                        catch
                                        end;
                                    end
                                end;
                            end;
                        end;
                        etc_render_fsbrain.surf_obj=[];
                    else
                        etc_render_fsbrain.surf_obj=[]; 
                    end;

                    for tissue_idx=1:length(name)
                        tissue_name{tissue_idx,1}=tissue{tissue_idx};
                        etc_render_fsbrain.tissue_obj(end+1).tissue_name=tissue_name{tissue_idx,1};

                        tissue_mat{tissue_idx,1}=name{tissue_idx};
                        etc_render_fsbrain.tissue_obj(end).tissue_mat=tissue_mat{tissue_idx,1};

                        tissue_cond{tissue_idx,1}=cond(tissue_idx);
                        etc_render_fsbrain.tissue_obj(end).tissue_cond=tissue_cond{tissue_idx,1};

                        tissue_enclosing_tissue{tissue_idx,1}=enclosingTissueName{tissue_idx};
                        etc_render_fsbrain.tissue_obj(end).tissue_enclosing_tissue=tissue_enclosing_tissue{tissue_idx,1};
                        
                        %new boundary
                        [a,idx]=ismember(app.enclosing_tissue_list,tissue_name{tissue_idx,1});
                        if(isempty(find(idx)))
                            app.enclosing_tissue_list(end+1)=tissue_name{tissue_idx,1};
                            app.EnclosingTissueDropDown.Items=app.enclosing_tissue_list;
                        end;

                        %update surf_obj
                        d=load(etc_render_fsbrain.tissue_obj(end).tissue_mat);


                        if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;
                        etc_render_fsbrain.surf_obj(end+1).vertex=d.P;
                        etc_render_fsbrain.surf_obj(end).face=d.t;
                        etc_render_fsbrain.surf_obj(end).TR=triangulation(d.t,d.P);;
                        etc_render_fsbrain.surf_obj(end).surf_center=incenter(etc_render_fsbrain.surf_obj(end).TR);
                        etc_render_fsbrain.surf_obj(end).surf_norm=d.normals;
                        [eS, TriPS, TriMS] = mt(etc_render_fsbrain.surf_obj(end).TR);
                        etc_render_fsbrain.surf_obj(end).eS=eS;
                        etc_render_fsbrain.surf_obj(end).TriPS=TriPS;
                        etc_render_fsbrain.surf_obj(end).TriMS=TriMS;

                        cc=colororder;
                        cc_idx=length(etc_render_fsbrain.surf_obj);
                        cc_idx=mod(cc_idx+1,size(cc,1));
                        if(cc_idx==0) cc_idx=size(cc,1); end;
                        etc_render_fsbrain.surf_obj(end).color=cc(cc_idx,:);
                        etc_render_fsbrain.surf_obj(end).flag_show=1;
                        etc_render_fsbrain.surf_obj(end).filename=name{tissue_idx};
                        etc_render_fsbrain.surf_obj(end).category=tissue{tissue_idx};

                        figure(etc_render_fsbrain.fig_brain);
                        etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',d.P,'faces',d.t,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp

                        axis vis3d tight;
                    end;
                    t = table(tissue_name,tissue_mat,tissue_cond,tissue_enclosing_tissue);


                    app.UITable.Data=cat(1,app.UITable.Data,t);


                    %update tms_nav figure;
                    if(isfield(etc_render_fsbrain,'app_tms_nav'))
                        if(~isempty(etc_render_fsbrain.app_tms_nav))
                            if(isvalid(etc_render_fsbrain.app_tms_nav))
                                etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.SurfaceDropDown));
                                etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.DefModelLamp),'g','r');
                            end;
                        end;
                    end;

            end;
        end

        % Close request function: TMSheadmodeltissuesetupUIFigure
        function TMSheadmodeltissuesetupUIFigureCloseRequest(app, event)
            global etc_render_fsbrain;

            if(isfield(etc_render_fsbrain,'tissue_def_file'))
                if(~isempty(etc_render_fsbrain.tissue_def_file))

                    %update tms_nav figure;
                    if(isfield(etc_render_fsbrain,'app_tms_nav'))
                        if(~isempty(etc_render_fsbrain.app_tms_nav))
                            if(isvalid(etc_render_fsbrain.app_tms_nav))
                                etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.SurfaceDropDown));
                                etc_render_fsbrain_tms_nav_notify(etc_render_fsbrain.app_tms_nav,struct('Source', etc_render_fsbrain.app_tms_nav.DefModelLamp),'g','r');

                            end;
                        end;
                    end;
                end;
            end;


            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TMSheadmodeltissuesetupUIFigure = uifigure('Visible', 'off');
            app.TMSheadmodeltissuesetupUIFigure.Name = 'TMS head model tissue setup';
            app.TMSheadmodeltissuesetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @TMSheadmodeltissuesetupUIFigureCloseRequest, true);
            app.UITable = uitable(app.TMSheadmodeltissuesetupUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.AddButton = uibutton(app.TMSheadmodeltissuesetupUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+';
            app.commonconductivitySmTextAreaLabel = uilabel(app.TMSheadmodeltissuesetupUIFigure);
            app.commonconductivitySmTextAreaLabel.Text = 'common conductivity (S/m)';
            app.commonconductivitySmTextArea = uitextarea(app.TMSheadmodeltissuesetupUIFigure);
            app.commonconductivitySmTextArea.Placeholder = 'Skin: 0.4650';
            app.commonconductivitySmTextArea.Value = {'Skin: 0.4650'; 'Skull: 0.010'; 'GM: 0275'; 'WM: 0.126'; 'CSF: 1.654'};
            app.TissueEditFieldLabel = uilabel(app.TMSheadmodeltissuesetupUIFigure);
            app.TissueEditFieldLabel.Text = 'Tissue';
            app.TissueEditField = uieditfield(app.TMSheadmodeltissuesetupUIFigure, 'text');
            app.CondEditFieldLabel = uilabel(app.TMSheadmodeltissuesetupUIFigure);
            app.CondEditFieldLabel.Text = 'Cond.';
            app.CondEditField = uieditfield(app.TMSheadmodeltissuesetupUIFigure, 'text');
            app.CondEditField.Value = '0';
            app.EnclosingTissueDropDownLabel = uilabel(app.TMSheadmodeltissuesetupUIFigure);
            app.EnclosingTissueDropDownLabel.Text = 'Enclosing Tissue';
            app.EnclosingTissueDropDown = uidropdown(app.TMSheadmodeltissuesetupUIFigure);
            app.CreateButton = uibutton(app.TMSheadmodeltissuesetupUIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.TextArea = uitextarea(app.TMSheadmodeltissuesetupUIFigure);
            app.Panel = uipanel(app.TMSheadmodeltissuesetupUIFigure);
            app.LoadMATButton = uibutton(app.Panel, 'push');
            app.LoadMATButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMATButtonPushed, true);
            app.LoadMATButton.Text = 'Load MAT';
            app.ChoosesurfaceButton = uibutton(app.Panel, 'push');
            app.ChoosesurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosesurfaceButtonPushed, true);
            app.ChoosesurfaceButton.Text = 'Choose surface';
            app.TissueGeometryLabel = uilabel(app.Panel);
            app.TissueGeometryLabel.Text = '<unspecified>';
            app.LoadButton = uibutton(app.TMSheadmodeltissuesetupUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.TMSheadmodeltissuesetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_tms_tissue

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TMSheadmodeltissuesetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TMSheadmodeltissuesetupUIFigure)
        end
    end
end