classdef etc_serial_monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        serialmonitorUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Lamp                   matlab.ui.control.Lamp
        loggingButton          matlab.ui.control.StateButton
        logfileButton          matlab.ui.control.Button
        ScanButton             matlab.ui.control.Button
        ConnectButton          matlab.ui.control.StateButton
        TextArea               matlab.ui.control.TextArea
        BaudDropDown           matlab.ui.control.DropDown
        BaudDropDownLabel      matlab.ui.control.Label
        PortDropDown           matlab.ui.control.DropDown
        PortDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        all_ports; % Description
        connect_port;
        baud_rate;

        arduino=[];
        fileID=[];
        fileName='';
        file_status;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            s=serialportlist;
            app.PortDropDown.Items=s;
            app.PortDropDown.Value=s{1};
            app.all_ports=s;
            
            app.BaudDropDown.Items={'300','1200','2400','4800','9600','19200','38400','57600','74880','115200','230400','250000','500000','1000000','2000000'};
            app.BaudDropDown.Value='9600';
            app.baud_rate=9600;

            app.Lamp.Color='r';

            app.file_status=0;
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            

            if(value)
                app.connect_port=app.PortDropDown.Value;

                % Define serial port parameters
                port = app.connect_port; % Replace with your Arduino COM port
                baudRate = app.baud_rate; % Must match Arduino's baud rate

                try
                    % Create the serial port object
                    app.arduino = serialport(port, baudRate);

                    % Optional: Configure read settings
                    configureTerminator(app.arduino, "LF"); % Use line feed ('\n') as terminator
                    app.arduino.Timeout = 10; % Timeout in seconds

                    app.Lamp.Color='g';
                    app.loggingButton.Enable="on";

                    % Open a file to save data (optional)
                    %app.fileID = fopen('./serial_data.txt', 'w');

                    % Start receiving data
                    disp('Receiving data from Arduino. Press Ctrl+C to stop.');

                    try
                        while (value>eps)
                            % Read data from the serial port
                            if app.arduino.NumBytesAvailable > 0
                                data = readline(app.arduino); % Read one line of data
                                %disp(data); % Display the data in MATLAB's Command Window
                                %app.TextArea.Value{end+1}=sprintf('%s%s\n',app.TextArea.Value{end},data);
                                app.TextArea.Value{1}=sprintf('%s\n',data);
                                % Save the data to a file (optional)
                                if(~isempty(app.fileID))
                                    if(app.file_status>eps)
                                        fprintf(app.fileID, '%s\n', data);
                                    end;
                                end;
                            end
                            value = app.ConnectButton.Value;
                            pause(0.1);
                        end

                        % Cleanup when interrupted
                        disp('Stopped receiving data.');
                        if(~isempty(app.fileID))
                            fclose(app.fileID);
                        end;
                        delete(app.arduino);
                        clear app.arduino;

                        app.Lamp.Color='r';
                        app.loggingButton.Enable="off";

                    catch
                        % Cleanup when interrupted
                        disp('Stopped receiving data.');
                        if(~isempty(app.fileID))
                            fclose(app.fileID);
                        end;
                        delete(app.arduino);
                        clear app.arduino;

                        app.Lamp.Color='r';
                        app.loggingButton.Enable="off";
                    end
                catch
                    app.ConnectButton.Value=0;
                    disp('error in connecting to the port...');
                end;
            else
                app.Lamp.Color='r';

                if(~isempty(app.arduino))
                    try
                        % Cleanup when interrupted
                        disp('Stopped receiving data.');
                        fclose(app.fileID);
                        delete(app.arduino);
                        clear app.arduino;
                        app.ConnectButton.Value=0;

                        app.Lamp.Color='r';
                        app.loggingButton.Enable="off";
                    catch
                    end
                end;

            end;

        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            if(~isempty(app.arduino))
                try
                    % Cleanup when interrupted
                    disp('Stopped receiving data.');
                    fclose(app.fileID);
                    delete(app.arduino);
                    clear app.arduino;
                    app.ConnectButton.Value=0;
                catch
                end
            end;


            s=serialportlist;
            app.PortDropDown.Items=s;
            app.PortDropDown.Value=s{1};
            app.all_ports=s;            
        end

        % Button pushed function: logfileButton
        function logfileButtonPushed(app, event)
            [filename, pathname, filterindex] = uigetfile(fullfile(pwd,'*.mgz;*.mgh;*.annot;*.label;*.nii'),'select an annotation/label file');
            if(isempty(filename))
                return;
            else
                app.fileName=sprintf('%s%s',pathname,filename);

                app.fileID = fopen(app.fileName, 'w');

                app.loggingButton.Enable="on";
            end
        end

        % Value changed function: loggingButton
        function loggingButtonValueChanged(app, event)
            value = app.loggingButton.Value;
            
            app.file_status=value;

            if(app.file_status<eps)
                if(~isempty(app.fileID))
                    try
                        fclose(app.fileID);
                    catch 
                    end;
                end;
            end;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.serialmonitorUIFigure = uifigure('Visible', 'off');
            app.serialmonitorUIFigure.Name = 'serial monitor';
            app.GridLayout = uigridlayout(app.serialmonitorUIFigure);
            app.PortDropDownLabel = uilabel(app.GridLayout);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.GridLayout);
            app.BaudDropDownLabel = uilabel(app.GridLayout);
            app.BaudDropDownLabel.Text = 'Baud';
            app.BaudDropDown = uidropdown(app.GridLayout);
            app.TextArea = uitextarea(app.GridLayout);
            app.ConnectButton = uibutton(app.GridLayout, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ScanButton = uibutton(app.GridLayout, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.logfileButton = uibutton(app.GridLayout, 'push');
            app.logfileButton.ButtonPushedFcn = createCallbackFcn(app, @logfileButtonPushed, true);
            app.logfileButton.Text = 'log file';
            app.loggingButton = uibutton(app.GridLayout, 'state');
            app.loggingButton.ValueChangedFcn = createCallbackFcn(app, @loggingButtonValueChanged, true);
            app.loggingButton.Text = 'logging';
            app.Lamp = uilamp(app.GridLayout);
            % Show the figure after all components are created
            app.serialmonitorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_serial_monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.serialmonitorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.serialmonitorUIFigure)
        end
    end
end