classdef etc_trace_analyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalyzeUIFigure              matlab.ui.Figure
        FilterPanel                  matlab.ui.container.Panel
        HPEditField                  matlab.ui.control.EditField
        HighpassHzEditFieldLabel     matlab.ui.control.Label
        LPEditField                  matlab.ui.control.EditField
        LowpassHzEditFieldLabel_2    matlab.ui.control.Label
        FilterUpdateCheckBox         matlab.ui.control.CheckBox
        filterButton                 matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        ComponentsPanel              matlab.ui.container.Panel
        AnalyzeButton_2              matlab.ui.control.Button
        discardcompEditField         matlab.ui.control.EditField
        discardcompEditField_2Label  matlab.ui.control.Label
        ReconUpdateCheckBox          matlab.ui.control.CheckBox
        ReconstructButton            matlab.ui.control.Button
        NcompEditField               matlab.ui.control.NumericEditField
        ofcompEditFieldLabel         matlab.ui.control.Label
        DecomposeButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        data_now; % Description

        ica;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_trace_obj;

            app.data_now=etc_trace_obj.data;

            %app.DataDropDown.Items=etc_trace_obj.all_data_name;
            %app.DataDropDown.Value=etc_trace_obj.all_data_name{etc_trace_obj.all_data_main_idx};

            app.NcompEditField.Value=round(size(etc_trace_obj.data,1)./3);

            app.ReconstructButton.Enable='off';
            app.discardcompEditField.Enable='off';
        end

        % Button pushed function: DecomposeButton
        function DecomposeButtonPushed(app, event)
            global etc_trace_obj;

            numOfIC=app.NcompEditField.Value;
            try
                if(numOfIC<1 | numOfIC> size(etc_trace_obj.data)) fprintf('error in specifiying NumOfIC!\n'); return; end;

                %[icasig, A, W] = fastica (etc_trace_obj.data,'numOfIC',numOfIC,'g','tanh');

                % 1) center
                app.ica.dataMean      = mean( etc_trace_obj.data, 2 );          % channel means
                Xc            = etc_trace_obj.data - app.ica.dataMean;

                % 2) eigen-whiten
                [E, D]        = eig( cov( Xc.' ) );                     % note transpose
                dd=diag(D);
                dd_min_idx=find(dd<max(dd)./100);
                dd(dd_min_idx)=max(dd)./100;
                D=diag(dd);
                app.ica.whiteMat      = inv( sqrt(D) ) * E.';
                app.ica.dewhiteMat    = E * sqrt(D);
                Xw            = app.ica.whiteMat * Xc;                          % whitened data

                % 3) FastICA on whitened data
                [ icasig, A, W ] = fastica( Xw, 'numOfIC', numOfIC, 'g', 'tanh' );


                n_old_component=0;
                if(isfield(etc_trace_obj,'data_component'))
                    n_old_component=size(etc_trace_obj.data_component{etc_trace_obj.all_data_main_idx},1);
                end;
                etc_trace_obj.data_component{etc_trace_obj.all_data_main_idx}=icasig;
                etc_trace_obj.topo_component{etc_trace_obj.all_data_main_idx}=A;

                ch_names={};
                for idx=1:size(etc_trace_obj.data_component{etc_trace_obj.all_data_main_idx},1)
                    ch_names{idx}=sprintf('comp%03d',idx);
                end;
                etc_trace_obj.data_component_ch_names{etc_trace_obj.all_data_main_idx}=ch_names;

                str=etc_trace_obj.montage_ch_name{etc_trace_obj.montage_idx}.ch_names;
                obj=findobj('Tag','listbox_channel');
                str=get(obj,'String');
                str(end:-1:end-n_old_component+1)=[];
                if(isfield(etc_trace_obj,'data_component_ch_names'))
                    set(obj,'String',cat(1,str(:),etc_trace_obj.data_component_ch_names{etc_trace_obj.all_data_main_idx}(:)));
                end;

                etc_trace_obj.flag_data_component=1;

                obj=findobj('Tag','checkbox_topo_component');
                set(obj,'Enable','On');
                set(obj,'Value',1);

                app.ReconstructButton.Enable='on';
                app.discardcompEditField.Enable='on';


                etc_trace_handle('redraw');
                %app.discardcompEditField.Value=[];
            catch
                fprintf('error in data decomposition...\n');
                return;
            end;
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            global etc_trace_obj

            discard_vector_idx=app.discardcompEditField.Value;

            
            A_recon=etc_trace_obj.topo_component{etc_trace_obj.all_data_main_idx};
            %A_recon(:,discard_vector_idx)=0;
            eval(sprintf('A_recon(:,%s)=0;',discard_vector_idx));


            app.data_now=A_recon*etc_trace_obj.data_component{etc_trace_obj.all_data_main_idx};

            app.data_now=app.ica.dewhiteMat * app.data_now+ app.ica.dataMean;     

            
            if(app.ReconUpdateCheckBox.Value)
                fprintf('Reconstructed results added...\n');

                etc_trace_add_data(app.data_now,'data_reconstructed');
            end;
                %app.DataDropDown.Items=etc_trace_obj.all_data_name;

        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)
            global etc_trace_obj

            hp=str2num(app.HPEditField.Value);
            lp=str2num(app.LPEditField.Value);
            
            flag_processed=0;
            %if(hp<lp)
                for ch_idx=1:size(etc_trace_obj.data,1)
                    %app.data_now(ch_idx,:)=bandpass(etc_trace_obj.data(ch_idx,:),[hp lp],etc_trace_obj.fs);
                    if(~isnan(hp)&~isempty(hp))
                        fprintf('.')
                        hp_n=round(etc_trace_obj.fs*2/hp);
                        app.data_now(ch_idx,:)=app.data_now(ch_idx,:)-filtfilt(ones(1, hp_n)./hp_n, 1, app.data_now(ch_idx,:));
                        flag_processed=1;
                    end;

                    if(~isnan(lp)&~isempty(lp))
                        fprintf('*')
                        lp_n=round(etc_trace_obj.fs*2/lp);
                        app.data_now(ch_idx,:)=filtfilt(ones(1, lp_n)./lp_n, 1, app.data_now(ch_idx,:));
                        flag_processed=1;
                    end;
                end;

                if(flag_processed)
                    fprintf('\n');
                    if(app.FilterUpdateCheckBox.Value)
                        fprintf('Filtered results added...\n');
                        etc_trace_add_data(app.data_now,'data_filtered');
                    end;
                end;
                %app.DataDropDown.Items=etc_trace_obj.all_data_name;
                %app.DataDropDown.Value=etc_trace_obj.all_data_name{etc_trace_obj.all_data_main_idx};

            %end;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            global etc_trace_obj;

            etc_trace_obj_analysis_data=app.data_now;    
            
            assignin('base','etc_trace_obj_analysis_data',etc_trace_obj_analysis_data);
            fprintf('variables "etc_trace_obj_analysis_data" exported\n');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalyzeUIFigure = uifigure('Visible', 'off');
            app.AnalyzeUIFigure.Name = 'Analyze';
            app.ComponentsPanel = uipanel(app.AnalyzeUIFigure);
            app.ComponentsPanel.Title = 'Components';
            app.DecomposeButton = uibutton(app.ComponentsPanel, 'push');
            app.DecomposeButton.ButtonPushedFcn = createCallbackFcn(app, @DecomposeButtonPushed, true);
            app.DecomposeButton.Text = 'Decompose';
            app.ofcompEditFieldLabel = uilabel(app.ComponentsPanel);
            app.ofcompEditFieldLabel.Text = '# of comp.';
            app.NcompEditField = uieditfield(app.ComponentsPanel, 'numeric');
            app.ReconstructButton = uibutton(app.ComponentsPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.ReconUpdateCheckBox = uicheckbox(app.ComponentsPanel);
            app.ReconUpdateCheckBox.Text = '';
            app.ReconUpdateCheckBox.Value = true;
            app.discardcompEditField_2Label = uilabel(app.ComponentsPanel);
            app.discardcompEditField_2Label.Text = 'discard comp';
            app.discardcompEditField = uieditfield(app.ComponentsPanel, 'text');
            app.AnalyzeButton_2 = uibutton(app.ComponentsPanel, 'push');
            app.AnalyzeButton_2.Text = 'Analyze';
            app.ExportButton = uibutton(app.AnalyzeUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FilterPanel = uipanel(app.AnalyzeUIFigure);
            app.FilterPanel.Title = 'Filter';
            app.filterButton = uibutton(app.FilterPanel, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Text = 'filter';
            app.FilterUpdateCheckBox = uicheckbox(app.FilterPanel);
            app.FilterUpdateCheckBox.Text = '';
            app.FilterUpdateCheckBox.Value = true;
            app.LowpassHzEditFieldLabel_2 = uilabel(app.FilterPanel);
            app.LowpassHzEditFieldLabel_2.Text = 'Low-pass (Hz)';
            app.LPEditField = uieditfield(app.FilterPanel, 'text');
            app.LPEditField.Value = 'nan';
            app.HighpassHzEditFieldLabel = uilabel(app.FilterPanel);
            app.HighpassHzEditFieldLabel.Text = 'High-pass (Hz)';
            app.HPEditField = uieditfield(app.FilterPanel, 'text');
            app.HPEditField.Value = 'nan';
            % Show the figure after all components are created
            app.AnalyzeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_trace_analyze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalyzeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalyzeUIFigure)
        end
    end
end