classdef seegdb_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        OthersSelectButton         matlab.ui.control.Button
        ChecksubjButton            matlab.ui.control.Button
        hemiDropDown               matlab.ui.control.DropDown
        hemiDropDownLabel          matlab.ui.control.Label
        SingleSubjectSelectButton  matlab.ui.control.Button
        ContactColorButton         matlab.ui.control.Button
        CheckBox                   matlab.ui.control.CheckBox
        ImportButton               matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        DeleteButton               matlab.ui.control.Button
        SortbyDropDown             matlab.ui.control.DropDown
        SortbyDropDownLabel        matlab.ui.control.Label
        SortButton                 matlab.ui.control.Button
        DistDropDown               matlab.ui.control.DropDown
        DistDropDownLabel          matlab.ui.control.Label
        RenderButton               matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        subjectsdirLabel           matlab.ui.control.Label
        SetsubjectsdirButton       matlab.ui.control.Button
        SearchButton               matlab.ui.control.Button
        CoordDropDown              matlab.ui.control.DropDown
        CoordDropDownLabel         matlab.ui.control.Label
        ROILabelDropDown           matlab.ui.control.DropDown
        ROILabelDropDownLabel      matlab.ui.control.Label
        SettargetDropDown          matlab.ui.control.DropDown
        subjectsDropDown           matlab.ui.control.DropDown
        subjectsDropDownLabel      matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        SetelectrodedirButton      matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        ZEditField                 matlab.ui.control.NumericEditField
        YEditField                 matlab.ui.control.NumericEditField
        XEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel_4          matlab.ui.control.Label
        ZEditFieldLabel_3          matlab.ui.control.Label
        ZEditFieldLabel_2          matlab.ui.control.Label
    end

    
    properties (Access = public)
        seegdb_obj; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.seegdb_obj.subjects_dir =getenv('SUBJECTS_DIR');
            app.subjectsdirLabel.Text=app.seegdb_obj.subjects_dir;

            app.seegdb_obj.electrode_file='';
            app.seegdb_obj.path='';
            app.seegdb_obj.roi_xyz=[];
            app.seegdb_obj.roi_idx=[];
            app.seegdb_obj.roi_file=[];

            app.seegdb_obj.sortby_idx=1;

            app.seegdb_obj.flag_RenderKeep=0;

            app.seegdb_obj.ContactColor=[0   0.4470   0.741]; %default contact color;

            app.seegdb_obj.hemi='lh';

            app.UITable.SelectionType='row';

            app.ContactColorButton.BackgroundColor=app.seegdb_obj.ContactColor;
            app.SearchButton.Enable='off';
            app.RenderButton.Enable='off';
            app.ExportButton.Enable='off';
            app.DeleteButton.Enable='off';
            app.SortButton.Enable='off';
            app.SingleSubjectSelectButton.Enable='off';

        end

        % Button pushed function: SetelectrodedirButton
        function SetelectrodedirButtonPushed(app, event)
            %scan dir
            app.seegdb_obj.path = pwd;
            app.seegdb_obj.path = uigetdir;

            try
                rootdir = app.seegdb_obj.path;
                if(ismac)
                    %filelist = dir(fullfile(rootdir, '**/*.*'));  %get list of files and folders in any subfolder
                    filelist=dir(sprintf('%s/**/*electrode*.mat',app.seegdb_obj.path));
                elseif(IsWindows)
                    %filelist = dir(fullfile(rootdir, '**\*.*'));  %get list of files and folders in any subfolder                    
                     filelist=dir(sprintf('%s\**\*electrode*.mat',app.seegdb_obj.path));
               end;
                %filelist = filelist(~[filelist.isdir]);
                
                app.seegdb_obj.subject={};
                app.seegdb_obj.electrode_file={};

                f = waitbar(0,'Scanning electrode directories...');

                for idx=1:length(filelist)
                    if(isempty(extract(filelist(idx).folder,app.seegdb_obj.subjects_dir))) %not SUBJECTS_DIR
                        if(isempty(extract(filelist(idx).folder,'subjects'))) %not 'subjects'

                            waitbar(idx./length(filelist),f,sprintf('[%s]',filelist(idx).name));
        
%                            if(regexp(filelist(idx).name,regexptranslate('wildcard','elec*.mat'))) %find all 'elec*.mat' files

                                newStr = split(filelist(idx).folder,{app.seegdb_obj.path,'/'});
                                newStr_idx = find(~cellfun(@isempty,newStr));
                                tmp=strsplit(newStr{newStr_idx(1)},'_');

                                %app.seegdb_obj.subject{end+1}=newStr{newStr_idx(1)};
                                app.seegdb_obj.subject{end+1}=tmp{1};
                                app.seegdb_obj.electrode_file{end+1}=sprintf('%s/%s',filelist(idx).folder,filelist(idx).name);
%                            end;
                        end;
                    end;
                end;

                close(f);

                app.subjectsDropDown.Items=app.seegdb_obj.subject;

                if(length(app.subjectsDropDown.Items)>0)
                    app.SettargetDropDown.Enable=1;
                end;

                if(~isempty(app.seegdb_obj.electrode_file)&~isempty(app.seegdb_obj.path))
                    if(~isempty(app.seegdb_obj.roi_file))
                        app.SearchButton.Enable='on';
                    elseif(~isempty(app.seegdb_obj.roi_xyz))
                        app.SearchButton.Enable='on';
                    end;
                end;
            catch 
            end;
        end

        % Clicked callback: SettargetDropDown
        function SettargetDropDownCallback(app, event)
           
            value = app.SettargetDropDown.Value;
            
            switch(value)
                case 'ROI/Label'
                    app.ROILabelDropDown.Enable=1;
                    app.XEditField.Enable=0;
                    app.YEditField.Enable=0;
                    app.ZEditField.Enable=0;

                    [filename, pathname, filterindex] = uigetfile(fullfile(pwd,'*.mgz;*.mgh;*.annot;*.label;*.nii'),'select an annotation/label file');

                    try
                        app.seegdb_obj.roi_file={};
                        [dummy,fstem,ext]=fileparts(filename);
                        switch lower(ext)
                            case '.label'
                                file_label=sprintf('%s/%s',pathname,filename);
                                [app.seegdb_obj.roi_idx,d0,d1,d2, vv] = inverse_read_label(file_label);
                                app.seegdb_obj.roi_file{end+1}=file_label;
                                app.seegdb_obj.roi_xyz=[];

                                app.ROILabelDropDown.Items={filename};

                            case '.mgz'

                                file_aseg=sprintf('%s/%s',pathname,filename);
                                app.seegdb_obj.overlay_vol_mask=MRIread(file_aseg);

                                [dummy,fstem]=fileparts(filename);

                                %file_lut='/Applications/freesurfer/FreeSurferColorLUT.txt';
                                fprintf('select a look-up-table (LUT) file for color and name definitions...\n');
                                [filename, pathname, filterindex] = uigetfile(fullfile(pwd,'*.txt'),'select a LUT file');
                                if(filename)
                                    file_lut=sprintf('%s%s',pathname,filename);

                                    [app.seegdb_obj.lut.number,app.seegdb_obj.lut.name,app.seegdb_obj.lut.r,app.seegdb_obj.lut.g,app.seegdb_obj.lut.b,f]=textread(file_lut,'%d%s%d%d%d%d','commentstyle','shell');
                                    
                                    [indx,tf] = listdlg('ListString',app.seegdb_obj.lut.name);
                                    if(tf>0)
                                        fprintf('[%s] is selected...\n',app.seegdb_obj.lut.name{indx});
                                        app.seegdb_obj.roi_idx=indx;
                                        app.seegdb_obj.roi_file{end+1}=file_aseg;
                                        app.seegdb_obj.roi_xyz=[];
                                    end;

                                    app.ROILabelDropDown.Items=app.seegdb_obj.lut.name;
                                    app.ROILabelDropDown.Value=app.seegdb_obj.lut.name{indx};
                                end;
                        end;
                    catch 
                    end;
                case 'Coordinates'
                    app.ROILabelDropDown.Items={};
                    app.ROILabelDropDown.Enable=0;
                    app.XEditField.Enable=1;
                    app.YEditField.Enable=1;
                    app.ZEditField.Enable=1;

                    app.seegdb_obj.roi_xyz=[app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value];
                    app.seegdb_obj.roi_idx=[];
                    app.seegdb_obj.roi_file={};
            end;

            if(~isempty(app.seegdb_obj.electrode_file)&~isempty(app.seegdb_obj.path))
                if(~isempty(app.seegdb_obj.roi_file))
                    app.SearchButton.Enable='on';
                elseif(~isempty(app.seegdb_obj.roi_xyz))
                    app.SearchButton.Enable='on';
                end;
            end;
        end

        % Button pushed function: SearchButton
        function startsearch(app, event)
            f = waitbar(0,'Calculating distance...');
            for f_idx=1:length(app.seegdb_obj.electrode_file)
                waitbar(f_idx./length(app.seegdb_obj.electrode_file),f,sprintf('[%s]',app.seegdb_obj.electrode_file{f_idx}));
                try
                    %make sure electrodes in MNI305 space
                    [output]=seegdb_electrode_file_parse(app.seegdb_obj.electrode_file{f_idx});
                    if(output==0)
                        fprintf('convert electrode file [%s] to MNI305 ...\n',app.seegdb_obj.electrode_file{f_idx})
                        [electrode_tmp]=seegdb_native2mni305(app.seegdb_obj.subject{f_idx}, app.seegdb_obj.electrode_file{f_idx}, app.seegdb_obj);
                    else
                        fprintf('load electrode file [%s] in MNI305 ...\n',app.seegdb_obj.electrode_file{f_idx})
                        tmp=load(app.seegdb_obj.electrode_file{f_idx});
                        electrode_tmp=tmp.electrode;
                    end
                catch
                    fprintf('[%s::%s] error in judging MNI305 vs. native spaace.\n',app.seegdb_obj.subject{f_idx}, app.seegdb_obj.electrode_file{f_idx});
                    close(f);
                    return;
                end;

                try
                    %calculate the distance
                    [pp,ff,ee]=fileparts(app.seegdb_obj.roi_file);
                    switch ee
                        case {'.mgz','.mgh'}
                            [app.seegdb_obj.distance]=seegdb_seg_calc_distance(app.seegdb_obj.subjects_dir,'fsaverage', app.seegdb_obj.roi_file, electrode_tmp, app.seegdb_obj.roi_idx);

                        case {'.label','.annot'}
                            [app.seegdb_obj.distance]=seegdb_calc_distance(app.seegdb_obj.subjects_dir,'fsaverage', app.seegdb_obj.roi_file, electrode_tmp);
                    end;

                    switch(app.DistDropDown.Value)
                        case 'COM' % d_COM
                            app.seegdb_obj.distance.metric='COM';
                        case 'MIN' % d_MIN
                            app.seegdb_obj.distance.metric='MIN';
                    end;
                    app.seegdb_obj.distance.subject=app.seegdb_obj.subject{f_idx};

                    [seegdb_table, app.seegdb_obj]=seegdb_updatetable(app.UITable.Data, app.seegdb_obj);
                    app.UITable.Data=seegdb_table;
                    
                    app.seegdb_obj.table=seegdb_table;

                    app.RenderButton.Enable='on';
                    app.DeleteButton.Enable='on';
                    app.SortButton.Enable='on';
                    app.SingleSubjectSelectButton.Enable='on';
                    app.OthersSelectButton.Enable='on';
                    app.ExportButton.Enable='on';
                    
                catch
                    fprintf('[%s::%s] error in calculating the distance between electrodes and ROI\n',app.seegdb_obj.subject{f_idx}, app.seegdb_obj.electrode_file{f_idx});
                    close(f);
                    return;
                end;
            end;
            close(f);
        end

        % Button pushed function: SetsubjectsdirButton
        function subjectsdir(app, event)
            try
                app.seegdb_obj.subjects_dir = uigetdir;
                app.subjectsdirLabel.Text=app.seegdb_obj.subjects_dir;


                if(~isempty(app.seegdb_obj.electrode_file)&~isempty(app.seegdb_obj.path))
                    if(~isempty(app.seegdb_obj.roi_file))
                        app.SearchButton.Enable='on';
                    elseif(~isempty(app.seegdb_obj.roi_xyz))
                        app.SearchButton.Enable='on';
                    end;
                end;
            catch
            end;

        end

        % Button pushed function: ResetButton
        function TabelReset(app, event)
            app.UITable.Data=[];

            clearvars -global etc_render_fsbrain;

            app.RenderButton.Enable='off';
            app.DeleteButton.Enable='off';
            app.SortButton.Enable='off';
            app.OthersSelectButton.Enable='off';
            app.SingleSubjectSelectButton.Enable='off';
            app.ExportButton.Enable='off';

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.seegdb_obj.row_select=unique(indices(:,1));
            %fprintf('[%d]\n',indices);
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            output=seegdb_render(app.seegdb_obj);
        end

        % Value changed function: DistDropDown
        function DistDropDownValueChanged(app, event)
            switch(app.DistDropDown.Value)
                case 'COM' % d_COM
                    app.seegdb_obj.distance.metric='COM';
                case 'MIN' % d_MIN
                    app.seegdb_obj.distance.metric='MIN';
            end;

            try
            app.UITable.Data=[];
            [seegdb_table, app.seegdb_obj]=seegdb_updatetable(app.UITable.Data, app.seegdb_obj);
            app.UITable.Data=seegdb_table;

            app.seegdb_obj.table=seegdb_table;
            catch 
                fprintf('update distance error!\n');
                return;
            end;
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
            %app.seegdb_obj.table=seegdb_table;  
            if(isfield(app.seegdb_obj,'table'))
                switch(app.SortbyDropDown.Value)
                    case 'Distance'
                        sortby_idx=4;
                    case 'Subject'
                        sortby_idx=1;
                    case 'Electrode'
                        sortby_idx=2;
                    case 'X'
                        sortby_idx=5;
                    case 'Y'
                        sortby_idx=6;
                    case 'Z'
                        sortby_idx=7;
                end;
                seegdb_table = sortrows(app.seegdb_obj.table,sortby_idx);
            
                app.seegdb_obj.table=seegdb_table;  
                app.UITable.Data=seegdb_table;
            end;
        end

        % Value changed function: SortbyDropDown
        function SortbyDropDownValueChanged(app, event)
            value = app.SortbyDropDown.Value;
            app.seegdb_obj.sortby_idx=value;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if(isfield(app.seegdb_obj,'row_select'))
                    app.seegdb_obj.table(app.seegdb_obj.row_select,:)=[];
            end;
            app.UITable.Data=app.seegdb_obj.table;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;

            app.seegdb_obj.flag_RenderKeep=value;
        end

        % Button pushed function: ContactColorButton
        function ContactColorButtonPushed(app, event)
            tmp=uisetcolor;
            if(sum(abs(tmp))>eps)
                app.seegdb_obj.ContactColor=tmp;
                app.ContactColorButton.BackgroundColor=app.seegdb_obj.ContactColor;

                seegdb_render(app.seegdb_obj);
            end;
        end

        % Button pushed function: SingleSubjectSelectButton
        function SingleSubjectSelectButtonPushed(app, event)
            if(isfield(app.seegdb_obj,'row_select'))
%                    app.seegdb_obj.table(app.seegdb_obj.row_select,:)=[];
                    tmp_table=app.seegdb_obj.table(app.seegdb_obj.row_select,:);
                    [a,b,c]=unique(tmp_table(:,1));
                    app.seegdb_obj.row_select=app.seegdb_obj.row_select(b);
                    
%                     [n_row, n_col]=size(app.seegdb_obj.table);
%                     table_idx=[];
%                     for col_idx=1:n_col;
%                         table_idx=cat(1,table_idx,[app.seegdb_obj.row_select(:),ones(length(app.seegdb_obj.row_select),1).*col_idx]);
%                     end;
                    %app.UITable.Selection=[app.seegdb_obj.row_select(:),ones(length(app.seegdb_obj.row_select),1)];
                    %app.UITable.Selection=table_idx;
                    app.UITable.SelectionType='row';
                    app.UITable.Selection=app.seegdb_obj.row_select(:)';
            end;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            tstr=datestr(datetime('now'),'mmddyy_HHMMss');
            fn=sprintf('seegdb_%s.mat',tstr);

            fn = uiputfile({'*.mat'},'seegdb_obj',fn);
            
            if(fn~=0)
                fprintf('saving [%s]...\n',fn);
                seegdb_obj=app.seegdb_obj;
                save(fn,'seegdb_obj');
            end;
        end

        % Value changed function: hemiDropDown
        function hemiDropDownValueChanged(app, event)
            value = app.hemiDropDown.Value;
            
            if(strcmp(value,app.seegdb_obj.hemi))
            
            else
                etc_render_fsbrain_handle('del'); 
                clearvars -global etc_render_fsbrain;
            end;
            app.seegdb_obj.hemi=value;

            output=seegdb_render(app.seegdb_obj);

        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            
            [filename, pathname, filterindex] = uigetfile({'*.mat','seegdb data'}, 'Pick the Matlab mat file with saved "seegdb_obj"');
            if(filename==0) return; end;
            
            try
                tmp=load(sprintf('%s/%s',pathname,filename));

                if(filename~=0)
    
                    if(isfield(tmp,'seegdb_obj'))
                        app.seegdb_obj=tmp.seegdb_obj;

                        %seegdb_updateobj(app.seegdb_obj);

                        app.subjectsdirLabel.Text=app.seegdb_obj.subjects_dir;

                        app.subjectsDropDown.Items=app.seegdb_obj.subject;

                        for idx=1:length(app.seegdb_obj.roi_file)
                            [path,fname]=fileparts(app.seegdb_obj.roi_file{idx});
                            app.ROILabelDropDown.Items{idx}=fname;
                            app.ROILabelDropDownLabel.Enable='on';
                            app.SettargetDropDown.Enable='on';

                            app.XEditField.Enable='off';
                            app.YEditField.Enable='off';
                            app.ZEditField.Enable='off';
                        end;
                        app.SearchButton.Enable='on';
                        app.RenderButton.Enable='on';
                        app.ExportButton.Enable='on';
                        app.DeleteButton.Enable='on';
                        app.SortButton.Enable='on';
                        app.OthersSelectButton.Enable='on';
                        app.SingleSubjectSelectButton.Enable='on';

                        app.UITable.Data=app.seegdb_obj.table;

                        [n_row, n_col]=size(app.seegdb_obj.table);
                        table_idx=[];
                        if(isfield(app.seegdb_obj,'row_select'))
                            if(~isempty(app.seegdb_obj.row_select))
                                app.UITable.SelectionType='row';
                                app.UITable.Selection=app.seegdb_obj.row_select(:)';

%                                 for col_idx=1:n_col;
%                                     table_idx=cat(1,table_idx,[app.seegdb_obj.row_select(:),ones(length(app.seegdb_obj.row_select),1).*col_idx]);
%                                 end;
                            end;
                        end;
                        %app.UITable.Selection=table_idx;

                    end;
                end;
            catch
            end;

        end

        % Button pushed function: ChecksubjButton
        function ChecksubjButtonPushed(app, event)
            if(~isempty(app.seegdb_obj.subject))
                fig = uifigure;
                vars = ["Subject MRI","Subject electrode"];
                varTypes = ["string","string"];
                tbl = table('Size',[length(app.seegdb_obj.subject),2],'VariableTypes',varTypes,'VariableNames',vars);
                tbl(:,1)=app.seegdb_obj.subject(:);
                tbl(:,2)=app.seegdb_obj.electrode_file(:);

                uit = uitable(fig,"Data",tbl);
                set(fig,'pos',[272   574   303   248]);
            end;
        end

        % Button pushed function: OthersSelectButton
        function OthersSelectButtonPushed(app, event)
            if(isfield(app.seegdb_obj,'row_select'))
                    app.UITable.SelectionType='row';
                    row_idx=[1:size(app.UITable.Data,1)];
                    app.seegdb_obj.row_select=setdiff(row_idx,app.seegdb_obj.row_select);
                    app.UITable.Selection=app.seegdb_obj.row_select;
            end;
        end

        % Value changed function: SettargetDropDown
        function SettargetDropDownValueChanged(app, event)
            value = app.SettargetDropDown.Value;
            
        end

        % Drop down opening function: SettargetDropDown
        function SettargetDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.ZEditFieldLabel_2 = uilabel(app.Panel);
            app.ZEditFieldLabel_2.Enable = 'off';
            app.ZEditFieldLabel_2.Text = 'Y';
            app.ZEditFieldLabel_3 = uilabel(app.Panel);
            app.ZEditFieldLabel_3.Enable = 'off';
            app.ZEditFieldLabel_3.Text = 'Z';
            app.ZEditFieldLabel_4 = uilabel(app.Panel);
            app.ZEditFieldLabel_4.Enable = 'off';
            app.ZEditFieldLabel_4.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.XEditField.Enable = 'off';
            app.YEditField = uieditfield(app.Panel, 'numeric');
            app.YEditField.Enable = 'off';
            app.ZEditField = uieditfield(app.Panel, 'numeric');
            app.ZEditField.Enable = 'off';
            app.SetelectrodedirButton = uibutton(app.GridLayout, 'push');
            app.SetelectrodedirButton.ButtonPushedFcn = createCallbackFcn(app, @SetelectrodedirButtonPushed, true);
            app.SetelectrodedirButton.Text = 'Set electrode dir.';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Subject'; 'Electrode'; 'Contact'; 'Distance'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.subjectsDropDownLabel = uilabel(app.GridLayout);
            app.subjectsDropDownLabel.Text = 'subjects';
            app.subjectsDropDown = uidropdown(app.GridLayout);
            app.subjectsDropDown.Items = {'[]'};
            app.subjectsDropDown.Value = '[]';
            app.SettargetDropDown = uidropdown(app.GridLayout);
            app.SettargetDropDown.Items = {'ROI/Label', 'Coordinates'};
            app.SettargetDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SettargetDropDownOpening, true);
            app.SettargetDropDown.ValueChangedFcn = createCallbackFcn(app, @SettargetDropDownValueChanged, true);
            app.SettargetDropDown.Enable = 'off';
            app.SettargetDropDown.ClickedFcn = createCallbackFcn(app, @SettargetDropDownCallback, true);
            app.SettargetDropDown.Value = 'ROI/Label';
            app.ROILabelDropDownLabel = uilabel(app.GridLayout);
            app.ROILabelDropDownLabel.Enable = 'off';
            app.ROILabelDropDownLabel.Text = 'ROI/Label';
            app.ROILabelDropDown = uidropdown(app.GridLayout);
            app.ROILabelDropDown.Items = {'[]'};
            app.ROILabelDropDown.Enable = 'off';
            app.ROILabelDropDown.Value = '[]';
            app.CoordDropDownLabel = uilabel(app.GridLayout);
            app.CoordDropDownLabel.Enable = 'off';
            app.CoordDropDownLabel.Text = 'Coord';
            app.CoordDropDown = uidropdown(app.GridLayout);
            app.CoordDropDown.Items = {'MNI305', 'Native'};
            app.CoordDropDown.Enable = 'off';
            app.CoordDropDown.Value = 'MNI305';
            app.SearchButton = uibutton(app.GridLayout, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @startsearch, true);
            app.SearchButton.Text = 'Search';
            app.SetsubjectsdirButton = uibutton(app.GridLayout, 'push');
            app.SetsubjectsdirButton.ButtonPushedFcn = createCallbackFcn(app, @subjectsdir, true);
            app.SetsubjectsdirButton.Text = 'Set subjects dir.';
            app.subjectsdirLabel = uilabel(app.GridLayout);
            app.subjectsdirLabel.Text = 'subjectsdir';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @TabelReset, true);
            app.ResetButton.Text = 'Reset';
            app.RenderButton = uibutton(app.GridLayout, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.DistDropDownLabel = uilabel(app.GridLayout);
            app.DistDropDownLabel.Text = 'Dist.';
            app.DistDropDown = uidropdown(app.GridLayout);
            app.DistDropDown.Items = {'COM', 'MIN'};
            app.DistDropDown.ValueChangedFcn = createCallbackFcn(app, @DistDropDownValueChanged, true);
            app.DistDropDown.Value = 'COM';
            app.SortButton = uibutton(app.GridLayout, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.SortbyDropDownLabel = uilabel(app.GridLayout);
            app.SortbyDropDownLabel.Text = 'Sort by';
            app.SortbyDropDown = uidropdown(app.GridLayout);
            app.SortbyDropDown.Items = {'Distance', 'Subject', 'Electrode', 'X', 'Y', 'Z'};
            app.SortbyDropDown.ValueChangedFcn = createCallbackFcn(app, @SortbyDropDownValueChanged, true);
            app.SortbyDropDown.Value = 'Distance';
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ContactColorButton = uibutton(app.GridLayout, 'push');
            app.ContactColorButton.ButtonPushedFcn = createCallbackFcn(app, @ContactColorButtonPushed, true);
            app.ContactColorButton.Text = '';
            app.SingleSubjectSelectButton = uibutton(app.GridLayout, 'push');
            app.SingleSubjectSelectButton.ButtonPushedFcn = createCallbackFcn(app, @SingleSubjectSelectButtonPushed, true);
            app.SingleSubjectSelectButton.Text = 'Unique';
            app.hemiDropDownLabel = uilabel(app.GridLayout);
            app.hemiDropDownLabel.Text = 'hemi';
            app.hemiDropDown = uidropdown(app.GridLayout);
            app.hemiDropDown.Items = {'lh', 'rh'};
            app.hemiDropDown.ValueChangedFcn = createCallbackFcn(app, @hemiDropDownValueChanged, true);
            app.hemiDropDown.Value = 'lh';
            app.ChecksubjButton = uibutton(app.GridLayout, 'push');
            app.ChecksubjButton.ButtonPushedFcn = createCallbackFcn(app, @ChecksubjButtonPushed, true);
            app.ChecksubjButton.Text = 'Check subj.';
            app.OthersSelectButton = uibutton(app.GridLayout, 'push');
            app.OthersSelectButton.ButtonPushedFcn = createCallbackFcn(app, @OthersSelectButtonPushed, true);
            app.OthersSelectButton.Text = 'Others';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seegdb_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end